classdef CiftiStorm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CiftiStormUIFigure         matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        NewprocessMenu             matlab.ui.container.Menu
        SetdefaultMenu             matlab.ui.container.Menu
        DatasetMenu                matlab.ui.container.Menu
        DatasetLoadMenu            matlab.ui.container.Menu
        DatasetListMenu            matlab.ui.container.Menu
        DatasetDeleteMenu          matlab.ui.container.Menu
        ExitMenu                   matlab.ui.container.Menu
        ToolsMenu                  matlab.ui.container.Menu
        StructuralprocessingMenu   matlab.ui.container.Menu
        StandardMenu               matlab.ui.container.Menu
        PreFreesurferMenu          matlab.ui.container.Menu
        FreesurferMenu_2           matlab.ui.container.Menu
        postFreesurferMenu         matlab.ui.container.Menu
        LegacyMenu                 matlab.ui.container.Menu
        FreesurferMenu             matlab.ui.container.Menu
        CiftifyMenu                matlab.ui.container.Menu
        FSLBetMenu                 matlab.ui.container.Menu
        ForwadandSourcemodelsMenu  matlab.ui.container.Menu
        MEEGprepMenu               matlab.ui.container.Menu
        BCVARETAMenu               matlab.ui.container.Menu
        ViewMenu                   matlab.ui.container.Menu
        HelpMenu                   matlab.ui.container.Menu
        CheckforupdateMenu         matlab.ui.container.Menu
        DocumentationMenu          matlab.ui.container.Menu
        AboutMenu                  matlab.ui.container.Menu
        Toolbar                    matlab.ui.container.Toolbar
        PushToolNewProcess         matlab.ui.container.toolbar.PushTool
        PushToolDefault_Params     matlab.ui.container.toolbar.PushTool
        PushToolRunProcess         matlab.ui.container.toolbar.PushTool
        PushTool                   matlab.ui.container.toolbar.PushTool
        PushToolListDataset        matlab.ui.container.toolbar.PushTool
        PushToolExplorerDS         matlab.ui.container.toolbar.PushTool
        GridLayout                 matlab.ui.container.GridLayout
        PanelControls              matlab.ui.container.Panel
        LabelBreadcrumbs           matlab.ui.control.Label
        PanelViewer                matlab.ui.container.Panel
        TabGroup                   matlab.ui.container.TabGroup
        TabExplorer                matlab.ui.container.Tab
        TreeInput                  matlab.ui.container.Tree
        TabDatasets                matlab.ui.container.Tab
        DatasetsDropDown           matlab.ui.control.DropDown
        DatasetsDropDownLabel      matlab.ui.control.Label
        TreeDatasets               matlab.ui.container.Tree
    end


    properties (Access = private)
        cfs_properties % Description
        ciftistormDir % Description
        bst_file % Description
        Datasets % Description
        Datasets_file
        currentDT % Description
        currentPr % Description
        currentAxes % Description
        colorMap % Description
        currentSub % Description
    end

    methods (Access = private)
        % Explore the Tree Folder
        function ExploreFolder(app,nodeData)
            app.TreeInput.Children.delete;
            if(ismember(nodeData.name,{'..'}))
                rootDir = fileparts(nodeData.folder);
                dirName = strsplit(rootDir,filesep);
                dirName = dirName{end};
            else
                rootDir = fullfile(nodeData.folder,nodeData.name);
                dirName = nodeData.name;
            end
            app.Breadcrumbs(rootDir)
            parent = uitreenode(app.TreeInput,'Text',dirName);
            parent.Icon = 'guide/images/folder.png';
            file_list = dir(rootDir);
            file_list(ismember({file_list.name},{'.'})) = [];
            for i=1:length(file_list)
                fileStruct = file_list(i);
                if(fileStruct.isdir)
                    node = uitreenode(parent, 'Text', file_list(i).name);
                    node.NodeData = fileStruct;
                    if(isequal(fileStruct.name,'..'))
                        icon_file = 'guide/images/folder_parent.png';
                    else
                        icon_file = 'guide/images/folder.png';
                    end
                    % Contex menu
                    cm = uicontextmenu(app.CiftiStormUIFigure);
                    m1 = uimenu(cm,"Text","Define as Anatomy folder","MenuSelectedFcn",{@DefineProperties,'Anatomy',node.NodeData});
                    m2 = uimenu(cm,"Text","Define as Non-Brain folder","MenuSelectedFcn",{@DefineProperties,'Non-brain',node.NodeData});
                    node.ContextMenu = cm;
                    node.Icon = icon_file;
                end
            end
            for i=1:length(file_list)
                fileStruct = file_list(i);
                if(~fileStruct.isdir)
                    node = uitreenode(parent, 'Text', file_list(i).name);
                    node.Icon = 'guide/images/file.jpeg';
                end
            end
            expand(parent);
        end
        % Create the Breadcrumbs in the navigation tree
        function Breadcrumbs(app,path)
            pathParts = strsplit(path,filesep);
            text = strcat(filesep, " ");
            for i=2:length(pathParts)
                if(i < length(pathParts))
                    text = strcat(text, pathParts{i}," ",filesep," ");
                else
                    text = strcat(text, pathParts{i});
                end
            end
            app.LabelBreadcrumbs.Text = strcat("<font style='color:blue;'>",text,"</font>");
            app.LabelBreadcrumbs.Interpreter = 'html';
        end
        % Fill a TreeNode with subfolders
        function FillFolder(app,node)
            nodeData = node.NodeData;
            if(~ismember(nodeData.name,{'..'}) && ~ismember(nodeData.name,{'.'}))
                rootDir = fullfile(nodeData.folder,nodeData.name);
                
                dirName = nodeData.name;
                subfile_list = dir(rootDir);
                subfile_list(ismember({subfile_list.name},{'.','..'})) = [];
                for j=1:length(subfile_list)
                    subfile = subfile_list(j);
                    if(subfile.isdir)
                        children = node.Children;
                        if(isempty(children) || all(~ismember({children.Text},subfile.name)))
                            subnode = uitreenode(node, 'Text', subfile.name);
                            subnode.Icon = 'guide/images/folder.png';
                            subnode.NodeData = subfile_list(j);
                            sub_cm = uicontextmenu(app.CiftiStormUIFigure);
                            m1 = uimenu(sub_cm,"Text","Define as Anatomy folder","MenuSelectedFcn",{@DefineProperties,'Anatomy',node.NodeData});
                            m2 = uimenu(sub_cm,"Text","Define as Non-Brain folder","MenuSelectedFcn",{@DefineProperties,'Non-brain',node.NodeData});
                            subnode.ContextMenu = sub_cm;
                        end
                    end
                end
                for j=1:length(subfile_list)
                    subfile = subfile_list(j);
                    if(~subfile.isdir)
                        children = node.Children;
                        if(isempty(children) || all(~ismember({children.Text},subfile.name)))
                            subnode = uitreenode(node, 'Text', subfile.name);
                            subnode.Icon = 'guide/images/file.jpeg';
                            subnode.NodeData = subfile_list(j);
                        end
                    end
                end
            end
        end   

        function PlotSurf(app,sSurfaces)
            delete(app.PanelViewer.Children);
            template = load('axes.mat');
            app.currentAxes = template.axes;
            alpha = 1;
            ProtocolInfo = bst_get('ProtocolInfo');
            for i=1:length(sSurfaces)
                surf = sSurfaces{i};
                switch surf
                    case 'Cortex'                        
                        filename = fullfile(ProtocolInfo.SUBJECTS, app.currentSub.Surface(app.currentSub.iCortex).FileName);
                        surface = load(filename);
                        patch( app.currentAxes,...
                            'Faces',            surface.Faces, ...
                            'Vertices',         surface.Vertices,...
                            'VertexNormals',    surface.VertNormals, ...
                            'VertexNormalsMode', 'auto',...
                            'FaceNormals',      [],...
                            'FaceNormalsMode',  'auto',...
                            'FaceVertexCData',  [], ...
                            'FaceColor',        [255/255 204/255 204/255], ...
                            'FaceAlpha',        alpha, ...
                            'AlphaDataMapping', 'none', ...
                            'EdgeColor',        'none', ...
                            'EdgeAlpha',        alpha, ...
                            'LineWidth',         0.5,...
                            'LineJoin',         'miter',...
                            'AmbientStrength',  0.5, ...
                            'DiffuseStrength',  0.5, ...
                            'SpecularStrength', 0.2, ...
                            'SpecularExponent', 1, ...
                            'SpecularColorReflectance', 0.5, ...
                            'FaceLighting',     'gouraud', ...
                            'EdgeLighting',     'gouraud', ...
                            'Tag',              'cortex');
                        alpha = alpha - 0.60;

                    case 'InnerSkull'
                        filename = fullfile(ProtocolInfo.SUBJECTS, app.currentSub.Surface(app.currentSub.iInnerSkull).FileName);
                        surface = load(filename);
                        patch( app.currentAxes,...
                            'Faces',            surface.Faces, ...
                            'Vertices',         surface.Vertices,...
                            'VertexNormals',    surface.VertNormals, ...
                            'VertexNormalsMode', 'auto',...
                            'FaceNormals',      [],...
                            'FaceNormalsMode',  'auto',...
                            'FaceVertexCData',  [],...
                            'FaceColor',        [0.7 0.7 0.7], ...
                            'FaceAlpha',        alpha, ...
                            'AlphaDataMapping', 'none', ...
                            'EdgeColor',        'none', ...
                            'EdgeAlpha',        alpha, ...
                            'LineWidth',         0.5,...
                            'LineJoin',         'miter',...
                            'AmbientStrength',  0.5, ...
                            'DiffuseStrength',  0.5, ...
                            'SpecularStrength', 0.2, ...
                            'SpecularExponent', 1, ...
                            'SpecularColorReflectance', 0.5, ...
                            'FaceLighting',     'gouraud', ...
                            'EdgeLighting',     'gouraud', ...
                            'Tag',              'innerskull');
                        alpha = alpha - 0.10;

                    case 'OuterSkull'
                        filename = fullfile(ProtocolInfo.SUBJECTS, app.currentSub.Surface(app.currentSub.iOuterSkull).FileName);
                        surface = load(filename);
                        patch( app.currentAxes,...
                            'Faces',            surface.Faces, ...
                            'Vertices',         surface.Vertices,...
                            'VertexNormals',    surface.VertNormals, ...
                            'VertexNormalsMode', 'auto',...
                            'FaceNormals',      [],...
                            'FaceNormalsMode',  'auto',...
                            'FaceVertexCData',  [], ...
                            'FaceColor',        [0.7 0.7 0.7], ...
                            'FaceAlpha',        alpha, ...
                            'AlphaDataMapping', 'none', ...
                            'EdgeColor',        'none', ...
                            'EdgeAlpha',        alpha, ...
                            'LineWidth',         0.5,...
                            'LineJoin',         'miter',...
                            'AmbientStrength',  0.5, ...
                            'DiffuseStrength',  0.5, ...
                            'SpecularStrength', 0.2, ...
                            'SpecularExponent', 1, ...
                            'SpecularColorReflectance', 0.5, ...
                            'FaceLighting',     'gouraud', ...
                            'EdgeLighting',     'gouraud', ...
                            'Tag',              'outerskull');
                        alpha = alpha - 0.10;

                    case 'Scalp'
                        filename = fullfile(ProtocolInfo.SUBJECTS, app.currentSub.Surface(app.currentSub.iScalp).FileName);
                        surface = load(filename);
                        patch( app.currentAxes,...
                            'Faces',            surface.Faces, ...
                            'Vertices',         surface.Vertices,...
                            'VertexNormals',    surface.VertNormals, ...
                            'VertexNormalsMode', 'auto',...
                            'FaceNormals',      [],...
                            'FaceNormalsMode',  'auto',...
                            'FaceVertexCData',  [], ...
                            'FaceColor',        [0.7 0.7 0.7], ...
                            'FaceAlpha',        alpha, ...
                            'AlphaDataMapping', 'none', ...
                            'EdgeColor',        'none', ...
                            'EdgeAlpha',        alpha, ...
                            'LineWidth',         0.5,...
                            'LineJoin',         'miter',...
                            'AmbientStrength',  0.5, ...
                            'DiffuseStrength',  0.5, ...
                            'SpecularStrength', 0.2, ...
                            'SpecularExponent', 1, ...
                            'SpecularColorReflectance', 0.5, ...
                            'FaceLighting',     'gouraud', ...
                            'EdgeLighting',     'gouraud', ...
                            'Tag',              'scalp');
                        alpha = alpha - 0.10;
                end
            end
            set(app.currentAxes,"Parent",app.PanelViewer);
            rotate3d(app.currentAxes,'on');
            view(app.currentAxes,0,0);
        end
        
        function PlotChannelsPosition(app, update)
            ProtocolInfo = bst_get('ProtocolInfo'); 
            Sscalp = load(fullfile(ProtocolInfo.SUBJECTS,app.currentSub.Surface(app.currentSub.iScalp).FileName));
            
            if(update)
                Channel = app.currentSub.Transformation.Cdata.Channel;
                ChanLoc     = [Channel.Loc]';
                delete(app.currentAxes.Children(1));
                ctColor         = [.9,.9,0]; % Yellow
                nVert           = 34;
                dSize           = 5;
                ElectrodeGrid = CreateGeometry3DElectrode_2(ChanLoc,'EEG',Sscalp, ctColor, nVert, dSize);
                patch(app.currentAxes,...
                    'Faces',               ElectrodeGrid.Faces, ...
                    'Vertices',            ElectrodeGrid.Vertices,...
                    'FaceVertexCData',     ElectrodeGrid.FaceVertexCData, ...
                    'FaceVertexAlphaData', ElectrodeGrid.FaceVertexAlphaData, ...
                    'FaceColor',           'flat', ...
                    'FaceAlpha',           'flat', ...
                    'AlphaDataMapping',    'none', ...
                    ElectrodeGrid.Options{:});
            else
                channelFile = app.currentSub.Study.Channel.FileName;
                Channel     = load(fullfile(ProtocolInfo.STUDIES,channelFile)).Channel;
                ChanLoc     = [Channel.Loc]';
                delete(app.currentAxes);
                template = load('axes.mat');
                app.currentAxes = template.axes;                
                patch(app.currentAxes, ...
                    'Faces',Sscalp.Faces, ...
                    'Vertices',Sscalp.Vertices, ...
                    'FaceVertexCData',[], ...
                    'FaceColor',[.6 .6 .6], ...
                    'EdgeColor','none', ...
                    'AlphaDataMapping', 'none', ...
                    'EdgeColor',        'none', ...
                    'EdgeAlpha',        1, ...
                    'BackfaceLighting', 'lit', ...
                    'AmbientStrength',  0.5, ...
                    'DiffuseStrength',  0.5, ...
                    'SpecularStrength', 0.2, ...
                    'SpecularExponent', 1, ...
                    'SpecularColorReflectance', 0.5, ...
                    'FaceLighting',     'gouraud', ...
                    'EdgeLighting',     'gouraud', ...
                    'FaceAlpha',.99, ...
                    'Tag', 'Scalp');
                ctColor         = [.9,.9,0]; % Yellow
                nVert           = 34;
                dSize           = 5;
                ElectrodeGrid = CreateGeometry3DElectrode_2(ChanLoc,'EEG',Sscalp, ctColor, nVert, dSize);
                patch(app.currentAxes,...
                    'Faces',               ElectrodeGrid.Faces, ...
                    'Vertices',            ElectrodeGrid.Vertices,...
                    'FaceVertexCData',     ElectrodeGrid.FaceVertexCData, ...
                    'FaceVertexAlphaData', ElectrodeGrid.FaceVertexAlphaData, ...
                    'FaceColor',           'flat', ...
                    'FaceAlpha',           'flat', ...
                    'AlphaDataMapping',    'none', ...
                    ElectrodeGrid.Options{:});
                app.currentAxes.Colormap = app.colorMap.cmap_a;
                set(app.currentAxes,"Parent",app.PanelViewer);
                rotate3d(app.currentAxes,'on');
                view(app.currentAxes,90,0);
            end
        end
        
        function PlotHeadmodel(app)
            delete(app.PanelViewer.Children);
            delete(app.currentAxes);
            template = load('axes.mat');
            app.currentAxes = template.axes;
            elecName = app.PanelControls.Children(1).Value;

            ProtocolInfo = bst_get('ProtocolInfo'); 
            Sscalp = load(fullfile(ProtocolInfo.SUBJECTS,app.currentSub.Surface(app.currentSub.iScalp).FileName));
            Cortex = load(fullfile(ProtocolInfo.SUBJECTS,app.currentSub.Surface(app.currentSub.iCortex).FileName));
            channelFile = app.currentSub.Study.Channel.FileName;
            Channel     = load(fullfile(ProtocolInfo.STUDIES,channelFile)).Channel;
            headmodelFile = app.currentSub.Study.HeadModel(app.currentSub.Study.iHeadModel).FileName;
            Headmodel     = load(fullfile(ProtocolInfo.STUDIES,headmodelFile));

            elecIndex   = find(ismember({Channel.Name},elecName),1);
            %% initial computations
            %% plot Lead Field
            [Ne,Nv]     = size(Headmodel.Gain);
            Nv          = Nv/3;
            XYZ         = Cortex.Vertices;
            Kn          = reshape(Headmodel.Gain,Ne,3,Nv);
            Kn          = permute(Kn,[1,3,2]);
            Kq          = permute(Kn, [1 3 2]);
            Kq          = permute(Kq, [3 2 1]);
            Kqm         = sqrt(dot(Kq,Kq,2));
            KqmC        = Kqm(:,:,elecIndex);
            quiver3(app.currentAxes,XYZ(:,1),XYZ(:,2),XYZ(:,3), ...
                Kq(:,1,elecIndex),Kq(:,2,elecIndex),Kq(:,3,elecIndex), ...
                'LineWidth',2,'Color',[0 0 1],'AutoScaleFactor',5);
            

            %% define electrode to show and Marker size
            patch( app.currentAxes,...
                'Faces',            Cortex.Faces, ...
                'Vertices',         Cortex.Vertices,...
                'VertexNormals',    Cortex.VertNormals, ...
                'VertexNormalsMode', 'auto',...
                'FaceNormals',      [],...
                'FaceNormalsMode',  'auto',...
                'FaceVertexCData',  KqmC, ...
                'FaceColor',        'inter', ...
                'FaceAlpha',        1, ...
                'AlphaDataMapping', 'none', ...
                'EdgeColor',        'none', ...
                'EdgeAlpha',        1, ...
                'LineWidth',         0.5,...
                'LineJoin',         'miter',...
                'AmbientStrength',  0.5, ...
                'DiffuseStrength',  0.5, ...
                'SpecularStrength', 0.2, ...
                'SpecularExponent', 1, ...
                'SpecularColorReflectance', 0.5, ...
                'FaceLighting',     'gouraud', ...
                'EdgeLighting',     'gouraud', ...
                'Tag',              'AnatSurface');
            

            % Ploting channles
            ChanLoc         = [Channel.Loc]';
            ctColor         = [.9,.9,0]; % Yellow
            nVert = 34;
            dSize = 4;
            ElectrodeGrid = CreateGeometry3DElectrode_2(ChanLoc,'EEG',Sscalp, ctColor, nVert, dSize);
            patch(app.currentAxes,...
                'Faces',               ElectrodeGrid.Faces, ...
                'Vertices',            ElectrodeGrid.Vertices,...
                'FaceVertexCData',     ElectrodeGrid.FaceVertexCData, ...
                'FaceVertexAlphaData', ElectrodeGrid.FaceVertexAlphaData, ...
                'FaceColor',           'flat', ...
                'FaceAlpha',           'flat', ...
                'AlphaDataMapping',    'none', ...
                ElectrodeGrid.Options{:});

            % Ploting selected channel
            SelectChannel         = Channel(elecIndex);
            SelectChanLoc         = [SelectChannel.Loc]';
            ctColor         = [1 0 0];
            ElectrodeGrid = CreateGeometry3DElectrode_2(SelectChanLoc,'EEG',Sscalp, ctColor, nVert, dSize);
            patch(app.currentAxes,...
                'Faces',               ElectrodeGrid.Faces, ...
                'Vertices',            ElectrodeGrid.Vertices,...
                'FaceVertexCData',     ElectrodeGrid.FaceVertexCData, ...
                'FaceVertexAlphaData', ElectrodeGrid.FaceVertexAlphaData, ...
                'FaceColor',           'flat', ...
                'FaceAlpha',           'flat', ...
                'AlphaDataMapping',    'none', ...
                ElectrodeGrid.Options{:});
            app.currentAxes.Colormap = app.colorMap.cmap_a;
            set(app.currentAxes,"Parent",app.PanelViewer);
            rotate3d(app.currentAxes,'on');
            view(app.currentAxes,180,90);
            axis(app.currentAxes,'equal');
            axis(app.currentAxes,'off');
        end
        
        function ChannelsCorrection(app, cbt, val)
            options = cbt.Parent.Children;
            optionSelected = '';
            for i=1:length(options)
                option = options(i);
                if(isequal(class(option),'matlab.ui.control.StateButton') && option.Value)
                    optionSelected = option;
                    break;
                end
            end 
            if(isequal(optionSelected,''))
                d = uiprogressdlg(app.CiftiStormUIFigure,'Title','Waiting for message box');
                msg = msgbox('Please select an option for the Channels correction.','Info'); 
                waitfor(msg);
                close(d);
                figure(app.CiftiStormUIFigure);
                return;
            end            
            Cdata = app.currentSub.Transformation.Cdata;
            FinalTransf = app.currentSub.Transformation.FinalTransf;
            [Rnew, Tnew, Rescale]  = ComputeChannelsTransf(optionSelected.Tag,val);
            SensorsVertices = [Cdata.Channel.Loc]';
            isChanged = 1:length(Cdata.Channel);
            [FinalTransf, SensorsVertices, isChanged] = ApplyChannelsTransf(FinalTransf, SensorsVertices, isChanged, Rnew, Tnew, Rescale);
            app.currentSub.Transformation.isChanged = isChanged;
            app.currentSub.Transformation.FinalTransf = FinalTransf;
            for i=1:length(app.currentSub.Transformation.Cdata.Channel)
                app.currentSub.Transformation.Cdata.Channel(i).Loc(:) = SensorsVertices(i,:);
            end
            app.PlotChannelsPosition(true);
        end
        
        function SelectChannelOption(app, cbt)
            options = cbt.Parent.Children;
            for i=1:length(options)
                option = options(i);
                if(~isequal(cbt.Tag,option.Tag) && isequal(class(option),'matlab.ui.control.StateButton'))
                    option.Value = false;
                end
            end            
        end
        
        function SaveChannelsCorrections(app, cbt)
            options = cbt.Parent.Children;
            for i=1:length(options)
                option = options(i);
                if(isequal(class(option),'matlab.ui.control.StateButton'))
                    option.Value = false;
                end
            end
            if(app.currentSub.Transformation.isChanged)
                d = uiprogressdlg(app.CiftiStormUIFigure,'Title','Waiting for message box');
                ProtocolInfo = bst_get('ProtocolInfo');
                opts.Interpreter = 'tex';
                % Include the desired Default answer
                opts.Default = 'Cancel';
                % Use the TeX interpreter to format the question
                quest = {strcat("You have maked channel transformations"), ...
                    'Would you like save this changes?'};
                answer = questdlg(quest,'Apply changes',...
                    'Apply','Cancel','Discard',opts);
                % Handle response
                switch answer
                    case 'Apply'
                        Cdata = load(fullfile(ProtocolInfo.STUDIES,app.currentSub.Study.Channel.FileName));
                        transfSensors = [app.currentSub.Transformation.Cdata.Channel.Loc]';
                        for i=1:length(Cdata.Channel)
                            Cdata.Channel(i).Loc(:) = transfSensors(i,:);
                        end                        
                        save(fullfile(ProtocolInfo.STUDIES,app.currentSub.Study.Channel.FileName),'-struct','Cdata');
                        app.currentSub.Transformation.isTransformed = true;
                        app.currentSub.Transformation.isChanged = false;
                        app.currentSub.Transformation.FinalTransf = eye(4);

                        app.MarkSubjectToRecompute();                        
                        msg = msgbox('The Channel transformations was successfully saved.','Info');
                        waitfor(msg);
                    case 'Cancel'
                        close(d);
                        figure(app.CiftiStormUIFigure);
                        return;
                    case 'Discard'
                        app.currentSub.Transformation.Cdata = load(fullfile(ProtocolInfo.STUDIES,app.currentSub.Study.Channel.FileName));
                        app.currentSub.Transformation.isChanged = false;
                        app.currentSub.Transformation.FinalTransf = eye(4);
                end                
                close(d);
                figure(app.CiftiStormUIFigure);
                app.PlotChannelsPosition(false);
            end
            
        end
        
        function ResetChannelsCorrections(app, cbt)  
            options = cbt.Parent.Children;
            for i=1:length(options)
                option = options(i);
                if(isequal(class(option),'matlab.ui.control.StateButton'))
                    option.Value = false;
                end
            end  
            ProtocolInfo = bst_get('ProtocolInfo');
            Cdata = load(fullfile(ProtocolInfo.STUDIES,app.currentSub.Study.Channel.FileName));
            app.currentSub.Transformation.Cdata = Cdata;
            app.currentSub.Transformation.isChanged = false;
            app.currentSub.Transformation.isTransformed = false;
            app.currentSub.Transformation.FinalTransf = eye(4);
            app.PlotChannelsPosition(false);
        end
        
        function ChannelsControls(app)
             % Channels controls
             if(isequal(class(app.PanelViewer.Children(1)),'matlab.ui.container.Panel'))
                delete(app.PanelViewer.Children(1))
             end
            y = app.PanelViewer.Position(4) - 130;
            pn = uipanel(Title="Channels correction options",Position=[10 y 210 98], Parent=app.PanelViewer, FontWeight="bold");

            % Options in X
            labelX = uilabel(Text="X",Position=[5 48 22 30],Parent=pn,FontSize=14, FontWeight="bold");
            RotX = uibutton('state',Text="",Icon="guide/images/rotation.png",Position=[20 52 22 22],Parent=pn,Tag="RotX", ...
                Tooltip="Rotation on X",ValueChangedFcn=@(cbt,event) app.SelectChannelOption(cbt));
            TransX = uibutton('state',Text="",Icon="guide/images/translation.png",Position=[45 52 22 22],Parent=pn,Tag="TransX", ...
                Tooltip="Translation on X",ValueChangedFcn=@(cbt,event) app.SelectChannelOption(cbt));
            ResizeX = uibutton('state',Text="",Icon="guide/images/resize.png",Position=[70 52 22 22],Parent=pn,Tag="ResizeX", ...
                Tooltip="Resize on X",ValueChangedFcn=@(cbt,event) app.SelectChannelOption(cbt));
             % Options in Y
            labelY = uilabel(Text="Y",Position=[5 24 30 30],Parent=pn,FontSize=14, FontWeight="bold");
            RotY = uibutton('state',Text="", Icon="guide/images/rotation.png",Position=[20 27 22 22],Parent=pn,Tag="RotY", ...
                Tooltip="Rotation on Y",ValueChangedFcn=@(cbt,event) app.SelectChannelOption(cbt));
            TransY = uibutton('state',Text="", Icon="guide/images/translation.png",Position=[45 27 22 22],Parent=pn,Tag="TransY", ...
                Tooltip="Translation on Y",ValueChangedFcn=@(cbt,event) app.SelectChannelOption(cbt));
            ResizeY = uibutton('state',Text="", Icon="guide/images/resize.png",Position=[70 27 22 22],Parent=pn,Tag="ResizeY", ...
                Tooltip="Resize on Y",ValueChangedFcn=@(cbt,event) app.SelectChannelOption(cbt));
             % Options in Z
            labelZ = uilabel(Text="Z",Position=[5 0 22 30],Parent=pn,FontSize=14, FontWeight="bold");
            RotZ = uibutton('state',Text="", Icon="guide/images/rotation.png",Position=[20 3 22 22],Parent=pn,Tag="RotZ", ...
                Tooltip="Rotation on Z",ValueChangedFcn=@(cbt,event) app.SelectChannelOption(cbt));
            TransZ = uibutton('state',Text="", Icon="guide/images/translation.png",Position=[45 3 22 22],Parent=pn,Tag="TransZ", ...
                Tooltip="Translation on Z",ValueChangedFcn=@(cbt,event) app.SelectChannelOption(cbt));
            ResizeZ = uibutton('state',Text="", Icon="guide/images/resize.png",Position=[70 3 22 22],Parent=pn,Tag="ResizeZ", ...
                Tooltip="Resize on Z",ValueChangedFcn=@(cbt,event) app.SelectChannelOption(cbt));

            % selectSensor = uibutton(Text="", Icon="guide/images/selectSensor.png",Position=[120 50 25 25],Parent=pn,Tag="sensor", ...
            %     Tooltip="Project sensors to Scalp", ButtonPushedFcn=@(cbt,event) app.PojectSensorsToScalp());
            projectSensors = uibutton(Text="", Icon="guide/images/project.png",Position=[127 50 25 25],Parent=pn,Tag="project", ...
                Tooltip="Project sensors to Scalp", ButtonPushedFcn=@(cbt,event) app.ChannelProjectToScalp(cbt));
            reset = uibutton(Text="", Icon="guide/images/reset.png",Position=[155 50 25 25],Parent=pn,Tag="reset", ...
                Tooltip="Reset corrections", ButtonPushedFcn=@(cbt,event) app.ResetChannelsCorrections(cbt));
            save = uibutton(Text="", Icon="guide/images/OK.png",Position=[183 50 25 25],Parent=pn,Tag="save", ...
                Tooltip="Save corrections", ButtonPushedFcn=@(cbt,event) app.SaveChannelsCorrections(cbt));

            left = uibutton(Text="", Icon="guide/images/left.png",Position=[140 10 25 25],Parent=pn, ...
                ButtonPushedFcn=@(cbt,event) app.ChannelsCorrection(cbt, -0.001),Tag="left");
            right = uibutton(Text="", Icon="guide/images/right.png",Position=[170 10 25 25],Parent=pn, ...
                ButtonPushedFcn=@(cbt,event) app.ChannelsCorrection(cbt, 0.001),Tag="right");
        end
        
        function ChannelProjectToScalp(app, cbt)
            options = cbt.Parent.Children;
            for i=1:length(options)
                option = options(i);
                if(isequal(class(option),'matlab.ui.control.StateButton'))
                    option.Value = false;
                end
            end
            if(app.currentSub.Transformation.isChanged)
                ProtocolInfo = bst_get('ProtocolInfo');
                Cdata = app.currentSub.Transformation.Cdata;
                Sscalp = load(fullfile(ProtocolInfo.SUBJECTS,app.currentSub.Surface(app.currentSub.iScalp).FileName));

                % Project electrodes on the surface
                ChanLocProj = channel_project_scalp(Sscalp.Vertices, [Cdata.Channel.Loc]');
                for i=1:length(app.currentSub.Transformation.Cdata.Channel)
                    app.currentSub.Transformation.Cdata.Channel(i).Loc(:) = ChanLocProj(i,:);
                end
                app.currentSub.Transformation.isChanged = true;
                app.currentSub.Transformation.FinalTransf = eye(4);
                app.PlotChannelsPosition(true);
            end
        end
        
        function MarkSubjectToRecompute(app)
            if(isequal(app.currentSub.Transformation.isTransformed,true))
                indx = find(ismember({app.TreeDatasets.Children.Text},app.currentSub.Name),1);
                app.TreeDatasets.Children(indx).Icon = "guide/images/subjectY.png";
                nodeStyle = uistyle;
                nodeStyle.FontColor = [0.9290 0.6940 0.1250];
                addStyle(app.TreeDatasets , nodeStyle , 'node' , app.TreeDatasets.Children(indx));

                % Including subject annotation to recompute

            end
        end
        
        function fillDatasetTab(app)
            %%
            %% Filling Datasets Tab
            %%

            app.DatasetsDropDown.Items = {app.Datasets.Name};
            app.DatasetsDropDown.ItemsData = {app.Datasets.UUID};
            app.DatasetsDropDown.Items{end+1} = '--Select--';
            app.DatasetsDropDown.ItemsData{end+1} = '--Select--';
            app.DatasetsDropDown.Value = app.currentDT.UUID;

            % Updating Dataset Tree
            delete(app.TreeDatasets.Children);
            subjects = bst_get('ProtocolSubjects').Subject;
            for i=1:length(subjects)
                subject = subjects(i);
                node = uitreenode(app.TreeDatasets, 'Text', subject.Name);
                node.NodeData = subject;
                node.UserData = 'subject';
                icon_file = 'guide/images/subject.png';
                node.Icon = icon_file;

                anat = subject.Anatomy(subject.iAnatomy);
                if(~isempty(anat))
                    subnode = uitreenode(node, 'Text', 'MRI');
                    subnode.Icon = 'guide/images/MRI.png';
                    subnode.NodeData = anat;
                end

                subnode = uitreenode(node, 'Text', 'BEM');
                subnode.Icon = 'guide/images/scalp.png';
                subnode.NodeData.Comment = 'BEM';

                scalp = subject.Surface(subject.iScalp);
                if(~isempty(scalp))
                    subnode = uitreenode(node, 'Text', 'Scalp');
                    subnode.Icon = 'guide/images/scalp.png';
                    subnode.NodeData = scalp;
                end

                outer = subject.Surface(subject.iOuterSkull);
                if(~isempty(outer))
                    subnode = uitreenode(node, 'Text', 'OuterSkull');
                    subnode.Icon = 'guide/images/outerskull.png';
                    subnode.NodeData = outer;
                end

                inner = subject.Surface(subject.iInnerSkull);
                if(~isempty(inner))
                    subnode = uitreenode(node, 'Text', 'InnerSkull');
                    subnode.Icon = 'guide/images/innerskull.png';
                    subnode.NodeData = inner;
                end

                cortex = subject.Surface(subject.iCortex);
                if(~isempty(cortex))
                    subnode = uitreenode(node, 'Text', 'Cortex');
                    subnode.Icon = 'guide/images/cortex_tree.png';
                    subnode.NodeData = cortex;
                end

                study = bst_get('StudyWithSubject', subject.FileName, 'intra_subject');
                node.NodeData.Study = study;
                if(~isempty(study.Channel))
                    channel = study.Channel;
                    subnode = uitreenode(node, 'Text', 'Channels');
                    subnode.Icon = 'guide/images/channels.png';
                    subnode.NodeData = channel;
                end
                if(~isempty(study.iHeadModel) && ~isempty(study.HeadModel(study.iHeadModel)))
                    headmodel = study.HeadModel(study.iHeadModel);
                    subnode = uitreenode(node, 'Text', 'Headmodel');
                    subnode.Icon = 'guide/images/headmodel.jpeg';
                    subnode.NodeData = headmodel;
                end
            end
            [icondata,iconcmap] = imread("guide/images/success.png");
            msg = msgbox({'The Dataset was loaded successfully!!!'},'Success',"custom",icondata,iconcmap);
            waitfor(msg);
            disp("-->> Dataset loaded");
            cfs_set('datasets',app.Datasets);
        end
        
        function UpdateDatasetTree(app)
            delete(app.TreeDatasets.Children);
            subjects = bst_get('ProtocolSubjects').Subject;
            for i=1:length(subjects)
                subject = subjects(i);

                node = uitreenode(app.TreeDatasets, 'Text', subject.Name);
                node.NodeData = subject;
                node.UserData = 'subject';
                icon_file = 'guide/images/subject.png';
                node.Icon = icon_file;

                anat = subject.Anatomy(subject.iAnatomy);
                if(~isempty(anat))
                    subnode = uitreenode(node, 'Text', 'MRI');
                    subnode.Icon = 'guide/images/MRI.png';
                    subnode.NodeData = anat;
                end

                subnode = uitreenode(node, 'Text', 'BEM');
                subnode.Icon = 'guide/images/scalp.png';
                subnode.NodeData.Comment = 'BEM';

                scalp = subject.Surface(subject.iScalp);
                if(~isempty(scalp))
                    subnode = uitreenode(node, 'Text', 'Scalp');
                    subnode.Icon = 'guide/images/scalp.png';
                    subnode.NodeData = scalp;
                end

                outer = subject.Surface(subject.iOuterSkull);
                if(~isempty(outer))
                    subnode = uitreenode(node, 'Text', 'OuterSkull');
                    subnode.Icon = 'guide/images/outerskull.png';
                    subnode.NodeData = outer;
                end

                inner = subject.Surface(subject.iInnerSkull);
                if(~isempty(inner))
                    subnode = uitreenode(node, 'Text', 'InnerSkull');
                    subnode.Icon = 'guide/images/innerskull.png';
                    subnode.NodeData = inner;
                end

                cortex = subject.Surface(subject.iCortex);
                if(~isempty(cortex))
                    subnode = uitreenode(node, 'Text', 'Cortex');
                    subnode.Icon = 'guide/images/cortex_tree.png';
                    subnode.NodeData = cortex;
                end

                study = bst_get('StudyWithSubject', subject.FileName, 'intra_subject');
                node.NodeData.Study = study;
                if(~isempty(study.Channel))
                    channel = study.Channel;
                    subnode = uitreenode(node, 'Text', 'Channels');
                    subnode.Icon = 'guide/images/channels.png';
                    subnode.NodeData = channel;
                end
                if(~isempty(study.iHeadModel) && ~isempty(study.HeadModel(study.iHeadModel)))
                    headmodel = study.HeadModel(study.iHeadModel);
                    subnode = uitreenode(node, 'Text', 'Headmodel');
                    subnode.Icon = 'guide/images/headmodel.jpeg';
                    subnode.NodeData = headmodel;
                end
            end
        end
        
        function set_default_properties(app)
            app.cfs_properties.general_params       = app.cfs_properties.defaults.general_params;
            app.cfs_properties.anatomy_params       = app.cfs_properties.defaults.anatomy_params;
            app.cfs_properties.channel_params       = app.cfs_properties.defaults.channel_params;
            app.cfs_properties.headmodel_params     = app.cfs_properties.defaults.headmodel_params;
            app.cfs_properties.integration_params   = app.cfs_properties.defaults.integration_params;

            % Saving property files
            saveJSON(app.cfs_properties.general_params,'cfs_properties/general_params.json');
            h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/general_params.json'));
            h.smartIndentContents
            h.save
            h.close
            saveJSON(app.cfs_properties.anatomy_params,'cfs_properties/process_import_anat.json');
            h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/process_import_anat.json'));
            h.smartIndentContents
            h.save
            h.close
            saveJSON(app.cfs_properties.channel_params,'cfs_properties/process_import_channel.json');
            h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/process_import_channel.json'));
            h.smartIndentContents
            h.save
            h.close
            saveJSON(app.cfs_properties.headmodel_params,'cfs_properties/process_comp_headmodel.json');
            h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/process_comp_headmodel.json'));
            h.smartIndentContents
            h.save
            saveJSON(app.cfs_properties.integration_params,'cfs_properties/process_integration.json');
            h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/process_integration.json'));
            h.smartIndentContents
            h.save
        end
    end

    methods (Access = public)

        function DefineProperties(app,event,action,clasif,nodeData)
            switch clasif
                case 'Output'
                    base_path = fullfile(nodeData.folder,nodeData.name);
                    app.cfs_properties.general_params.output_path = base_path;
                    saveJSON(app.cfs_properties.general_params,'cfs_properties/general_params.json');
                    h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/general_params.json'));
                    h.smartIndentContents
                    h.save
                    h.close
                case 'Anatomy'
                    base_path = fullfile(nodeData.folder,nodeData.name);
                    structures = dir(base_path);
                    structures(ismember( {structures.name}, {'.', '..'})) = [];  %remove . and ..
                    count = 0;
                    for i=1:length(structures)
                        structure = structures(i);
                        if(~isfile(fullfile(structure.folder,structure.name,'T1w','T1w.nii.gz'))...
                                && ~isfile(fullfile(structure.folder,structure.name,'T1w','T1w_acpc_dc_restore.nii.gz'))...
                                && ~isfile(fullfile(structure.folder,structure.name,'T1w','T1w_acpc_dc.nii.gz'))...
                                && ~isfile(fullfile(structure.folder,structure.name,'T1w','T2w.nii.gz'))...
                                && ~isfile(fullfile(structure.folder,structure.name,'T1w','T2w_acpc_dc_restore.nii.gz'))...
                                && ~isfile(fullfile(structure.folder,structure.name,'T1w','T2w_acpc_dc.nii.gz')))
                            count = count + 1;
                        end
                    end
                    if(isequal(count,length(structures)))
                        msgbox({'The selected folder do not contain any Ciftify structure',...
                            'Please select a correct anatomy folder.'},'Info');
                        return;
                    end
                    if(count > 0)
                        msgbox({'One or more subject forders are not a HCP structure.',...
                            'Please check the Anatomy folder or you can continue in this way.'},'Info');
                    end
                    app.cfs_properties.anatomy_params.anatomy_type.type_list{2}.base_path = base_path;
                    saveJSON(app.cfs_properties.anatomy_params,'cfs_properties/process_import_anat.json');
                    h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/process_import_anat.json'));
                    h.smartIndentContents
                    h.save
                    h.close
                case 'Non-brain'
                    base_path = fullfile(nodeData.folder,nodeData.name);
                    structures = dir(base_path);
                    structures(ismember( {structures.name}, {'.', '..'})) = [];  %remove . and ..
                    count = 0;
                    for i=1:length(structures)
                        structure = structures(i);
                        if(~isfile(fullfile(structure.folder,structure.name,[structure.name,'_outskin_mesh.nii.gz']))...
                                || ~isfile(fullfile(structure.folder,structure.name,[structure.name,'_outskull_mesh.nii.gz']))...
                                || ~isfile(fullfile(structure.folder,structure.name,[structure.name,'_inskull_mesh.nii.gz'])))
                            count = count + 1;
                        end
                    end
                    if(isequal(count,length(structures)))
                        msgbox({'The selected folder do not contain any FSL Bet structure',...
                            'Please select a correct anatomy folder.'},'Info');
                        return;
                    end
                    if(count > 0)
                        msgbox({'One or more subject forders are not a FSL Bet structure.',...
                            'Please check the FSL non-brain surfaces folder or you can continue in this way.'},'Info');
                    end
                    app.cfs_properties.anatomy_params.common_params.non_brain_surfaces.base_path = base_path;
                    saveJSON(app.cfs_properties.anatomy_params,'cfs_properties/process_import_anat.json');
                    h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/process_import_anat.json'));
                    h.smartIndentContents
                    h.save
                    h.close
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.CiftiStormUIFigure,"center");            
            app.cfs_properties = get_properties();         
            
            % Checking dependencies
            BST_path = app.cfs_properties.general_params.bst_config.bst_path;            
            if(~cfs_check_properties('BST_path',BST_path))
                d = uiprogressdlg(app.CiftiStormUIFigure,'Title','Waiting for the parameters configuration');
                opts.Interpreter = 'tex';
                % Include the desired Default answer
                opts.Default = 'Set';
                % Use the TeX interpreter to format the question
                quest = {strcat("The Brainstorm path is not defined"), ...
                    'Please define it before continue'};
                answer = questdlg(quest,'BST path',...
                    'Set','No',opts);
                % Handle response
                switch answer
                    case 'Set'
                        try
                            while true
                                folder = uigetdir("Select the Brinstorm path");
                                if(isfile(fullfile(folder,'brainstorm.m')))
                                    app.cfs_properties.general_params.bst_config.bst_path = folder;
                                    % saving property files
                                    saveJSON(app.cfs_properties.general_params,'cfs_properties/general_params.json');
                                    h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/general_params.json'));
                                    h.smartIndentContents
                                    h.save
                                    h.close
                                    msg = msgbox({'The Brainstorm path was successfully defined'},'Info');
                                    waitfor(msg);                                    
                                    delete(d);                                    
                                    figure(app.CiftiStormUIFigure);
                                    break;
                                else                                    
                                    opts.Interpreter = 'tex';
                                    % Include the desired Default answer
                                    opts.Default = 'Yes';
                                    % Use the TeX interpreter to format the question
                                    quest = {strcat("The selected folder do not contain the BrainStorm toolbox."), ...
                                        'Would you like to define a correct Brainstorm path?'};
                                    answer = questdlg(quest,'BST path',...
                                        'Yes','Cancel',opts);
                                    switch answer
                                        case 'Yes'
                                            
                                        case 'Cancel'
                                            msgbox({'The process will be stoped.', ...
                                                ' Please select a correct Brainstorm path.'},'Info');
                                            delete(app);
                                            return;
                                    end
                                end
                            end
                        catch
                            msgbox({'The process will be stoped.', ...
                                ' Please select a correct Brainstorm path.'},'Info');
                            delete(app);
                            return;
                        end
                    case 'No'
                        msgbox({'The process will be stoped.', ...
                            ' Please select a correct Brainstorm path.'},'Info');
                        delete(app);
                        return;
                end
            end
            d = uiprogressdlg(app.CiftiStormUIFigure,'Title','Starting Brainstorm in background.');
            status          = starting_brainstorm(app.cfs_properties);
            if(status)
                delete(d);
            end

            % Load Explorer tree
            figure(app.CiftiStormUIFigure);
            userDir = getUserDir();
            app.Breadcrumbs(userDir);
            app.colorMap = load('templates/mycolormap_brain_basic_conn.mat');
            parent = uitreenode(app.TreeInput,'Text',userDir);
            parent.Icon = 'guide/images/folder.png';
            file_list = dir(userDir);
            file_list(ismember({file_list.name},{'.'})) = [];
            for i=1:length(file_list)
                fileStruct = file_list(i);
                if(fileStruct.isdir)
                    node = uitreenode(parent, 'Text', fileStruct.name);
                    node.NodeData = fileStruct;
                    if(isequal(fileStruct.name,'..'))
                        icon_file = 'guide/images/folder_parent.png';
                    else
                        icon_file = 'guide/images/folder.png';
                    end
                    % Context menu
                    cm = uicontextmenu(app.CiftiStormUIFigure);
                    mo = uimenu(cm,"Text","Define as Output folder","MenuSelectedFcn",{@app.DefineProperties,'Output',fileStruct},'Separator','on');

                    m1 = uimenu(cm,"Text","Define as Anatomy folder","MenuSelectedFcn",{@app.DefineProperties,'Anatomy',fileStruct});
                    m2 = uimenu(cm,"Text","Define as Non-Brain folder","MenuSelectedFcn",{@app.DefineProperties,'Non-brain',fileStruct});
                    node.ContextMenu = cm;
                    node.Icon = icon_file;
                end
            end
            for i=1:length(file_list)
                fileStruct = file_list(i);
                if(~fileStruct.isdir)
                    node = uitreenode(parent, 'Text', fileStruct.name);
                    node.Icon = 'guide/images/file.jpeg';
                end
            end
            expand(parent);
 
            %% Load Datasets
            app.Datasets_file = cfs_get('datasets_file');
            app.Datasets = cfs_get('datasets');
            reject = [];
            if(~isempty(app.Datasets))
                for i=1:length(app.Datasets)
                    dt = app.Datasets(i);
                    if(~isfile(fullfile(dt.Location,'CiftiStorm.json')))
                        opts.Interpreter = 'tex';
                        % Include the desired Default answer
                        opts.Default = 'Update';
                        % Use the TeX interpreter to format the question
                        quest = {strcat("We can not find the Dataset: ", dt.Name, "in the defined location:"), ...
                            strcat(dt.Location),...
                            'Would you like to define a correct path or delete the Dataset information ?'};
                        answer = questdlg(quest,'Dataset path',...
                            'Update','Delete',opts);

                        % Handle response
                        switch answer
                            case 'Update'                                
                                    while true
                                        folder = uigetdir(strcat("Select the ",dt.Name," path"));
                                        if(~isfile(fullfile(folder,'CiftiStorm.json')))
                                            d = uiprogressdlg(app.CiftiStormUIFigure,'Title','Checking selected folder.');
                                            msg = msgbox({'The selected folder do not contain a CiftiStorm structure', ...
                                                'Please select a new Dataset path'},'Info');
                                            waitfor(msg);
                                            delete(d);
                                            figure(app.CiftiStormUIFigure);
                                        else
                                            tempDT = jsondecode(fileread(fullfile(folder,'CiftiStorm.json')));
                                            if(~isequal(dt.UUID,tempDT.UUID))
                                                d = uiprogressdlg(app.CiftiStormUIFigure,'Title','Checking selected dataset.');
                                                msg = msgbox({'The new configuration do not corresponde with the stored dataset', ...
                                                    'Please select a new Dataset path'},'Info');
                                                waitfor(msg);
                                                delete(d);
                                                figure(app.CiftiStormUIFigure);
                                            else
                                                iProtocol = bst_get('Protocol', tempDT.ProtocolName);
                                                if(isempty(iProtocol))
                                                    gui_brainstorm('SetCurrentProtocol', iProtocol);
                                                    for j=1:length(tempDT.Participants)
                                                        participant = tempDT.Participants(j);
                                                        part_info = jsondecode(fileread(fullfile(tempDT.Location,participant.SubID,participant.FileInfo)));
                                                        ZipFile = fullfile(tempDT.Location, participant.SubID, part_info.brainstorm);
                                                        import_subject(ZipFile);
                                                    end
                                                end
                                                app.Datasets(i) = tempDT;
                                                break;
                                            end                                         
                                        end
                                    end                                
                            case 'Delete'
                                reject = [reject, i];                                
                        end
                    else

                    end
                end

                app.Datasets(reject) = [];

                app.DatasetsDropDown.Items = {app.Datasets.Name};
                app.DatasetsDropDown.ItemsData = {app.Datasets.UUID};
                app.DatasetsDropDown.Items{end+1} = '--Select--';
                app.DatasetsDropDown.ItemsData{end+1} = '--Select--';
                app.DatasetsDropDown.Value = '--Select--';
                saveJSON(app.Datasets,app.Datasets_file);
                h = matlab.desktop.editor.openDocument(app.Datasets_file);
                h.smartIndentContents
                h.save
                h.close
            end

        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            brainstorm stop;
            delete(app);
        end

        % Callback function: NewprocessMenu, PushToolNewProcess
        function NewprocessMenuSelected(app, event)
            d = uiprogressdlg(app.CiftiStormUIFigure,'Title','Waiting for the parameters configuration');
            obj_configure = Params_configuration();
            waitfor(obj_configure);
            close(d)
            figure(app.CiftiStormUIFigure);
        end

        % Double-clicked callback: TreeInput
        function TreeInputDoubleClicked(app, event)
            node = event.InteractionInformation.Node;
            if(node.NodeData.isdir)
                ExploreFolder(app,node.NodeData);
            end
        end

        % Clicked callback: TreeInput
        function TreeInputClicked(app, event)
            node = event.InteractionInformation.Node;
            if(node.NodeData.isdir)
                FillFolder(app,node);
            end
        end

        % Callback function: PushToolRunProcess
        function PushToolRunProcessClicked(app, event)
            app.cfs_properties = get_properties('run');
            [~, reject_subjects]     = check_properties(app.cfs_properties);
            d = uiprogressdlg(app.CiftiStormUIFigure,'Title','Processing dataset. This process will take long time.');

            %% BrainStorm configuration
            disp('-->> Preparing BrainStorm properties.');
            disp('==========================================================================');
            bst_path        = app.cfs_properties.general_params.bst_config.bst_path;
            addpath(genpath(bst_path));
            status          = starting_brainstorm(app.cfs_properties);
            
            %% Calling dataset function to analysis
            if(status)
                %% Processing dataset
                datasetFile = cfs_process_interface(app.cfs_properties,reject_subjects,app.CiftiStormUIFigure);

                %% Registering datasete
                app.currentDT = jsondecode(fileread(datasetFile));
                if(isempty(app.Datasets))
                    app.Datasets = app.currentDT;
                else
                    app.Datasets(end + 1) = app.currentDT;
                end
                cfs_set('datasets',app.Datasets);
                app.DatasetsDropDown.Items = {app.Datasets.Name};
                app.DatasetsDropDown.ItemsData = {app.Datasets.UUID};
                app.DatasetsDropDown.Items{end+1} = '--Select--';
                app.DatasetsDropDown.ItemsData{end+1} = '--Select--';
                app.DatasetsDropDown.Value = app.currentDT.UUID;

                % Updating Dataset Tree
                delete(app.TreeDatasets.Children);
                ProtocolName = app.currentDT.ProtocolName;
                iProtocol = bst_get('Protocol', ProtocolName);
                gui_brainstorm('SetCurrentProtocol', iProtocol);
                subjects = bst_get('ProtocolSubjects').Subject;
                for i=1:length(subjects)
                    subject = subjects(i);

                    node = uitreenode(app.TreeDatasets, 'Text', subject.Name);
                    node.NodeData = subject;
                    node.UserData = 'subject';
                    icon_file = 'guide/images/subject.png';
                    node.Icon = icon_file;

                    anat = subject.Anatomy(subject.iAnatomy);
                    if(~isempty(anat))
                        subnode = uitreenode(node, 'Text', 'MRI');
                        subnode.Icon = 'guide/images/MRI.png';
                        subnode.NodeData = anat;
                    end

                    subnode = uitreenode(node, 'Text', 'BEM');
                    subnode.Icon = 'guide/images/scalp.png';
                    subnode.NodeData.Comment = 'BEM';

                    scalp = subject.Surface(subject.iScalp);
                    if(~isempty(scalp))
                        subnode = uitreenode(node, 'Text', 'Scalp');
                        subnode.Icon = 'guide/images/scalp.png';
                        subnode.NodeData = scalp;
                    end

                    outer = subject.Surface(subject.iOuterSkull);
                    if(~isempty(outer))
                        subnode = uitreenode(node, 'Text', 'OuterSkull');
                        subnode.Icon = 'guide/images/outerskull.png';
                        subnode.NodeData = outer;
                    end

                    inner = subject.Surface(subject.iInnerSkull);
                    if(~isempty(inner))
                        subnode = uitreenode(node, 'Text', 'InnerSkull');
                        subnode.Icon = 'guide/images/innerskull.png';
                        subnode.NodeData = inner;
                    end

                    cortex = subject.Surface(subject.iCortex);
                    if(~isempty(cortex))
                        subnode = uitreenode(node, 'Text', 'Cortex');
                        subnode.Icon = 'guide/images/cortex_tree.png';
                        subnode.NodeData = cortex;
                    end

                    study = bst_get('StudyWithSubject', subject.FileName, 'intra_subject');
                    node.NodeData.Study = study;
                    if(~isempty(study.Channel))
                        channel = study.Channel;
                        subnode = uitreenode(node, 'Text', 'Channels');
                        subnode.Icon = 'guide/images/channels.png';
                        subnode.NodeData = channel;
                    end
                    if(~isempty(study.iHeadModel) && ~isempty(study.HeadModel(study.iHeadModel)))
                        headmodel = study.HeadModel(study.iHeadModel);
                        subnode = uitreenode(node, 'Text', 'Headmodel');
                        subnode.Icon = 'guide/images/headmodel.jpeg';
                        subnode.NodeData = headmodel;
                    end
                end
                app.TabGroup.SelectedTab = app.TabDatasets;
            end
            delete(d);
            %% Stopping BrainStorm
            disp('==========================================================================');
            %% brainstorm('stop');
            disp('==========================================================================');
            disp(strcat('CFS -->> Dataset processed....'));
            disp('==========================================================================');
            close all;
            clear all;
        end

        % Menu selected function: MEEGprepMenu
        function MEEGprepMenuSelected(app, event)
            d = uiprogressdlg(app.CiftiStormUIFigure,'Title','Waiting for the parameters configuration');            
            meegprep;
            waitfor(obj_configure);
            close(d)
            figure(app.CiftiStormUIFigure);
        end

        % Menu selected function: BCVARETAMenu
        function BCVARETAMenuSelected(app, event)
            d = uiprogressdlg(app.CiftiStormUIFigure,'Title','Waiting for the parameters configuration');            
            bcvareta;
            waitfor(obj_configure);
            close(d)
            figure(app.CiftiStormUIFigure);
        end

        % Value changed function: DatasetsDropDown
        function DatasetsDropDownValueChanged(app, event)
            delete(app.TreeDatasets.Children);
            comment = app.DatasetsDropDown.Value;
            if(~isequal(comment,'--Select--'))
                uip = uiprogressdlg(app.CiftiStormUIFigure,'Title','Updating Dataset tree.');
                app.currentDT = app.Datasets(find(ismember({app.Datasets.UUID},comment),1));
                ProtocolName = app.currentDT.ProtocolName;
                iProtocol = bst_get('Protocol', ProtocolName);                
                gui_brainstorm('SetCurrentProtocol', iProtocol);                                            
                % Updating Dataset Tree
                app.UpdateDatasetTree();
                delete(uip);
            end            
        end

        % Callback function: DatasetLoadMenu, PushTool
        function DatasetLoadMenuSelected(app, event)
            homedir = char(java.lang.System.getProperty('user.home'));
            d = uiprogressdlg(app.CiftiStormUIFigure,'Title','Loading Dataset......');
            folder = uigetdir(homedir,"Select the Dataset folder");
            if(isequal(folder,0))
                close(d)
                figure(app.CiftiStormUIFigure);
                return;
            end            
            tempDT = jsondecode(fileread(fullfile(folder,'CiftiStorm.json')));
            tempDT.Location = fullfile(folder);
            if(~isempty(app.Datasets) && ~isempty(find(ismember({app.Datasets.UUID},tempDT.UUID),1)))
                opts.Interpreter = 'tex';
                % Include the desired Default answer
                opts.Default = 'No';
                % Use the TeX interpreter to format the question
                quest = {strcat("The selected Dataset is already in the CiftiStorm Database"), ...
                    'Would you like to import it anyway?'};
                answer = questdlg(quest,'Loading dataset',...
                    'Update','AsNew','No',opts);
                if(isequal(answer,'AsNew'))
                    inputDLG = inputdlg('Please type the new Dataset name:', ' Dataset', 1);
                    if(isempty(inputDLG))
                        m = msgbox({'The Protocol name can not be empty',...
                            ' Please load the database again.'},'Info');
                        waitfor(m);
                        return;
                    end
                    datasetName= char(inputDLG(1));
                    tempDT.Name = datasetName;
                end
            else                
                answer = 'AsNew';
            end

            % Handle response
            if(isequal(answer,'AsNew') || isequal(answer,'Update'))  
                ProtocolName = tempDT.ProtocolName;
                iProtocol = bst_get('Protocol', ProtocolName);
                if(isempty(iProtocol))
                    gui_brainstorm('CreateProtocol',ProtocolName , 0, 0);
                    iProtocol = bst_get('Protocol', ProtocolName);
                    gui_brainstorm('SetCurrentProtocol', iProtocol);
                else
                    opts.Interpreter = 'tex';
                    % Include the desired Default answer
                    opts.Default = 'Replace';
                    % Use the TeX interpreter to format the question
                    quest = {strcat("The Brainstorm protocol: ",ProtocolName," already exist"), ...
                        'Would you like to replace it or import as a new one?'};
                    answerP = questdlg(quest,'Loading dataset',...
                        'Replace','AsNew','Cancel',opts);
                    switch answerP
                        case 'Replace'
                            gui_brainstorm('DeleteProtocol', ProtocolName);
                            gui_brainstorm('CreateProtocol',ProtocolName , 0, 0);
                            iProtocol = bst_get('Protocol', ProtocolName);
                            gui_brainstorm('SetCurrentProtocol', iProtocol);                            
                        case 'AsNew'
                            inputDLG = inputdlg('Please type the new Protocol name:', ' Protocol', 1);
                            if(isempty(inputDLG))
                                m = msgbox({'The Protocol name can not be empty',...
                                    ' Please load the database again.'},'Info');
                                waitfor(m);
                                return;
                            end
                            newProtocolName= char(inputDLG(1));
                            tempDT.ProtocolName = newProtocolName;
                            gui_brainstorm('CreateProtocol',newProtocolName , 0, 0);
                            iProtocol = bst_get('Protocol', newProtocolName);
                            gui_brainstorm('SetCurrentProtocol', iProtocol);
                        case 'Cancel'
                            return;
                    end
                end
            else
                return;
            end

            %% Importing subjects to Brainstorm database
            uip = uiprogressdlg(app.CiftiStormUIFigure,'Title','Importing subjects to Brainstorm database.');
            for i=1:length(tempDT.Participants)
                participant = tempDT.Participants(i);
                part_info = jsondecode(fileread(fullfile(tempDT.Location,participant.SubID,participant.FileInfo)));
                ZipFile = fullfile(tempDT.Location,participant.SubID,part_info.brainstorm_dir);
                import_subject(ZipFile);
            end
            delete(uip);

            %% Updating Datasets
            if(isequal(answer,'AsNew'))
                if(isempty(app.Datasets))
                    app.Datasets = tempDT;
                else
                    app.Datasets(end+1) = tempDT;
                end
            else
                iDataset = find(ismember({app.Datasets.UUID},tempDT.UUID),1);
                app.Datasets(iDataset) = tempDT;
            end
            app.currentDT = tempDT;

            %% Filling Datasets Tab
            app.fillDatasetTab();               
            
            close(d)
            figure(app.CiftiStormUIFigure);
            app.TabGroup.SelectedTab = app.TabDatasets;
        end

        % Menu selected function: FreesurferMenu
        function FreesurferMenuSelected(app, event)
            d = uiprogressdlg(app.CiftiStormUIFigure,'Title','Checking dependencies......');
            if ismac || isunix
                [status,cmdout] = system('recon-all --version');
                if(isequal(status,0))
                    obj_configure = FreesurferUI();  
                    waitfor(obj_configure);
                else
                    msg = msgbox({'We can not find the freesurfer command on the system.',...
                        'Please install Freesurfer or check the configuration.'},'Warnning','warn');
                    waitfor(msg);
                end
            else
                msg = msgbox({'The structural processing is not supported by your Operating System.'},'Warnning','warn');
                waitfor(msg);
            end
            close(d)
            figure(app.CiftiStormUIFigure);
        end

        % Menu selected function: CiftifyMenu
        function CiftifyMenuSelected(app, event)
            d = uiprogressdlg(app.CiftiStormUIFigure,'Title','Checking dependencies......');
            if ismac || isunix
                [status,cmdout] = system('ciftify-recon-all -h');
                if(isequal(status,0))
                    obj_configure = CiftifyUI();
                    waitfor(obj_configure);
                else
                    msg = msgbox({'We can not find the Ciftify command on the system.',...
                        'Please install Ciftify or check the configuration.'},'Warnning','warn');
                    waitfor(msg);
                end
            else
                msg = msgbox({'The structural processing is not supported by your Operating System.'},'Warnning','warn');
                waitfor(msg);
            end
            close(d)
            figure(app.CiftiStormUIFigure);
        end

        % Menu selected function: FSLBetMenu
        function FSLBetMenuSelected(app, event)
            d = uiprogressdlg(app.CiftiStormUIFigure,'Title','Checking dependencies......');
            if ismac || isunix
                [status,cmdout] = system('bet2 -h');
                if(isequal(status,0))
                    
                end
            else
                msg = msgbox({'The structural processing is not supported by your Operating System.'},'Warnning','warn');
                waitfor(msg);

            end
            close(d)
            figure(app.CiftiStormUIFigure);
        end

        % Close request function: CiftiStormUIFigure
        function CiftiStormUIFigureCloseRequest(app, event)
            brainstorm stop;
            delete(app)
            
        end

        % Selection changed function: TreeDatasets
        function TreeDatasetsSelectionChanged(app, event)
            selectedNode = app.TreeDatasets.SelectedNodes;           
            delete(app.PanelControls.Children);
            nodeData = selectedNode.NodeData;
            ProtocolInfo = bst_get('ProtocolInfo'); 
            if(isequal(selectedNode.UserData,'subject'))
                app.currentSub = bst_get('Subject', selectedNode.Text, 1);
                study = bst_get('StudyWithSubject', app.currentSub.FileName , 'intra_subject');
                app.currentSub.Study = study;
                app.currentSub.Transformation.isChanged = false;
            else
                app.currentSub = bst_get('Subject', selectedNode.Parent.Text, 1);
                study = bst_get('StudyWithSubject', app.currentSub.FileName , 'intra_subject');
                app.currentSub.Study = study;
                app.currentSub.Transformation.isChanged = false;
                
                if(isequal(nodeData.Comment,'MRI'))

                end
                if(isequal(selectedNode.Text,'BEM'))
                    sSurfaces = {'Cortex','InnerSkull','OuterSkull','Scalp'};                    
                    app.PlotSurf(sSurfaces);
                end
                if(isequal(selectedNode.Text,'Scalp'))
                    sSurfaces = {'Scalp'};                    
                    app.PlotSurf(sSurfaces);
                end
                if(isequal(selectedNode.Text,'OuterSkull'))
                    sSurfaces = {'OuterSkull'};                    
                    app.PlotSurf(sSurfaces);
                end
                if(isequal(selectedNode.Text,'InnerSkull'))
                    sSurfaces = {'InnerSkull'};                    
                    app.PlotSurf(sSurfaces);
                end
                if(isequal(selectedNode.Text,'Cortex'))
                    sSurfaces = {'Cortex'};                    
                    app.PlotSurf(sSurfaces);
                end
                if(isequal(selectedNode.Text,'Channels'))
                    Cdata = load(fullfile(ProtocolInfo.STUDIES,app.currentSub.Study.Channel.FileName));
                    app.currentSub.Transformation.Cdata = Cdata;
                    app.currentSub.Transformation.FinalTransf = eye(4);
                    app.PlotChannelsPosition(false);
                    app.ChannelsControls();
                end
                if(isequal(selectedNode.Text,'Headmodel'))
                    pos = app.PanelControls.Position;
                    uilabel(app.PanelControls,'Text','Channels:',...
                        'Position',[(pos(3)/2-60) 4 60 30]);
                    channelFile = app.currentSub.Study.Channel.FileName;
                    Channel     = load(fullfile(ProtocolInfo.STUDIES,channelFile)).Channel;
                    uidropdown(app.PanelControls,"Items",{Channel.Name},...
                        'Value', 'Fz','Position',[(pos(3)/2) 4 75 27],...
                        "ValueChangedFcn",@(dropd,event) app.PlotHeadmodel());
                    app.PlotHeadmodel();
                end 
            end
        end

        % Callback function: DatasetListMenu, PushToolListDataset
        function DatasetListMenuSelected(app, event)
            d = uiprogressdlg(app.CiftiStormUIFigure,'Title','Listing Datastes......');
            if(isempty(app.Datasets))
                msg = msgbox({'There is no Dataset to show.',...
                    'Please import or create a Dataset.'},'Info',"help","modal");
                movegui(msg,'north');
            else
                datasetUI                   = DatasetUI();    
                datasetUI.ciftistormDir     = app.ciftistormDir;
                datasetUI.Datasets_file     = app.Datasets_file;
                datasetUI.Datasets          = app.Datasets;                
                tableData.Name              = {app.Datasets.Name}';                  
                for i=1:length(app.Datasets)                   
                    descrip_array{i}        = string(app.Datasets(i).Description);
                end
                tableData.Description       = descrip_array';
                tableData.Protocol          = {app.Datasets.ProtocolName}';
                tableData.Location          = {app.Datasets.Location}';
                datasetUI.UITable.Data      = struct2table(tableData);
                waitfor(datasetUI);
            end
            app.Datasets = cfs_get('datasets');
            app.currentDT = app.Datasets(1);
            delete(d);
            app.DatasetsDropDownValueChanged(event);
        end

        % Callback function: PushToolDefault_Params, SetdefaultMenu
        function SetdefaultMenuSelected(app, event)
             opts.Interpreter = 'tex';
            % Include the desired Default answer
            opts.Default = 'Yes';
            % Use the TeX interpreter to format the question
            quest = {strcat("You will restore the default params"), ...
                'Would you like continue?'};
            answer = questdlg(quest,'Apply changes',...
                'Yes','No',opts);
            % Handle response
            switch answer
                case 'Yes'
                    set_default_properties(app);
                case 'No'
            end
        end

        % Menu selected function: DocumentationMenu
        function DocumentationMenuSelected(app, event)
            DocumentationUI();
        end

        % Menu selected function: CheckforupdateMenu
        function CheckforupdateMenuSelected(app, event)
            d = uiprogressdlg(app.CiftiStormUIFigure,'Title','Checking the internet connection......');
            iconnection = isnetav();
            delete(d);
            if(iconnection)
                % loading local data
                local = jsondecode(fileread(strcat('app/properties.json')));
                % finding online data
                d1 = uiprogressdlg(app.CiftiStormUIFigure,'Title','Checking online version');
                url = local.generals.url_check;
                matlab.net.http.HTTPOptions.VerifyServerName = false;
                options = weboptions('ContentType','json','Timeout',Inf,'RequestMethod','auto');
                online = webread(url,options);
                disp('-->> Comparing local and master version');
                delete(d1);
                if(local.generals.version_number < online.generals.version_number)
                    answer = questdlg({'There a new version available of CiftiStorm pipeline.',' Do you want to update the latest version?'}, ...
                        'Update CiftiStorm', ...
                        'Yes','No','Close');
                    % Handle response
                    switch answer
                        case 'Yes'
                            [updated, errors] = update_version(app);
                            if(updated)
                                msg = msgbox({'CiftiStorm was updated with the latest version.',...
                                    strcat('Version:',online.generals.version), ...
                                    strcat('Date:',online.generals.version_date), ...
                                    'The application will be closed.'},'Info',"help","modal");
                                waitfor(msg);
                            else
                                
                            end
                            figure(app.CiftiStormUIFigure);
                        case 'No'
                            figure(app.CiftiStormUIFigure);
                            return;
                        case ''
                            figure(app.CiftiStormUIFigure);
                            return;
                    end
                else
                    msg = msgbox({'You are using the CiftiStorm latest version',...
                        strcat('Version:',online.generals.version), ...
                        strcat('Date:',online.generals.version_date)}, ...
                        'Info',"help","modal");
                    waitfor(msg);
                    disp('-->> Nothing to update');
                end
            else
                msg = msgbox({'There are some problems with your internet connection.',...
                    'Please check your internet conection.'},'Info',"help","modal");
                waitfor(msg);
                figure(app.CiftiStormUIFigure);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CiftiStormUIFigure = uifigure('Visible', 'off');
            app.CiftiStormUIFigure.Name = 'CiftiStorm';
            app.CiftiStormUIFigure.CloseRequestFcn = createCallbackFcn(app, @CiftiStormUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.CiftiStormUIFigure);
            app.FileMenu.Text = 'File';
            app.NewprocessMenu = uimenu(app.FileMenu);
            app.NewprocessMenu.MenuSelectedFcn = createCallbackFcn(app, @NewprocessMenuSelected, true);
            app.NewprocessMenu.Tooltip = {'Configure new process configuration'};
            app.NewprocessMenu.Text = 'New process';
            app.SetdefaultMenu = uimenu(app.FileMenu);
            app.SetdefaultMenu.MenuSelectedFcn = createCallbackFcn(app, @SetdefaultMenuSelected, true);
            app.SetdefaultMenu.Tooltip = {'Restore default parameters configuration'};
            app.SetdefaultMenu.Text = 'Set default';
            app.DatasetMenu = uimenu(app.FileMenu);
            app.DatasetMenu.Separator = 'on';
            app.DatasetMenu.Text = 'Dataset';
            app.DatasetLoadMenu = uimenu(app.DatasetMenu);
            app.DatasetLoadMenu.MenuSelectedFcn = createCallbackFcn(app, @DatasetLoadMenuSelected, true);
            app.DatasetLoadMenu.Tooltip = {'Load Dataset'};
            app.DatasetLoadMenu.Text = 'Load';
            app.DatasetListMenu = uimenu(app.DatasetMenu);
            app.DatasetListMenu.MenuSelectedFcn = createCallbackFcn(app, @DatasetListMenuSelected, true);
            app.DatasetListMenu.Tooltip = {'List Datasets'};
            app.DatasetListMenu.Text = 'List';
            app.DatasetDeleteMenu = uimenu(app.DatasetMenu);
            app.DatasetDeleteMenu.Tooltip = {'Delete dataset'};
            app.DatasetDeleteMenu.Text = 'Delete';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.ToolsMenu = uimenu(app.CiftiStormUIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.StructuralprocessingMenu = uimenu(app.ToolsMenu);
            app.StructuralprocessingMenu.Text = 'Structural processing';
            app.StandardMenu = uimenu(app.StructuralprocessingMenu);
            app.StandardMenu.Text = 'Standard';
            app.PreFreesurferMenu = uimenu(app.StandardMenu);
            app.PreFreesurferMenu.Text = 'PreFreesurfer';
            app.FreesurferMenu_2 = uimenu(app.StandardMenu);
            app.FreesurferMenu_2.Text = 'Freesurfer';
            app.postFreesurferMenu = uimenu(app.StandardMenu);
            app.postFreesurferMenu.Text = 'postFreesurfer';
            app.LegacyMenu = uimenu(app.StructuralprocessingMenu);
            app.LegacyMenu.Text = 'Legacy';
            app.FreesurferMenu = uimenu(app.LegacyMenu);
            app.FreesurferMenu.MenuSelectedFcn = createCallbackFcn(app, @FreesurferMenuSelected, true);
            app.FreesurferMenu.Text = 'Freesurfer';
            app.CiftifyMenu = uimenu(app.LegacyMenu);
            app.CiftifyMenu.MenuSelectedFcn = createCallbackFcn(app, @CiftifyMenuSelected, true);
            app.CiftifyMenu.Text = 'Ciftify';
            app.FSLBetMenu = uimenu(app.LegacyMenu);
            app.FSLBetMenu.MenuSelectedFcn = createCallbackFcn(app, @FSLBetMenuSelected, true);
            app.FSLBetMenu.Text = 'FSL Bet';
            app.ForwadandSourcemodelsMenu = uimenu(app.ToolsMenu);
            app.ForwadandSourcemodelsMenu.Text = 'Forwad and Source models';
            app.MEEGprepMenu = uimenu(app.ToolsMenu);
            app.MEEGprepMenu.MenuSelectedFcn = createCallbackFcn(app, @MEEGprepMenuSelected, true);
            app.MEEGprepMenu.Text = 'MEEGprep';
            app.BCVARETAMenu = uimenu(app.ToolsMenu);
            app.BCVARETAMenu.MenuSelectedFcn = createCallbackFcn(app, @BCVARETAMenuSelected, true);
            app.BCVARETAMenu.Text = 'BC-VARETA';
            app.ViewMenu = uimenu(app.CiftiStormUIFigure);
            app.ViewMenu.Text = 'View';
            app.HelpMenu = uimenu(app.CiftiStormUIFigure);
            app.HelpMenu.Text = 'Help';
            app.CheckforupdateMenu = uimenu(app.HelpMenu);
            app.CheckforupdateMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckforupdateMenuSelected, true);
            app.CheckforupdateMenu.Text = 'Check for update';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @DocumentationMenuSelected, true);
            app.DocumentationMenu.Text = 'Documentation';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.Toolbar = uitoolbar(app.CiftiStormUIFigure);
            app.PushToolNewProcess = uipushtool(app.Toolbar);
            app.PushToolNewProcess.Tooltip = {'New process'};
            app.PushToolNewProcess.ClickedCallback = createCallbackFcn(app, @NewprocessMenuSelected, true);
            app.PushToolNewProcess.Icon = fullfile(pathToMLAPP, 'images', 'new_process.jpeg');
            app.PushToolDefault_Params = uipushtool(app.Toolbar);
            app.PushToolDefault_Params.Tooltip = {'Set default params'};
            app.PushToolDefault_Params.ClickedCallback = createCallbackFcn(app, @SetdefaultMenuSelected, true);
            app.PushToolDefault_Params.Icon = fullfile(pathToMLAPP, 'images', 'default.png');
            app.PushToolRunProcess = uipushtool(app.Toolbar);
            app.PushToolRunProcess.Tooltip = {'Run process'};
            app.PushToolRunProcess.ClickedCallback = createCallbackFcn(app, @PushToolRunProcessClicked, true);
            app.PushToolRunProcess.Icon = fullfile(pathToMLAPP, '', 'images', 'run.jpg');
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @DatasetLoadMenuSelected, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'images', 'upload.png');
            app.PushTool.Separator = 'on';
            app.PushToolListDataset = uipushtool(app.Toolbar);
            app.PushToolListDataset.Tooltip = {'List Datasets'};
            app.PushToolListDataset.ClickedCallback = createCallbackFcn(app, @DatasetListMenuSelected, true);
            app.PushToolListDataset.Icon = fullfile(pathToMLAPP, 'images', 'list_dataset.png');
            app.PushToolExplorerDS = uipushtool(app.Toolbar);
            app.PushToolExplorerDS.Tooltip = {'Explore Datasets'};
            app.PushToolExplorerDS.Icon = fullfile(pathToMLAPP, 'images', 'explore.png');
            app.GridLayout = uigridlayout(app.CiftiStormUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabExplorer = uitab(app.TabGroup);
            app.TabExplorer.Title = 'Explorer';
            app.TreeInput = uitree(app.TabExplorer);
            app.TreeInput.ClickedFcn = createCallbackFcn(app, @TreeInputClicked, true);
            app.TreeInput.DoubleClickedFcn = createCallbackFcn(app, @TreeInputDoubleClicked, true);
            app.TabDatasets = uitab(app.TabGroup);
            app.TabDatasets.Title = 'Datasets';
            app.TreeDatasets = uitree(app.TabDatasets);
            app.TreeDatasets.SelectionChangedFcn = createCallbackFcn(app, @TreeDatasetsSelectionChanged, true);
            app.DatasetsDropDownLabel = uilabel(app.TabDatasets);
            app.DatasetsDropDownLabel.Text = 'Datasets:';
            app.DatasetsDropDown = uidropdown(app.TabDatasets);
            app.DatasetsDropDown.Items = {'--Select--'};
            app.DatasetsDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasetsDropDownValueChanged, true);
            app.DatasetsDropDown.Value = '--Select--';
            app.PanelViewer = uipanel(app.GridLayout);
            app.PanelViewer.Title = 'Viewer';
            app.LabelBreadcrumbs = uilabel(app.GridLayout);
            app.LabelBreadcrumbs.Text = '';
            app.PanelControls = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.CiftiStormUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CiftiStorm

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.CiftiStormUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.CiftiStormUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CiftiStormUIFigure)
        end
    end
end