classdef CiftifyUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Showrejectedsubjects     matlab.ui.control.Hyperlink
        DatasetinformationPanel  matlab.ui.container.Panel
        RunCiftifyVisCheckBox    matlab.ui.control.CheckBox
        FreesurferPathEditField  matlab.ui.control.EditField
        pathLabel_2              matlab.ui.control.Label
        FreesurferPathButton     matlab.ui.control.Button
        LabelInfo                matlab.ui.control.Label
        RuninbackgroundCheckBox  matlab.ui.control.CheckBox
        UITable                  matlab.ui.control.Table
        CiftifyPathEditField     matlab.ui.control.EditField
        pathLabel                matlab.ui.control.Label
        CiftifyPathButton        matlab.ui.control.Button
        ButtonCancel             matlab.ui.control.Button
        ButtonRun                matlab.ui.control.Button
    end

    
    properties (Access = private)
        rejected_subs % Description
    end
    
    methods (Access = private)
        
        function SelectAll(app,cbx)
            if(cbx.Value)
                app.UITable.Data{:,1} = true(size(app.UITable.Data(:,1)));
            else
                app.UITable.Data{:,1} = false(size(app.UITable.Data(:,1)));
            end
            total = size(app.UITable.Data(:,1),1);
            selected = length(find(table2array(app.UITable.Data(:,1))));
            app.LabelInfo.Text = strcat(num2str(selected)," selected from ",num2str(total), " subjects." );
        end
        
        function [checked,errorMsg] = parseFreesurferFolder(app,FsDir)
            %% ===== PARSE FREESURFER FOLDER =====
            
            % Find MRI
            errorMsg = [];
            T1File = file_find(FsDir, 'T1.mgz', 2);
            T2File = file_find(FsDir, 'T2.mgz', 2);
            if isempty(T1File)
                T1File = file_find(FsDir, '*.nii.gz', 0);
                if ~isempty(T1File)
                    T1Comment = 'MRI';
                else
                    errorMsg = [errorMsg 'MRI file was not found: T1.mgz' 10];
                end
            elseif ~isempty(T1File) && ~isempty(T2File)
                T1Comment = 'MRI T1';
                T2Comment = 'MRI T2';
            else
                T1Comment = 'MRI';
            end
            % Find surface: lh.pial (or lh.pial.T1)
            TessLhFile = file_find(FsDir, 'lh.pial', 2);
            if ~isempty(TessLhFile)
                d = dir(TessLhFile);
                if (length(d) == 1) && (d.bytes < 256)
                    TessLhFile = [];
                end
            end
            if isempty(TessLhFile)
                TessLhFile = file_find(FsDir, 'lh.pial.T1', 2);
                if isempty(TessLhFile)
                    errorMsg = [errorMsg 'Surface file was not found: lh.pial/lh.pial.T1' 10];
                end
            end
            % Find surface: rh.pial (or rh.pial.T1)
            TessRhFile = file_find(FsDir, 'rh.pial', 2);
            if ~isempty(TessRhFile)
                d = dir(TessRhFile);
                if (length(d) == 1) && (d.bytes < 256)
                    TessRhFile = [];
                end
            end
            if isempty(TessRhFile)
                TessRhFile = file_find(FsDir, 'rh.pial.T1', 2);
                if isempty(TessRhFile)
                    errorMsg = [errorMsg 'Surface file was not found: rh.pial/rh.pial.T1' 10];
                end
            end
            % Find other surfaces
            TessLwFile = file_find(FsDir, 'lh.white', 2);
            if(isempty(TessLwFile))
                errorMsg = [errorMsg 'Surface file was not found: surf/lh.white' 10];
            end
            TessRwFile = file_find(FsDir, 'rh.white', 2);
            if(isempty(TessRwFile))
                errorMsg = [errorMsg 'Surface file was not found: surf/rh.white' 10];
            end
            TessLsphFile = file_find(FsDir, 'lh.sphere.reg', 2);
            if(isempty(TessLsphFile))
                errorMsg = [errorMsg 'Surface file was not found: surf/lh.sphere.reg' 10];
            end
            TessRsphFile = file_find(FsDir, 'rh.sphere.reg', 2);
             if(isempty(TessRsphFile))
                errorMsg = [errorMsg 'Surface file was not found: surf/rh.sphere.reg' 10];
            end
            TessLRsphFile = file_find(FsDir, 'lh.sphere.reg', 2);
            if(isempty(TessLRsphFile))
                errorMsg = [errorMsg 'Surface file was not found: surf/lh.sphere.reg' 10];
            end
            TessRLsphFile = file_find(FsDir, 'rh.sphere.reg', 2);
            if(isempty(TessRLsphFile))
                errorMsg = [errorMsg 'Surface file was not found: surf/rh.sphere.reg' 10];
            end
            
            % Find volume segmentation
            AsegFile = file_find(FsDir, 'aseg.mgz', 2);
            if(isempty(AsegFile))
                errorMsg = [errorMsg 'Volume file was not found: mri/aseg.mgz' 10];
            end
            OtherAsegFiles = file_find(FsDir, '*+aseg.mgz', 2, 0);
            if(isempty(OtherAsegFiles))
                errorMsg = [errorMsg 'Volume file was not found: mri/*+aseg.mgz' 10];
            end
            % Find labels
            AnnotLhFiles = file_find(FsDir, 'lh.*.annot', 2, 0);
            if(isempty(AnnotLhFiles))
                errorMsg = [errorMsg 'Labels file was not found: label/lh.*.annot' 10];
            end
            AnnotRhFiles = file_find(FsDir, 'rh.*.annot', 2, 0);
            if(isempty(AnnotRhFiles))
                errorMsg = [errorMsg 'Labels file was not found: label/rh.*.annot' 10];
            end
            % Remove old labels
            if ~isempty(AnnotLhFiles) && ~isempty(AnnotRhFiles)
                % Freesurfer 5.3 creates "BA.annot", Freesurfer 6 creates "BA_exvivo.annot"
            	% If the two are available in the same folder, both versions were executed, and the old "BA.annot" is outdated but not replaced: ignore it
                % Left
                iBAold = find(~cellfun(@(c)isempty(strfind(c, 'BA.annot')), AnnotLhFiles));
                iBAnew = find(~cellfun(@(c)isempty(strfind(c, 'BA_exvivo.annot')), AnnotLhFiles));
                if ~isempty(iBAold) && ~isempty(iBAnew)
                    AnnotLhFiles(iBAold) = [];
                end
                % Right
                iBAold = find(~cellfun(@(c)isempty(strfind(c, 'BA.annot')), AnnotRhFiles));
                iBAnew = find(~cellfun(@(c)isempty(strfind(c, 'BA_exvivo.annot')), AnnotRhFiles));
                if ~isempty(iBAold) && ~isempty(iBAnew)
                    AnnotRhFiles(iBAold) = [];
                end
                % Remove temporary FastSurfer files: ?h.aparc.mapped.prefix.annot
                iTmpL = find(~cellfun(@(c)isempty(strfind(c, 'mapped.prefix')), AnnotLhFiles));
                if ~isempty(iTmpL)
                    AnnotLhFiles(iTmpL) = [];
                end
                iTmpR = find(~cellfun(@(c)isempty(strfind(c, 'mapped.prefix')), AnnotRhFiles));
                if ~isempty(iTmpR)
                    AnnotRhFiles(iTmpR) = [];
                end
                % Re-order the files so that FreeSurfer atlases are first (for automatic region labelling)
                iDKL = find(~cellfun(@(c)isempty(strfind(c, 'aparc')), AnnotLhFiles));
                iDKR = find(~cellfun(@(c)isempty(strfind(c, 'aparc')), AnnotRhFiles));
                if ~isempty(iDKL) && ~isempty(iDKR)
                    AnnotLhFiles = AnnotLhFiles([iDKL, setdiff(1:length(AnnotLhFiles), iDKL)]);                    
                    AnnotRhFiles = AnnotRhFiles([iDKR, setdiff(1:length(AnnotRhFiles), iDKR)]);
                    if(isempty(AnnotLhFiles) || isempty(AnnotRhFiles))
                        errorMsg = [errorMsg 'Atlases file was not found: label/*aparc*.annot' 10];
                    end
                end
            end
            % Find thickness maps            
            ThickLhFile = file_find(FsDir, 'lh.thickness', 2);
            ThickRhFile = file_find(FsDir, 'rh.thickness', 2);
            if(isempty(ThickLhFile) || isempty(ThickRhFile))
                errorMsg = [errorMsg 'Surface file was not found: surf/lh.thickness | surf/rh.thickness' 10];
            end
            
            % Report errors
            checked = true;
            if ~isempty(errorMsg)
                checked = false;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CiftifyPathButton
        function CiftifyPathButtonPushed(app, event)
             try
                folder = uigetdir("Select the Freesurfer workspace path");
                folder = strrep(folder,'\','/');
                app.CiftifyPathEditField.Value = folder;
            catch
                app.CiftifyPathEditField.Value = '';
            end
            figure(app.UIFigure);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            total = size(app.UITable.Data(:,1),1);
            selected = length(find(table2array(app.UITable.Data(:,1))));
            app.LabelInfo.Text = strcat(num2str(selected)," selected from ",num2str(total), " subjects." );
        end

        % Value changed function: RuninbackgroundCheckBox
        function RuninbackgroundCheckBoxValueChanged(app, event)
            value = app.RuninbackgroundCheckBox.Value;
            if(value)
                app.RuninbackgroundCheckBox.FontWeight = 'bold';
            else
                app.RuninbackgroundCheckBox.FontWeight = 'normal';
            end
        end

        % Button pushed function: ButtonRun
        function ButtonRunPushed(app, event)
            if(isempty(app.FreesurferPathEditField))
                msgbox({'The Freesurfer workspace path can not be empty.', ...
                    'Please check the configuration.'},'Info');
                figure(app.UIFigure);
                return;
            end
            if(isempty(app.CiftifyPathEditField.Value))
                msgbox({'The Ciftify workspace path can not be empty.', ...
                    'Please check the configuration.'},'Info');
                figure(app.UIFigure);
                return;
            end           
            if(isequal(length(find(table2array(app.UITable.Data(:,1)))),0))
                msgbox({'You should select at least one subject to process.', ...
                    'Please check the configuration.'},'Info');
                figure(app.UIFigure);
                return;
            end
            freesurfer_ws = app.FreesurferPathEditField.Value;
            ciftify_ws = app.CiftifyPathEditField.Value;
            indxs = find(table2array(app.UITable.Data(:,1)));
            subjects = join(string(table2array(app.UITable.Data(indxs,2))));

            structual = jsondecode(fileread(fullfile('cfs_properties/process_structural.json')));
            structual.ciftify.freesurfer_ws = freesurfer_ws;
            structual.ciftify.workspace = ciftify_ws;
            structual.ciftify.participants = subjects;

            saveJSON(structual,fullfile(pwd,'cfs_properties/process_structural.json'));
            h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/process_structural.json'));
            h.smartIndentContents
            h.save
            h.close
            ciftify_vis = false;
            if(app.RunCiftifyVisCheckBox.Value)
                ciftify_vis = true;
            end
            if(app.RuninbackgroundCheckBox.Value) 
                command = strcat("./guide/ciftify_processing.sh ",freesurfer_ws," ", ciftify_ws, " '", subjects, "' ", num2str(ciftify_vis), " &");
            else
                command = strcat("./guide/ciftify_processing.sh ",freesurfer_ws," ", ciftify_ws, " '", subjects, "' ", num2str(ciftify_vis));
            end
            [status,cmdout] = system(command,'-echo');
        end

        % Button pushed function: ButtonCancel
        function ButtonCancelPushed(app, event)
            delete(app);
        end

        % Button pushed function: FreesurferPathButton
        function FreesurferPathButtonPushed(app, event)
            try
                folder = uigetdir("Select the Dataset path");
                folder = strrep(folder,'\','/');
                app.FreesurferPathEditField.Value = folder;

                % Filling table
                subjects = dir(folder);
                subjects(ismember({subjects.name},{'.','..','fsaverage'})) = [];
                subjects(~[subjects.isdir]) = [];
                reject = [];
                for i=1:length(subjects)
                    sub = subjects(i);
                    FsDir = fullfile(sub.folder,sub.name);
                    [checked,errorMsg] = app.parseFreesurferFolder(FsDir);
                    if(~checked)
                        reject = [reject i];
                    end
                end
                app.Showrejectedsubjects.Enable = false;
                app.rejected_subs = subjects(reject);
                if(~isempty(reject))
                    subjects(reject) = [];
                    app.Showrejectedsubjects.Enable = true;
                end
                subs = {subjects.name}';
                cbxs = true(size(subjects));
                T = table(cbxs,subs);
                app.UITable.Data = T;
                
                if(isequal(app.DatasetinformationPanel.Children(1).Tag,'CheckAll'))
                    delete(app.DatasetinformationPanel.Children(1))
                end
                uicheckbox("Value",true,Tag='CheckAll',Text='',Position=[56,275,30,30],Parent=app.DatasetinformationPanel,...
                    ValueChangedFcn=@(cbt,event) app.SelectAll(cbt));
                total = size(subjects,1);
                selected = length(find(table2array(app.UITable.Data(:,1))));
                app.LabelInfo.Text = strcat(num2str(selected)," selected from ",num2str(total), " subjects." );
            catch
                app.FreesurferPathEditField.Value = '';
            end
            app.CiftifyPathEditField.Value = '';
            figure(app.UIFigure);      
           
        end

        % Callback function: Showrejectedsubjects
        function ShowrejectedsubjectsHyperlinkClicked(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Freesurfer configuration';
            app.ButtonRun = uibutton(app.UIFigure, 'push');
            app.ButtonRun.ButtonPushedFcn = createCallbackFcn(app, @ButtonRunPushed, true);
            app.ButtonRun.Icon = fullfile(pathToMLAPP, 'images', 'run.jpg');
            app.ButtonRun.Text = 'Run';
            app.ButtonCancel = uibutton(app.UIFigure, 'push');
            app.ButtonCancel.ButtonPushedFcn = createCallbackFcn(app, @ButtonCancelPushed, true);
            app.ButtonCancel.Icon = fullfile(pathToMLAPP, 'images', 'cancel.jpg');
            app.ButtonCancel.Text = 'Cancel';
            app.DatasetinformationPanel = uipanel(app.UIFigure);
            app.DatasetinformationPanel.Title = 'Dataset information';
            app.CiftifyPathButton = uibutton(app.DatasetinformationPanel, 'push');
            app.CiftifyPathButton.ButtonPushedFcn = createCallbackFcn(app, @CiftifyPathButtonPushed, true);
            app.CiftifyPathButton.Text = 'Select';
            app.pathLabel = uilabel(app.DatasetinformationPanel);
            app.pathLabel.Text = 'Ciftify WS:';
            app.CiftifyPathEditField = uieditfield(app.DatasetinformationPanel, 'text');
            app.CiftifyPathEditField.Editable = 'off';
            app.UITable = uitable(app.DatasetinformationPanel);
            app.UITable.ColumnName = {'Select'; 'Subjects'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.ColumnEditable = [true false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.Multiselect = 'off';
            app.RuninbackgroundCheckBox = uicheckbox(app.DatasetinformationPanel);
            app.RuninbackgroundCheckBox.ValueChangedFcn = createCallbackFcn(app, @RuninbackgroundCheckBoxValueChanged, true);
            app.RuninbackgroundCheckBox.Tooltip = {'If "checked" will run this process in background and you can continue using the CiftiStorm application'};
            app.RuninbackgroundCheckBox.Text = 'Run in background';
            app.LabelInfo = uilabel(app.DatasetinformationPanel);
            app.LabelInfo.Text = '';
            app.FreesurferPathButton = uibutton(app.DatasetinformationPanel, 'push');
            app.FreesurferPathButton.ButtonPushedFcn = createCallbackFcn(app, @FreesurferPathButtonPushed, true);
            app.FreesurferPathButton.Tooltip = {'Freesurfer Workspace'};
            app.FreesurferPathButton.Text = 'Select';
            app.pathLabel_2 = uilabel(app.DatasetinformationPanel);
            app.pathLabel_2.Text = 'Fresurfer WS:';
            app.FreesurferPathEditField = uieditfield(app.DatasetinformationPanel, 'text');
            app.FreesurferPathEditField.Editable = 'off';
            app.FreesurferPathEditField.Tooltip = {'Freesurfer Workspace'};
            app.RunCiftifyVisCheckBox = uicheckbox(app.DatasetinformationPanel);
            app.RunCiftifyVisCheckBox.Text = 'Run the visualization post processing';
            app.Showrejectedsubjects = uihyperlink(app.UIFigure);
            app.Showrejectedsubjects.HyperlinkClickedFcn = createCallbackFcn(app, @ShowrejectedsubjectsHyperlinkClicked, true);
            app.Showrejectedsubjects.Enable = 'off';
            app.Showrejectedsubjects.Text = {'Show rejected subjects'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CiftifyUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end