classdef DatasetUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DatasetsUIFigure  matlab.ui.Figure
        FileMenu          matlab.ui.container.Menu
        HelpMenu          matlab.ui.container.Menu
        Toolbar           matlab.ui.container.Toolbar
        LoadPushTool      matlab.ui.container.toolbar.PushTool
        ExplorePushTool   matlab.ui.container.toolbar.PushTool
        DeletePushTool    matlab.ui.container.toolbar.PushTool
        PushTool          matlab.ui.container.toolbar.PushTool
        GridLayout        matlab.ui.container.GridLayout
        Panel             matlab.ui.container.Panel
        CloseButton       matlab.ui.control.Button
        UITable           matlab.ui.control.Table
    end

    
    properties (Access = public)
        ciftistormDir;
        Datasets_file;
        Datasets;
    end
    
    methods (Access = private)
        
        function UpdateDatasetsTable(app)
            set(app.UITable, 'Data', {});
            tableData.Name                  = {app.Datasets.Name}';                
                for i=1:length(app.Datasets)                   
                    descrip_array{i}        = string(app.Datasets(i).Description);
                end
                tableData.Description       = descrip_array';
                tableData.Protocol          = {app.Datasets.ProtocolName}';
                tableData.Location          = {app.Datasets.Location}';
                app.UITable.Data            = struct2table(tableData);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.SelectionType = 'row';
            movegui(app.DatasetsUIFigure,[800,200]); 
        end

        % Close request function: DatasetsUIFigure
        function DatasetsUIFigureCloseRequest(app, event)
            delete(app)            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            previousData = event.PreviousData;
            newData = event.NewData;
            uDataset = app.Datasets(indices(1));
            updated = false;
            switch indices(2)
                case 1
                    uDataset.Name = newData;
                    updated = true;
                case 2
                    uDataset.Description = newData;
                    updated = true;
                case 3
                    uDataset.ProtocolName = newData;
                    iProtocol = bst_get('Protocol', previousData);
                    gui_brainstorm('SetCurrentProtocol', iProtocol);
                    isRenamed = db_rename_protocol(uDataset.ProtocolName);
                    if(isRenamed)
                        [icondata,iconcmap] = imread("guide/images/success.png");
                        msg = msgbox({'The Protocol was updated successfully!!!'},'Success',"custom",icondata,iconcmap);
                        updated = true;
                    else
                        [icondata,iconcmap] = imread("guide/images/success.png");
                        msg = msgbox({'The Protocol was not !!!', 'Please check de Protocol name'},'warning',"custom",icondata,iconcmap);
                    end
            end
            if(updated)
                app.Datasets(indices(1)) = uDataset; 
                cfs_set('datasets',app.Datasets);
                app.Datasets = cfs_get('datasets');
                [icondata,iconcmap] = imread("guide/images/success.png");
                msg = msgbox({'The Dataset was updated successfully!!!'},'Success',"custom",icondata,iconcmap);
                
            else
                [icondata,iconcmap] = imread("guide/images/success.png");
                 msg = msgbox({'Were some problems updating the Dataset','Please check the Dataset values'},'Success',"custom",icondata,iconcmap);
            end
        end

        % Callback function: LoadPushTool
        function LoadPushToolClicked(app, event)
            homedir = char(java.lang.System.getProperty('user.home'));
            d = uiprogressdlg(app.DatasetsUIFigure,'Title','Loading Dataset......');
            folder = uigetdir(homedir,"Select the Dataset folder");
            if(isequal(folder,0))
                close(d)
                figure(app.DatasetsUIFigure);
                return;
            end
            tempDT = jsondecode(fileread(fullfile(folder,'CiftiStorm.json')));
            tempDT.Location = fullfile(folder);
            if(~isempty(app.Datasets) && ~isempty(find(ismember({app.Datasets.UUID},tempDT.UUID),1)))
                opts.Interpreter = 'tex';
                % Include the desired Default answer
                opts.Default = 'No';
                % Use the TeX interpreter to format the question
                quest = {strcat("The selected Dataset is already in the CiftiStorm Database"), ...
                    'Would you like to import it anyway?'};
                answer = questdlg(quest,'Loading dataset',...
                    'Update','AsNew','No',opts);
                if(isequal(answer,'AsNew'))
                    inputDLG = inputdlg('Please type the new Dataset name:', ' Dataset', 1);
                    if(isempty(inputDLG))
                        m = msgbox({'The Protocol name can not be empty',...
                            ' Please load the database again.'},'Info');
                        waitfor(m);
                        delete(d);
                        return;
                    end
                    datasetName= char(inputDLG(1));
                    tempDT.Name = datasetName;
                end
            else
                answer = 'AsNew';
            end

            % Handle response
            if(isequal(answer,'AsNew') || isequal(answer,'Update'))
                ProtocolName = tempDT.ProtocolName;
                iProtocol = bst_get('Protocol', ProtocolName);
                if(isempty(iProtocol))
                    gui_brainstorm('CreateProtocol',ProtocolName , 0, 0);
                    iProtocol = bst_get('Protocol', ProtocolName);
                    gui_brainstorm('SetCurrentProtocol', iProtocol);
                else
                    opts.Interpreter = 'tex';
                    % Include the desired Default answer
                    opts.Default = 'Replace';
                    % Use the TeX interpreter to format the question
                    quest = {strcat("The Brainstorm protocol: ",ProtocolName," already exist"), ...
                        'Would you like to replace it or import as a new one?'};
                    answerP = questdlg(quest,'Loading dataset',...
                        'Replace','AsNew','Cancel',opts);
                    switch answerP
                        case 'Replace'
                            gui_brainstorm('DeleteProtocol', ProtocolName);
                            gui_brainstorm('CreateProtocol',ProtocolName , 0, 0);
                            iProtocol = bst_get('Protocol', ProtocolName);
                            gui_brainstorm('SetCurrentProtocol', iProtocol);
                        case 'AsNew'
                            inputDLG = inputdlg('Please type the new Protocol name:', ' Protocol', 1);
                            if(isempty(inputDLG))
                                m = msgbox({'The Protocol name can not be empty',...
                                    ' Please load the database again.'},'Info');
                                waitfor(m);
                                delete(d);
                                return;
                            end
                            newProtocolName= char(inputDLG(1));
                            tempDT.ProtocolName = newProtocolName;
                            gui_brainstorm('CreateProtocol',newProtocolName , 0, 0);
                            iProtocol = bst_get('Protocol', newProtocolName);
                            gui_brainstorm('SetCurrentProtocol', iProtocol);
                        case 'Cancel'
                            delete(d);
                            return;
                    end
                end
            else
                delete(d);
                return;                
            end

            %% Importing subjects to Brainstorm database
            uip = uiprogressdlg(app.DatasetsUIFigure,'Title','Importing subjects to Brainstorm database.');
            for i=1:length(tempDT.Participants)
                participant = tempDT.Participants(i);
                part_info = jsondecode(fileread(fullfile(tempDT.Location,participant.SubID,participant.FileInfo)));
                ZipFile = fullfile(tempDT.Location,participant.SubID,part_info.brainstorm_dir);
                import_subject(ZipFile);
            end
            delete(uip);

            %% Updating Datasets
            if(isequal(answer,'AsNew'))
                if(isempty(app.Datasets))
                    app.Datasets = tempDT;
                else
                    app.Datasets(end+1) = tempDT;
                end
            else
                iDataset = find(ismember({app.Datasets.UUID},tempDT.UUID),1);
                app.Datasets(iDataset) = tempDT;
            end            
            cfs_set('datasets',app.Datasets);
            app.Datasets = cfs_get('datasets');
            app.UpdateDatasetsTable();
            delete(d);
            figure(app.DatasetsUIFigure);
        end

        % Callback function: DeletePushTool
        function DeletePushToolClicked(app, event)
            row = app.UITable.Selection;
            if(isempty(row))
                msgbox({'Please select a Dataset to delete.'},'Error',"error","modal");
            else
                opts.Interpreter = 'tex';
                % Include the desired Default answer
                opts.Default = 'Cancel';
                % Use the TeX interpreter to format the question
                quest = {strcat("Would you like to delete the Dataset: ", app.Datasets(row).Name)};
                answer = questdlg(quest,'Delete',...
                    'Delete','Cancel',opts);
                % Handle response
                switch answer
                    case 'Delete'
                        app.Datasets(row) = [];
                        [icondata,iconcmap] = imread("guide/images/success.png");
                        msg = msgbox({'The Dataset was deleted successfully!!!'},'Success',"custom",icondata,iconcmap);
                        
                        app.UpdateDatasetsTable();
                        disp("-->> Dataset deleted");
                        cfs_set('datasets',app.Datasets);
                        app.Datasets = cfs_get('datasets');
                    case 'Cancel'
                end
            end
        end

        % Callback function: ExplorePushTool
        function ExplorePushToolClicked(app, event)
            row = app.UITable.Selection;
            if(isempty(row))
                msg = msgbox({'Please select a Dataset to delete.'},'Error',"error","modal");
                
            else
                DSExplore = app.Datasets(row);                
                appExplore = ExploreDS(app,DSExplore);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DatasetsUIFigure = uifigure('Visible', 'off');
            app.DatasetsUIFigure.Name = 'Datasets';
            app.DatasetsUIFigure.CloseRequestFcn = createCallbackFcn(app, @DatasetsUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.DatasetsUIFigure);
            app.FileMenu.Text = 'File';
            app.HelpMenu = uimenu(app.DatasetsUIFigure);
            app.HelpMenu.Text = 'Help';
            app.Toolbar = uitoolbar(app.DatasetsUIFigure);
            app.LoadPushTool = uipushtool(app.Toolbar);
            app.LoadPushTool.Tooltip = {'Load Dataset'};
            app.LoadPushTool.ClickedCallback = createCallbackFcn(app, @LoadPushToolClicked, true);
            app.LoadPushTool.Icon = fullfile(pathToMLAPP, 'images', 'upload.png');
            app.ExplorePushTool = uipushtool(app.Toolbar);
            app.ExplorePushTool.Tooltip = {'Explore Dataset'};
            app.ExplorePushTool.ClickedCallback = createCallbackFcn(app, @ExplorePushToolClicked, true);
            app.ExplorePushTool.Icon = fullfile(pathToMLAPP, 'images', 'explore.png');
            app.DeletePushTool = uipushtool(app.Toolbar);
            app.DeletePushTool.Tooltip = {'Delete Dataset'};
            app.DeletePushTool.ClickedCallback = createCallbackFcn(app, @DeletePushToolClicked, true);
            app.DeletePushTool.Icon = fullfile(pathToMLAPP, 'images', 'delete.png');
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Help'};
            app.PushTool.Icon = fullfile(pathToMLAPP, 'images', 'help.png');
            app.GridLayout = uigridlayout(app.DatasetsUIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Name'; 'Description'; 'Protocol'; 'Location'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true true false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.Panel = uipanel(app.GridLayout);
            app.CloseButton = uibutton(app.Panel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.DatasetsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DatasetUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DatasetsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DatasetsUIFigure)
        end
    end
end