classdef Dataset_configuration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ParamsconfigurationPanel        matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        GeneralsTab                     matlab.ui.container.Tab
        GeneralsparamsPanel             matlab.ui.container.Panel
        ImportPrepEDEditField_2         matlab.ui.control.EditField
        ColormapfileLabel               matlab.ui.control.Label
        SelectImportPrepEDButton_2      matlab.ui.control.Button
        SubjectsperprotocolEditField    matlab.ui.control.NumericEditField
        SubjectsperprotocolEditFieldLabel  matlab.ui.control.Label
        ProtocolnameEditField           matlab.ui.control.EditField
        ProtocolnameEditFieldLabel      matlab.ui.control.Label
        ModalityDropDown                matlab.ui.control.DropDown
        ModalityDropDownLabel           matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        OutputconfigurationsPanel       matlab.ui.container.Panel
        ReportspathEditField            matlab.ui.control.EditField
        ReportspathEditFieldLabel       matlab.ui.control.Label
        WorkspacepathEditField          matlab.ui.control.EditField
        WorkspacepathEditFieldLabel     matlab.ui.control.Label
        SelectReportsButton             matlab.ui.control.Button
        SelectWorkspaceButton           matlab.ui.control.Button
        DependenciesPanel               matlab.ui.container.Panel
        SPM12pathEditField              matlab.ui.control.EditField
        SPM12pathEditFieldLabel         matlab.ui.control.Label
        BSTdbpathEditField              matlab.ui.control.EditField
        BrainstormdbpathEditFieldLabel  matlab.ui.control.Label
        BSTpathEditField                matlab.ui.control.EditField
        BraninstormpathEditFieldLabel   matlab.ui.control.Label
        SelectSPMButton                 matlab.ui.control.Button
        SelectBST_dbButton              matlab.ui.control.Button
        SelectBSTButton                 matlab.ui.control.Button
        AnatomicalstructureTab          matlab.ui.container.Tab
        MRITransformationPanel          matlab.ui.container.Panel
        ImportPrepEDEditField_3         matlab.ui.control.EditField
        ExamplefileLabel_2              matlab.ui.control.Label
        ImportPrepBPEditField_2         matlab.ui.control.EditField
        BasepathLabel_3                 matlab.ui.control.Label
        SelectImportPrepEDButton_3      matlab.ui.control.Button
        SelectImpPrepBPButton_2         matlab.ui.control.Button
        SurfacesresolutionPanel         matlab.ui.container.Panel
        NoverticesofCortexEditField     matlab.ui.control.NumericEditField
        NoverticesofCortexEditFieldLabel  matlab.ui.control.Label
        NoverticesofSkullEditField      matlab.ui.control.NumericEditField
        NoverticesofSkullEditFieldLabel  matlab.ui.control.Label
        NoverticesofHeadEditField       matlab.ui.control.NumericEditField
        NoverticesofHeadEditFieldLabel  matlab.ui.control.Label
        AnatomyconfigurationButtonGroup  matlab.ui.container.ButtonGroup
        HCPIndividualPanel              matlab.ui.container.Panel
        HCPIndividNBPathEditField       matlab.ui.control.EditField
        NonbrainsurfacesLabel_2         matlab.ui.control.Label
        HCPIndividPathEditField         matlab.ui.control.EditField
        AnatomypathLabel_2              matlab.ui.control.Label
        SelectCHPTemplatePathButton_4   matlab.ui.control.Button
        SelectCHPIndividPathButton      matlab.ui.control.Button
        HCPTemplatePanel                matlab.ui.container.Panel
        HCPTemplatenameEditField        matlab.ui.control.EditField
        TemplatenameEditFieldLabel      matlab.ui.control.Label
        HCPTemplNBPathEditField         matlab.ui.control.EditField
        NonbrainsurfacesLabel           matlab.ui.control.Label
        SelectCHPTemplNBPathButton      matlab.ui.control.Button
        HCPTemplPathEditField           matlab.ui.control.EditField
        AnatomypathLabel                matlab.ui.control.Label
        SelectCHPTemplPathButton        matlab.ui.control.Button
        BSTDefaultPanel                 matlab.ui.container.Panel
        AnatomyTemplateDropDown         matlab.ui.control.DropDown
        TemplatenameDropDownLabel       matlab.ui.control.Label
        Button_HCP_Individual           matlab.ui.control.RadioButton
        Button_HCP_Template             matlab.ui.control.RadioButton
        Button_BST_Default              matlab.ui.control.RadioButton
        ChannelTab                      matlab.ui.container.Tab
        SelectChanneloptionButtonGroup  matlab.ui.container.ButtonGroup
        UseChannelRawdataPanel          matlab.ui.container.Panel
        ChanFormatDropDown              matlab.ui.control.DropDown
        FormatDropDownLabel             matlab.ui.control.Label
        ChanRawDataFPathEditField       matlab.ui.control.EditField
        FilelocationLabel               matlab.ui.control.Label
        SelectRawDataFPathButton        matlab.ui.control.Button
        ChanRawDataBPathEditField       matlab.ui.control.EditField
        BasepathLabel                   matlab.ui.control.Label
        SelectRawDataBPathButton        matlab.ui.control.Button
        UseChanneltemplatePanel         matlab.ui.container.Panel
        RedefinelabelsButton            matlab.ui.control.Button
        Channel_UITable                 matlab.ui.control.Table
        ChannTemplatenameDropDown       matlab.ui.control.DropDown
        TemplatenameDropDownLabel_2     matlab.ui.control.Label
        ChannGroupDropDown              matlab.ui.control.DropDown
        GroupDropDownLabel              matlab.ui.control.Label
        Button_Channel_raw_data         matlab.ui.control.RadioButton
        Button_Channel_template         matlab.ui.control.RadioButton
        PreprocesseddataTab             matlab.ui.container.Tab
        SelectEventsCheckBox            matlab.ui.control.CheckBox
        CleanDataCheckBox               matlab.ui.control.CheckBox
        SelecteventsPanel               matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        DefineeventsButton              matlab.ui.control.Button
        GetEventsDropDown               matlab.ui.control.DropDown
        GeteventsbyLabel                matlab.ui.control.Label
        CleandataconfigurationPanel     matlab.ui.container.Panel
        MaxfrequencyEditField           matlab.ui.control.NumericEditField
        MaxfrequencyEditFieldLabel      matlab.ui.control.Label
        CleanToolboxPathEditField       matlab.ui.control.EditField
        ToolboxpathLabel                matlab.ui.control.Label
        SelectCleanToolboxBPButton      matlab.ui.control.Button
        CleanToolboxNameDropDown        matlab.ui.control.DropDown
        ToolboxLabel                    matlab.ui.control.Label
        PreprocesseddataconfigurationButtonGroup  matlab.ui.container.ButtonGroup
        PrepUseRawDataPanel             matlab.ui.container.Panel
        ImportpreprocesseddataPanel     matlab.ui.container.Panel
        ImportPrepFormatDropDown        matlab.ui.control.DropDown
        DataformatLabel                 matlab.ui.control.Label
        ImportPrepEDEditField           matlab.ui.control.EditField
        ExamplefileLabel                matlab.ui.control.Label
        SelectImportPrepEDButton        matlab.ui.control.Button
        ImportPrepBPEditField           matlab.ui.control.EditField
        BasepathLabel_2                 matlab.ui.control.Label
        SelectImpPrepBPButton           matlab.ui.control.Button
        DonotimportdataPanel            matlab.ui.container.Panel
        Button_PreUseRawData            matlab.ui.control.RadioButton
        Button_ImportPrepData           matlab.ui.control.RadioButton
        Button_DoNotImportData          matlab.ui.control.RadioButton
        SetpropertiesButton             matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function checked = check_visual_params(app)
            checked = true;
            if(isempty(app.BSTpathEditField.Value))
                msgbox({'The BrainStorm toolbox path can not be empty.',...
                    ' Please select a correct Brainstorm path.'},'Info');
                checked = false;
            end
            if(isempty(app.BSTdbpathEditField.Value))
                msgbox({'The BrainStorm db path can not be empty.',...
                    ' Please select a correct DB path.'},'Info');
                app.BSTdbpathEditField.Value = 'local';
                checked = false;
            end
            if(isempty(app.SPM12pathEditField.Value))
                msgbox({'The SPM12 path can not be empty.',...
                    ' Please select a correct SPM12 path.'},'Info');
                checked = false;
            end
            if(isempty(app.WorkspacepathEditField.Value))
                msgbox({'The Workspace path can not be empty.',...
                    ' Please select a correct Workspace path.'},'Info');
                checked = false;
            end
            if(isempty(app.ReportspathEditField.Value))
                msgbox({'The Reports path can not be empty.',...
                    ' Please select a correct Reports path.'},'Info');
                checked = false;
            end
            if(isempty(app.ProtocolnameEditField.Value))
                msgbox({'The Protocol name field can not be empty.',...
                    ' Please type a correct Protocol name.'},'Info');
                checked = false;
            end
        end
        
        function load_default_params(app)
            app.BSTDefaultPanel.Enable = 'on';
            app.HCPTemplatePanel.Enable = 'off';
            app.HCPIndividualPanel.Enable = 'off';
            get_bst_default_anatomy(app);
            
            app.UseChanneltemplatePanel.Enable = 'on';
            app.UseChannelRawdataPanel.Enable = 'off';
            get_bst_default_channel(app);
            
            % Preprocessed data tab
            
        end
        
        function get_bst_default_anatomy(app)
            % loading anatomical templates
            defaults = jsondecode(fileread(fullfile('bst_templates','bst_default_anatomy.json')));
            app.AnatomyTemplateDropDown.Items = {defaults.name};
            app.AnatomyTemplateDropDown.Items{end+1} = '--Select a template--';
            app.AnatomyTemplateDropDown.Value = '--Select a template--';
        end
        
        function get_bst_default_channel(app)
            defaults = jsondecode(fileread(fullfile('bst_templates','bst_layout_default.json')));
            app.ChannGroupDropDown.Items = {defaults.name};
            app.ChannGroupDropDown.Items{end+1} = '--Select--';
            app.ChannGroupDropDown.Value = '--Select--';
            app.ChannTemplatenameDropDown.Items = {'--Select a template--'};
            app.ChannTemplatenameDropDown.Value = '--Select a template--';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Dataset Configuration';
            addpath('app');
            addpath('bst_templates');
            addpath('config_protocols');
            addpath('external');
            addpath(genpath('functions'));
            addpath('external');
            load_default_params(app);
        end

        % Button pushed function: SelectBSTButton
        function SelectBSTButtonPushed(app, event)
            try
                folder = uigetdir("Select the Brinstorm path");
                if(isfile(fullfile(folder,'brainstorm.m')))
                    app.BSTpathEditField.Value = folder;
                else
                    msgbox({'The selected folder do not contain the BrainStorm toolbox.',...
                        folder,...
                        ' Please select a correct Brainstorm path.'},'Info');
                    app.BSTpathEditField.Value = '';
                end
            catch
            end
        end

        % Button pushed function: SelectSPMButton
        function SelectSPMButtonPushed(app, event)
            try
                folder = uigetdir("Select the SPM12 path");
                if(isfile(fullfile(folder,'spm.m')))
                    app.SPM12pathEditField.Value = folder;
                else
                    msgbox({'The selected folder do not contain the SPM12 toolbox.',...
                        folser,...
                        ' Please select a correct SPM12 path.'},'Info');
                    app.SPM12pathEditField.Value = '';
                end
            catch
            end
        end

        % Button pushed function: SelectBST_dbButton
        function SelectBST_dbButtonPushed(app, event)
            try
                folder = uigetdir("Select the BST db folder");
                [~,values] = fileattrib(folder);
                if(values.UserWrite)
                    app.BSTdbpathEditField.Value = folder;
                else
                    app.BSTdbpathEditField.Value = 'local';
                    msgbox({'The current user do not have write permissions on the selected forder.',...
                        ' Please check the folder permission or select another output folder.'},'Info');
                end
            catch
            end
        end

        % Button pushed function: SelectWorkspaceButton
        function SelectWorkspaceButtonPushed(app, event)
            try
                folder = uigetdir("Select the Output folder");
                [~,values] = fileattrib(folder);
                if(values.UserWrite)
                    app.WorkspacepathEditField.Value = folder;
                else
                    msgbox({'The current user do not have write permissions on the selected forder.',...
                        ' Please check the folder permission or select another output folder.'},'Info');
                end
            catch
            end
        end

        % Button pushed function: SelectReportsButton
        function SelectReportsButtonPushed(app, event)
            try
                folder = uigetdir("Select the Reports output folder");
                [~,values] = fileattrib(folder);
                if(values.UserWrite)
                    app.ReportspathEditField.Value = folder;
                else
                    msgbox({'The current user do not have write permissions on the selected forder.',...
                        folder,...
                        ' Please check the folder permission or select another output folder.'},'Info');
                end
            catch
            end
        end

        % Selection changed function: 
        % AnatomyconfigurationButtonGroup
        function AnatomyconfigurationButtonGroupSelectionChanged(app, event)
            if(app.Button_BST_Default.Value)
                app.BSTDefaultPanel.Enable = 'on';
                app.HCPTemplatePanel.Enable = 'off';
                app.HCPIndividualPanel.Enable = 'off';
            end
            if(app.Button_HCP_Template.Value)
                app.BSTDefaultPanel.Enable = 'off';
                app.HCPTemplatePanel.Enable = 'on';
                app.HCPIndividualPanel.Enable = 'off';
            end
            if(app.Button_HCP_Individual.Value)
                app.BSTDefaultPanel.Enable = 'off';
                app.HCPTemplatePanel.Enable = 'off';
                app.HCPIndividualPanel.Enable = 'on';
            end
        end

        % Value changed function: ChannGroupDropDown
        function ChannGroupDropDownValueChanged(app, event)
            group_name = app.ChannGroupDropDown.Value;
            if(isequal(app.ModalityDropDown.Value,'--Select--'))
                msgbox('Please select the modality first in Generals Tab.','Info');
                app.ChannGroupDropDown.Value = '--Select--';
                return;
            end
            if(isempty(app.BSTpathEditField.Value))
                msgbox('Please select the Brainstorm path in Generals Tab.','Info');
                app.ChannGroupDropDown.Value = '--Select--';
                return;
            end
            if(~isequal(group_name,'--Select--'))
                defaults = jsondecode(fileread(fullfile('bst_templates','bst_layout_default.json')));
                layouts_name = defaults(find(ismember({defaults.name},group_name),1)).contents;
                app.ChannTemplatenameDropDown.Items = {layouts_name.name};
                app.ChannTemplatenameDropDown.Items{end+1} = '--Select a template--';
                app.ChannTemplatenameDropDown.Value = '--Select a template--';
                msgbox({'Be sure to select the mode and direction of the BrainStorm in <<Generals Tab>>, before selecting the Channel Layout.'},'Info');
            else
                app.ChannTemplatenameDropDown.Items = {'--Select a template--'};
                app.ChannTemplatenameDropDown.Value = '--Select a template--';
                set(app.Channel_UITable, 'Data', []);
            end
        end

        % Selection changed function: SelectChanneloptionButtonGroup
        function SelectChanneloptionButtonGroupSelectionChanged(app, event)
            if(app.Button_Channel_template.Value)
                app.UseChanneltemplatePanel.Enable = 'on';
                app.UseChannelRawdataPanel.Enable = 'off';
            end
            if(app.Button_Channel_raw_data.Value)
                app.UseChanneltemplatePanel.Enable = 'off';
                app.UseChannelRawdataPanel.Enable = 'on';
            end
        end

        % Value changed function: ChannTemplatenameDropDown
        function ChannTemplatenameDropDownValueChanged(app, event)
            template_name = app.ChannTemplatenameDropDown.Value;
            if(~isequal(template_name,'--Select a template--'))
                template_name = strrep(template_name,' ','_');
                if(isequal(app.ModalityDropDown.Value,'--Select--'))
                    msgbox('Please select the modality in Generals Tab.','Info');
                    app.ChannTemplatenameDropDown.Value = '--Select a template--';
                    return;
                end
                if(isempty(app.BSTpathEditField.Value))
                    msgbox('Please select the Brainstorm path in Generals Tab.','Info');
                    app.ChannTemplatenameDropDown.Value = '--Select a template--';
                    return;
                end
                if(isequal(app.ModalityDropDown.Value,'EEG'))
                    layout = load(fullfile(app.BSTpathEditField.Value,'defaults','eeg',app.ChannGroupDropDown.Value,['channel_',template_name,'.mat']));
                end
                if(isequal(app.ModalityDropDown.Value,'MEG'))
                    layout = load(fullfile(app.BSTpathEditField.Value,'defaults','meg',[template_name,'.mat']));
                end
                for i=1:length(layout.Channel)
                    loc = layout.Channel(i).Loc;
                    Weight = layout.Channel(i).Weight;
                    fields(i).Locs = strcat(num2str(loc(1)),',',num2str(loc(2)),',',num2str(loc(3)));
                end
                order_layout.No         = cellstr(string(1:length(layout.Channel)))';
                order_layout.Name       = {layout.Channel.Name}';
                order_layout.Type       = {layout.Channel.Type}';
                order_layout.Position   = {fields.Locs}';
                order_layout.Comment    = {layout.Channel.Comment}';
                T                       = struct2table(order_layout);
                set(app.Channel_UITable, 'Data', T);
            else
                set(app.Channel_UITable, 'Data', []);
            end
            
        end

        % Value changed function: ModalityDropDown
        function ModalityDropDownValueChanged(app, event)
            modality = app.ModalityDropDown.Value;
            if(isequal(modality,'EEG'))
                app.Button_Channel_template.Value = true;
                app.UseChanneltemplatePanel.Enable = 'on';
                app.UseChannelRawdataPanel.Enable = 'off';
            else
                app.Button_Channel_raw_data.Value = true;
                app.UseChanneltemplatePanel.Enable = 'off';
                app.UseChannelRawdataPanel.Enable = 'on';
            end
        end

        % Button pushed function: SelectRawDataBPathButton
        function SelectRawDataBPathButtonPushed(app, event)
            try
                folder = uigetdir("Select the Raw data base path");
                folder = strrep(folder,'\','/');
                app.ChanRawDataBPathEditField.Value = folder;
            catch
            end
        end

        % Button pushed function: SelectCHPTemplPathButton
        function SelectCHPTemplPathButtonPushed(app, event)
            try
                if(isempty(app.HCPTemplatenameEditField.Value))
                    msgbox({'The HCP template name can not be empty.',...
                        'Please type a HCP Template name.'},'Info');
                    return;
                end
                template_name = app.HCPTemplatenameEditField.Value;
                folder = uigetdir("Select the Template basepath");
                if(~isfolder(fullfile(folder,template_name)))
                    msgbox({strcat("There is no folder with ",template_name," in the selected HCP Template folder."),...
                        'Please selcct a correct HCP Template folder or check the Template name filed.'},'Info');
                    app.HCPTemplPathEditField.Value = '';
                    return;
                end
                if(~isfile(fullfile(folder,template_name,'T1w','T1w.nii.gz')))
                    msgbox({'The template folder is not a HCP structure.',...
                        'Please check the HCP Template folder.'},'Info');
                    app.HCPTemplPathEditField.Value = '';
                    return;
                end
                app.HCPTemplPathEditField.Value = folder;
            catch
            end
        end

        % Button pushed function: SelectCHPTemplNBPathButton
        function SelectCHPTemplNBPathButtonPushed(app, event)
            try
                if(isempty(app.HCPTemplatenameEditField.Value))
                    msgbox({'The HCP template name can not be empty.',...
                        'Please type a HCP Template name.'},'Info');
                    return;
                end
                template_name = app.HCPTemplatenameEditField.Value;
                folder = uigetdir("Select the Template basepath");
                if(~isfolder(fullfile(folder,template_name)))
                    msgbox({strcat("There is no folder with ",template_name," in the selected Non-brain folder."),...
                        'Please selcct a correct Non-brain folder or check the Template name filed.'},'Info');
                    app.HCPTemplNBPathEditField.Value = '';
                    return;
                end
                if(~isfile(fullfile(folder,template_name,[template_name,'_outskin_mesh.nii.gz']))...
                        || ~isfile(fullfile(folder,template_name,[template_name,'_outskull_mesh.nii.gz']))...
                        || ~isfile(fullfile(folder,template_name,[template_name,'_inskull_mesh.nii.gz'])))
                msgbox({'One or more forders are not a FSL Bet output command.',...
                    'Please check the Non-brain Template folder.'},'Info');
                app.HCPTemplNBPathEditField.Value = '';
                return;
                end
                app.HCPTemplNBPathEditField.Value = folder;
            catch
            end
        end

        % Button pushed function: SelectCHPIndividPathButton
        function SelectCHPIndividPathButtonPushed(app, event)
            try
                folder = uigetdir("Select the Template basepath");
                structures = dir(folder);
                structures(ismember( {structures.name}, {'.', '..'})) = [];  %remove . and ..
                for i=1:length(structures)
                    structure = structures(i);
                    if(~isfile(fullfile(structure.folder,structure.name,'T1w','T1w.nii.gz')))
                        msgbox({'One or more forders are not a HCP structure.',...
                            'Please check the HCP Template folder.'},'Info');
                        break;
                    end
                end
                app.HCPIndividPathEditField.Value = folder;
            catch
            end
        end

        % Button pushed function: SelectCHPTemplatePathButton_4
        function SelectCHPTemplatePathButton_4Pushed(app, event)
            try
                folder = uigetdir("Select the Template basepath");
                structures = dir(folder);
                structures(ismember( {structures.name}, {'.', '..'})) = [];  %remove . and ..
                for i=1:length(structures)
                    structure = structures(i);
                    if(~isfile(fullfile(structure.folder,structure.name,[structure.name,'_outskin_mesh.nii.gz']))...
                            || ~isfile(fullfile(structure.folder,structure.name,[structure.name,'_outskull_mesh.nii.gz']))...
                            || ~isfile(fullfile(structure.folder,structure.name,[structure.name,'_inskull_mesh.nii.gz'])))
                    msgbox({'One or more forders are not a FSL Bet output command.',...
                        'Please check the Non-brain Template folder.'},'Info');
                    break;
                    end
                end
                app.HCPIndividNBPathEditField.Value = folder;
            catch
            end
        end

        % Button pushed function: RedefinelabelsButton
        function RedefinelabelsButtonPushed(app, event)
            if(isequal(app.ChannGroupDropDown.Value,'--Select--') || isequal(app.ChannTemplatenameDropDown.Value,'--Select a template--'))
                msgbox('Please select first a channel layout.','Info');
            else
                obj = Redefine_channel_labels();
                obj.load_channel_labels(app.BSTpathEditField.Value,[],app.ChannGroupDropDown.Value,app.ChannTemplatenameDropDown.Value);
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Selection changed function: 
        % PreprocesseddataconfigurationButtonGroup
        function PreprocesseddataconfigurationButtonGroupSelectionChanged(app, event)
            if(app.Button_DoNotImportData.Value)
                app.DonotimportdataPanel.Enable = 'on';
                app.ImportpreprocesseddataPanel.Enable = 'off';
                app.PrepUseRawDataPanel.Enable = 'off';
                app.CleanDataCheckBox.Enable = 'off';
                app.SelectEventsCheckBox.Enable = 'off';
                app.CleanDataCheckBox.Value = false;
                app.SelectEventsCheckBox.Value = false;
                app.CleandataconfigurationPanel.Enable = 'off';
                app.SelecteventsPanel.Enable = 'off';
            end
            if(app.Button_ImportPrepData.Value)
                app.DonotimportdataPanel.Enable = 'off';
                app.ImportpreprocesseddataPanel.Enable = 'on';
                app.PrepUseRawDataPanel.Enable = 'off';
                app.CleanDataCheckBox.Enable = 'on';
                app.SelectEventsCheckBox.Enable = 'on';
            end
            if(app.Button_PreUseRawData.Value)
                if(~app.Button_Channel_raw_data.Value || isempty(app.ChanRawDataBPathEditField.Value)...
                        || isempty(app.ChanRawDataFPathEditField.Value) || isequal(app.ChanFormatDropDown.Value,'-Select-'))
                msgbox('Select and fill the raw data fields in Channel tab, before use the raw data as signal.','Info');
                app.Button_DoNotImportData.Value = true;
                return;
                else                    
                    app.DonotimportdataPanel.Enable = 'off';
                    app.ImportpreprocesseddataPanel.Enable = 'off';
                    app.PrepUseRawDataPanel.Enable = 'on';
                    app.CleanDataCheckBox.Enable = 'on';
                    app.SelectEventsCheckBox.Enable = 'on';
                end
            end
        end

        % Value changed function: CleanDataCheckBox
        function CleanDataCheckBoxValueChanged(app, event)
            value = app.CleanDataCheckBox.Value;
            if(value)
                app.CleandataconfigurationPanel.Enable = 'on';
            else
                app.CleandataconfigurationPanel.Enable = 'off';
            end
        end

        % Value changed function: SelectEventsCheckBox
        function SelectEventsCheckBoxValueChanged(app, event)
            value = app.SelectEventsCheckBox.Value;
            if(value)
                app.SelecteventsPanel.Enable = 'on';
            else
                app.SelecteventsPanel.Enable = 'off';
            end
        end

        % Button pushed function: SelectImpPrepBPButton
        function SelectImpPrepBPButtonPushed(app, event)
            try
                folder = uigetdir("Select the Raw data base path");
                folder = strrep(folder,'\','/');
                app.ImportPrepBPEditField.Value = folder;
            catch
            end
        end

        % Button pushed function: SelectRawDataFPathButton
        function SelectRawDataFPathButtonPushed(app, event)
            try
                if(isempty(app.ChanRawDataBPathEditField.Value))
                    msgbox('Select first the base path of the raw data.','Info');
                    return;
                end
                [file_name,file_path] = uigetfile({'*.mat';'*.mff';'*.edf';'*.*'},'Select a reference file to import');
                file_path = strrep(file_path,'\','/');
                base_path = app.ChanRawDataBPathEditField.Value;
                file_parts = split(file_path,base_path);
                if(isequal(length(file_parts),1))
                    msgbox({'The base path and file path do not match in the first part.', ...
                        'Please check the configuration.'},'Info');                    
                    return;
                end
                SubID_parts = split(file_parts{2},'/');
                SubID = SubID_parts{2};
                ref_path = split(file_path,SubID);
                ref_path = strcat(ref_path{2},file_name);
                ref_path = strrep(ref_path,SubID,'SubID');
                app.ChanRawDataFPathEditField.Value = ref_path;
            catch
                
            end
        end

        % Button pushed function: SelectImportPrepEDButton
        function SelectImportPrepEDButtonPushed(app, event)
            try
                if(isempty(app.ImportPrepBPEditField.Value))
                    msgbox('Select first the base path of the preprocessed data.','Info');
                    return;
                end
                [file_name,file_path] = uigetfile({'*.mat';'*.mff';'*.edf';'*.txt';'*.plg';'*.dat';'*.set';'*.*'},'Select a reference file to import');
                file_path = strrep(file_path,'\','/');
                base_path = app.ImportPrepBPEditField.Value;
                file_parts = split(file_path,base_path);
                if(isequal(length(file_parts),1))
                    msgbox({'The base path and file path do not match in the first part.', ...
                        'Please check the configuration.'},'Info');
                    return;
                end
                SubID_parts = split(file_parts{2},'/');
                SubID = SubID_parts{2};
                ref_path = split(file_path,SubID);
                ref_path = strcat(ref_path{2},file_name);
                ref_path = strrep(ref_path,SubID,'SubID');
                app.ChanRawDataFPathEditField.Value = ref_path;
            catch
                
            end
        end

        % Button pushed function: SelectCleanToolboxBPButton
        function SelectCleanToolboxBPButtonPushed(app, event)
            if(isequal(app.CleanToolboxNameDropDown.Value,'--Select--'))
                msgbox('Please select the Toolbox name first','Info');
                return;
            end
             try
                folder = uigetdir("Select the clean toolbox path");
                folder = strrep(folder,'\','/');
                if(isequal(app.CleanToolboxNameDropDown.Value,'EEGLab') && isfile(fullfile(app.CleanToolboxNameDropDown.Value,'eeglab.m')))
                    app.CleanToolboxPathEditField.Value = folder;
                else
                    msgbox({'We can not find the eeglab.m file in the defined location',...
                        folder,...
                        'Please select the Toolbox path correctly.'},'Info');
                    app.CleanToolboxPathEditField.Value = '';
                    return;
                end
            catch
            end
        end

        % Button pushed function: DefineeventsButton
        function DefineeventsButtonPushed(app, event)
            if(isequal(app.GetEventsDropDown.Value,'--Select--'))
                msgbox({'Please select first the get even ',...
                        folder,...
                        'Please select the Toolbox path correctly.'},'Info');
            else
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SetpropertiesButton = uibutton(app.UIFigure, 'push');
            app.SetpropertiesButton.Text = 'Set properties';
            app.ParamsconfigurationPanel = uipanel(app.UIFigure);
            app.ParamsconfigurationPanel.Title = 'Params configuration';
            app.TabGroup = uitabgroup(app.ParamsconfigurationPanel);
            app.GeneralsTab = uitab(app.TabGroup);
            app.GeneralsTab.Title = 'Generals';
            app.DependenciesPanel = uipanel(app.GeneralsTab);
            app.DependenciesPanel.Title = 'Dependencies';
            app.SelectBSTButton = uibutton(app.DependenciesPanel, 'push');
            app.SelectBSTButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBSTButtonPushed, true);
            app.SelectBSTButton.Text = 'Select';
            app.SelectBST_dbButton = uibutton(app.DependenciesPanel, 'push');
            app.SelectBST_dbButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBST_dbButtonPushed, true);
            app.SelectBST_dbButton.Text = 'Select';
            app.SelectSPMButton = uibutton(app.DependenciesPanel, 'push');
            app.SelectSPMButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSPMButtonPushed, true);
            app.SelectSPMButton.Text = 'Select';
            app.BraninstormpathEditFieldLabel = uilabel(app.DependenciesPanel);
            app.BraninstormpathEditFieldLabel.Text = 'Braninstorm path:';
            app.BSTpathEditField = uieditfield(app.DependenciesPanel, 'text');
            app.BrainstormdbpathEditFieldLabel = uilabel(app.DependenciesPanel);
            app.BrainstormdbpathEditFieldLabel.Text = 'Brainstorm db path:';
            app.BSTdbpathEditField = uieditfield(app.DependenciesPanel, 'text');
            app.BSTdbpathEditField.Value = 'local';
            app.SPM12pathEditFieldLabel = uilabel(app.DependenciesPanel);
            app.SPM12pathEditFieldLabel.Text = 'SPM12 path:';
            app.SPM12pathEditField = uieditfield(app.DependenciesPanel, 'text');
            app.OutputconfigurationsPanel = uipanel(app.GeneralsTab);
            app.OutputconfigurationsPanel.Title = 'Output configurations';
            app.SelectWorkspaceButton = uibutton(app.OutputconfigurationsPanel, 'push');
            app.SelectWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SelectWorkspaceButtonPushed, true);
            app.SelectWorkspaceButton.Text = 'Select';
            app.SelectReportsButton = uibutton(app.OutputconfigurationsPanel, 'push');
            app.SelectReportsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectReportsButtonPushed, true);
            app.SelectReportsButton.Text = 'Select';
            app.WorkspacepathEditFieldLabel = uilabel(app.OutputconfigurationsPanel);
            app.WorkspacepathEditFieldLabel.Text = 'Workspace path:';
            app.WorkspacepathEditField = uieditfield(app.OutputconfigurationsPanel, 'text');
            app.ReportspathEditFieldLabel = uilabel(app.OutputconfigurationsPanel);
            app.ReportspathEditFieldLabel.Text = 'Reports path:';
            app.ReportspathEditField = uieditfield(app.OutputconfigurationsPanel, 'text');
            app.GeneralsparamsPanel = uipanel(app.GeneralsTab);
            app.GeneralsparamsPanel.Title = 'Generals params';
            app.Label = uilabel(app.GeneralsparamsPanel);
            app.Label.Text = '';
            app.ModalityDropDownLabel = uilabel(app.GeneralsparamsPanel);
            app.ModalityDropDownLabel.Text = 'Modality:';
            app.ModalityDropDown = uidropdown(app.GeneralsparamsPanel);
            app.ModalityDropDown.Items = {'--Select--', 'EEG', 'MEG'};
            app.ModalityDropDown.ValueChangedFcn = createCallbackFcn(app, @ModalityDropDownValueChanged, true);
            app.ModalityDropDown.Value = '--Select--';
            app.ProtocolnameEditFieldLabel = uilabel(app.GeneralsparamsPanel);
            app.ProtocolnameEditFieldLabel.Text = 'Protocol name:';
            app.ProtocolnameEditField = uieditfield(app.GeneralsparamsPanel, 'text');
            app.SubjectsperprotocolEditFieldLabel = uilabel(app.GeneralsparamsPanel);
            app.SubjectsperprotocolEditFieldLabel.Text = 'Subjects per protocol:';
            app.SubjectsperprotocolEditField = uieditfield(app.GeneralsparamsPanel, 'numeric');
            app.SubjectsperprotocolEditField.Limits = [5 100];
            app.SubjectsperprotocolEditField.Value = 10;
            app.SelectImportPrepEDButton_2 = uibutton(app.GeneralsparamsPanel, 'push');
            app.SelectImportPrepEDButton_2.Text = 'Select';
            app.ColormapfileLabel = uilabel(app.GeneralsparamsPanel);
            app.ColormapfileLabel.Text = 'Colormap file:';
            app.ImportPrepEDEditField_2 = uieditfield(app.GeneralsparamsPanel, 'text');
            app.AnatomicalstructureTab = uitab(app.TabGroup);
            app.AnatomicalstructureTab.Title = 'Anatomical structure';
            app.AnatomyconfigurationButtonGroup = uibuttongroup(app.AnatomicalstructureTab);
            app.AnatomyconfigurationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AnatomyconfigurationButtonGroupSelectionChanged, true);
            app.AnatomyconfigurationButtonGroup.Title = 'Anatomy configuration';
            app.Button_BST_Default = uiradiobutton(app.AnatomyconfigurationButtonGroup);
            app.Button_BST_Default.Text = '';
            app.Button_BST_Default.Value = true;
            app.Button_HCP_Template = uiradiobutton(app.AnatomyconfigurationButtonGroup);
            app.Button_HCP_Template.Text = '';
            app.Button_HCP_Individual = uiradiobutton(app.AnatomyconfigurationButtonGroup);
            app.Button_HCP_Individual.Text = '';
            app.BSTDefaultPanel = uipanel(app.AnatomyconfigurationButtonGroup);
            app.BSTDefaultPanel.Title = 'BST Default';
            app.TemplatenameDropDownLabel = uilabel(app.BSTDefaultPanel);
            app.TemplatenameDropDownLabel.Text = 'Template name:';
            app.AnatomyTemplateDropDown = uidropdown(app.BSTDefaultPanel);
            app.HCPTemplatePanel = uipanel(app.AnatomyconfigurationButtonGroup);
            app.HCPTemplatePanel.Enable = 'off';
            app.HCPTemplatePanel.Title = 'HCP Template';
            app.SelectCHPTemplPathButton = uibutton(app.HCPTemplatePanel, 'push');
            app.SelectCHPTemplPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCHPTemplPathButtonPushed, true);
            app.SelectCHPTemplPathButton.Text = 'Select';
            app.AnatomypathLabel = uilabel(app.HCPTemplatePanel);
            app.AnatomypathLabel.Text = 'Anatomy path:';
            app.HCPTemplPathEditField = uieditfield(app.HCPTemplatePanel, 'text');
            app.SelectCHPTemplNBPathButton = uibutton(app.HCPTemplatePanel, 'push');
            app.SelectCHPTemplNBPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCHPTemplNBPathButtonPushed, true);
            app.SelectCHPTemplNBPathButton.Text = 'Select';
            app.NonbrainsurfacesLabel = uilabel(app.HCPTemplatePanel);
            app.NonbrainsurfacesLabel.Text = 'Non-brain surfaces:';
            app.HCPTemplNBPathEditField = uieditfield(app.HCPTemplatePanel, 'text');
            app.TemplatenameEditFieldLabel = uilabel(app.HCPTemplatePanel);
            app.TemplatenameEditFieldLabel.Text = 'Template name:';
            app.HCPTemplatenameEditField = uieditfield(app.HCPTemplatePanel, 'text');
            app.HCPIndividualPanel = uipanel(app.AnatomyconfigurationButtonGroup);
            app.HCPIndividualPanel.Enable = 'off';
            app.HCPIndividualPanel.Title = 'HCP Individual';
            app.SelectCHPIndividPathButton = uibutton(app.HCPIndividualPanel, 'push');
            app.SelectCHPIndividPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCHPIndividPathButtonPushed, true);
            app.SelectCHPIndividPathButton.Text = 'Select';
            app.SelectCHPTemplatePathButton_4 = uibutton(app.HCPIndividualPanel, 'push');
            app.SelectCHPTemplatePathButton_4.ButtonPushedFcn = createCallbackFcn(app, @SelectCHPTemplatePathButton_4Pushed, true);
            app.SelectCHPTemplatePathButton_4.Text = 'Select';
            app.AnatomypathLabel_2 = uilabel(app.HCPIndividualPanel);
            app.AnatomypathLabel_2.Text = 'Anatomy path:';
            app.HCPIndividPathEditField = uieditfield(app.HCPIndividualPanel, 'text');
            app.NonbrainsurfacesLabel_2 = uilabel(app.HCPIndividualPanel);
            app.NonbrainsurfacesLabel_2.Text = 'Non-brain surfaces:';
            app.HCPIndividNBPathEditField = uieditfield(app.HCPIndividualPanel, 'text');
            app.SurfacesresolutionPanel = uipanel(app.AnatomicalstructureTab);
            app.SurfacesresolutionPanel.Title = 'Surfaces resolution';
            app.NoverticesofHeadEditFieldLabel = uilabel(app.SurfacesresolutionPanel);
            app.NoverticesofHeadEditFieldLabel.Text = 'No. vertices of Head:';
            app.NoverticesofHeadEditField = uieditfield(app.SurfacesresolutionPanel, 'numeric');
            app.NoverticesofHeadEditField.Limits = [5000 15000];
            app.NoverticesofHeadEditField.Value = 8000;
            app.NoverticesofSkullEditFieldLabel = uilabel(app.SurfacesresolutionPanel);
            app.NoverticesofSkullEditFieldLabel.Text = 'No. vertices of Skull:';
            app.NoverticesofSkullEditField = uieditfield(app.SurfacesresolutionPanel, 'numeric');
            app.NoverticesofSkullEditField.Limits = [5000 15000];
            app.NoverticesofSkullEditField.Value = 8000;
            app.NoverticesofCortexEditFieldLabel = uilabel(app.SurfacesresolutionPanel);
            app.NoverticesofCortexEditFieldLabel.Text = 'No. vertices of Cortex:';
            app.NoverticesofCortexEditField = uieditfield(app.SurfacesresolutionPanel, 'numeric');
            app.NoverticesofCortexEditField.Limits = [5000 15000];
            app.NoverticesofCortexEditField.Value = 8000;
            app.MRITransformationPanel = uipanel(app.AnatomicalstructureTab);
            app.MRITransformationPanel.Title = 'MRI Transformation';
            app.SelectImpPrepBPButton_2 = uibutton(app.MRITransformationPanel, 'push');
            app.SelectImpPrepBPButton_2.Text = 'Select';
            app.SelectImportPrepEDButton_3 = uibutton(app.MRITransformationPanel, 'push');
            app.SelectImportPrepEDButton_3.Text = 'Select';
            app.BasepathLabel_3 = uilabel(app.MRITransformationPanel);
            app.BasepathLabel_3.Text = 'Base path:';
            app.ImportPrepBPEditField_2 = uieditfield(app.MRITransformationPanel, 'text');
            app.ExamplefileLabel_2 = uilabel(app.MRITransformationPanel);
            app.ExamplefileLabel_2.Text = 'Example file:';
            app.ImportPrepEDEditField_3 = uieditfield(app.MRITransformationPanel, 'text');
            app.ChannelTab = uitab(app.TabGroup);
            app.ChannelTab.Title = 'Channel';
            app.SelectChanneloptionButtonGroup = uibuttongroup(app.ChannelTab);
            app.SelectChanneloptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectChanneloptionButtonGroupSelectionChanged, true);
            app.SelectChanneloptionButtonGroup.Title = 'Select Channel option';
            app.Button_Channel_template = uiradiobutton(app.SelectChanneloptionButtonGroup);
            app.Button_Channel_template.Text = '';
            app.Button_Channel_template.Value = true;
            app.Button_Channel_raw_data = uiradiobutton(app.SelectChanneloptionButtonGroup);
            app.Button_Channel_raw_data.Text = '';
            app.UseChanneltemplatePanel = uipanel(app.SelectChanneloptionButtonGroup);
            app.UseChanneltemplatePanel.Title = 'Use Channel template';
            app.GroupDropDownLabel = uilabel(app.UseChanneltemplatePanel);
            app.GroupDropDownLabel.Text = 'Group:';
            app.ChannGroupDropDown = uidropdown(app.UseChanneltemplatePanel);
            app.ChannGroupDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannGroupDropDownValueChanged, true);
            app.TemplatenameDropDownLabel_2 = uilabel(app.UseChanneltemplatePanel);
            app.TemplatenameDropDownLabel_2.Text = 'Template name:';
            app.ChannTemplatenameDropDown = uidropdown(app.UseChanneltemplatePanel);
            app.ChannTemplatenameDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannTemplatenameDropDownValueChanged, true);
            app.Channel_UITable = uitable(app.UseChanneltemplatePanel);
            app.Channel_UITable.ColumnName = {'No.'; 'Name'; 'Type'; 'Location'; 'Comment'};
            app.Channel_UITable.RowName = {};
            app.RedefinelabelsButton = uibutton(app.UseChanneltemplatePanel, 'push');
            app.RedefinelabelsButton.ButtonPushedFcn = createCallbackFcn(app, @RedefinelabelsButtonPushed, true);
            app.RedefinelabelsButton.Text = 'Redefine labels';
            app.UseChannelRawdataPanel = uipanel(app.SelectChanneloptionButtonGroup);
            app.UseChannelRawdataPanel.Enable = 'off';
            app.UseChannelRawdataPanel.Title = 'Use Raw data';
            app.SelectRawDataBPathButton = uibutton(app.UseChannelRawdataPanel, 'push');
            app.SelectRawDataBPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRawDataBPathButtonPushed, true);
            app.SelectRawDataBPathButton.Text = 'Select';
            app.BasepathLabel = uilabel(app.UseChannelRawdataPanel);
            app.BasepathLabel.Text = 'Base path:';
            app.ChanRawDataBPathEditField = uieditfield(app.UseChannelRawdataPanel, 'text');
            app.SelectRawDataFPathButton = uibutton(app.UseChannelRawdataPanel, 'push');
            app.SelectRawDataFPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRawDataFPathButtonPushed, true);
            app.SelectRawDataFPathButton.Text = 'Select';
            app.FilelocationLabel = uilabel(app.UseChannelRawdataPanel);
            app.FilelocationLabel.Text = 'File location:';
            app.ChanRawDataFPathEditField = uieditfield(app.UseChannelRawdataPanel, 'text');
            app.FormatDropDownLabel = uilabel(app.UseChannelRawdataPanel);
            app.FormatDropDownLabel.Text = 'Format:';
            app.ChanFormatDropDown = uidropdown(app.UseChannelRawdataPanel);
            app.ChanFormatDropDown.Items = {'-Select-', 'mat', 'mff', 'edf', '4D'};
            app.ChanFormatDropDown.Value = '-Select-';
            app.PreprocesseddataTab = uitab(app.TabGroup);
            app.PreprocesseddataTab.Title = 'Preprocessed data';
            app.PreprocesseddataconfigurationButtonGroup = uibuttongroup(app.PreprocesseddataTab);
            app.PreprocesseddataconfigurationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PreprocesseddataconfigurationButtonGroupSelectionChanged, true);
            app.PreprocesseddataconfigurationButtonGroup.Title = 'Preprocessed data configuration';
            app.Button_DoNotImportData = uiradiobutton(app.PreprocesseddataconfigurationButtonGroup);
            app.Button_DoNotImportData.Text = '';
            app.Button_DoNotImportData.Value = true;
            app.Button_ImportPrepData = uiradiobutton(app.PreprocesseddataconfigurationButtonGroup);
            app.Button_ImportPrepData.Text = '';
            app.Button_PreUseRawData = uiradiobutton(app.PreprocesseddataconfigurationButtonGroup);
            app.Button_PreUseRawData.Text = '';
            app.DonotimportdataPanel = uipanel(app.PreprocesseddataconfigurationButtonGroup);
            app.DonotimportdataPanel.Title = 'Do not import data';
            app.ImportpreprocesseddataPanel = uipanel(app.PreprocesseddataconfigurationButtonGroup);
            app.ImportpreprocesseddataPanel.Enable = 'off';
            app.ImportpreprocesseddataPanel.Title = 'Import preprocessed data';
            app.SelectImpPrepBPButton = uibutton(app.ImportpreprocesseddataPanel, 'push');
            app.SelectImpPrepBPButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImpPrepBPButtonPushed, true);
            app.SelectImpPrepBPButton.Text = 'Select';
            app.BasepathLabel_2 = uilabel(app.ImportpreprocesseddataPanel);
            app.BasepathLabel_2.Text = 'Base path:';
            app.ImportPrepBPEditField = uieditfield(app.ImportpreprocesseddataPanel, 'text');
            app.SelectImportPrepEDButton = uibutton(app.ImportpreprocesseddataPanel, 'push');
            app.SelectImportPrepEDButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImportPrepEDButtonPushed, true);
            app.SelectImportPrepEDButton.Text = 'Select';
            app.ExamplefileLabel = uilabel(app.ImportpreprocesseddataPanel);
            app.ExamplefileLabel.Text = 'Example file:';
            app.ImportPrepEDEditField = uieditfield(app.ImportpreprocesseddataPanel, 'text');
            app.DataformatLabel = uilabel(app.ImportpreprocesseddataPanel);
            app.DataformatLabel.Text = 'Data format:';
            app.ImportPrepFormatDropDown = uidropdown(app.ImportpreprocesseddataPanel);
            app.ImportPrepFormatDropDown.Items = {'--Select--', 'mat', 'plg', 'set', 'txt', 'edf'};
            app.ImportPrepFormatDropDown.Value = '--Select--';
            app.PrepUseRawDataPanel = uipanel(app.PreprocesseddataconfigurationButtonGroup);
            app.PrepUseRawDataPanel.Enable = 'off';
            app.PrepUseRawDataPanel.Title = 'Use raw data';
            app.CleandataconfigurationPanel = uipanel(app.PreprocesseddataTab);
            app.CleandataconfigurationPanel.Enable = 'off';
            app.CleandataconfigurationPanel.Title = 'Clean data configuration';
            app.ToolboxLabel = uilabel(app.CleandataconfigurationPanel);
            app.ToolboxLabel.Text = 'Toolbox:';
            app.CleanToolboxNameDropDown = uidropdown(app.CleandataconfigurationPanel);
            app.CleanToolboxNameDropDown.Items = {'--Select--', 'EEGLab'};
            app.CleanToolboxNameDropDown.Value = '--Select--';
            app.SelectCleanToolboxBPButton = uibutton(app.CleandataconfigurationPanel, 'push');
            app.SelectCleanToolboxBPButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCleanToolboxBPButtonPushed, true);
            app.SelectCleanToolboxBPButton.Text = 'Select';
            app.ToolboxpathLabel = uilabel(app.CleandataconfigurationPanel);
            app.ToolboxpathLabel.Text = 'Toolbox path:';
            app.CleanToolboxPathEditField = uieditfield(app.CleandataconfigurationPanel, 'text');
            app.MaxfrequencyEditFieldLabel = uilabel(app.CleandataconfigurationPanel);
            app.MaxfrequencyEditFieldLabel.Text = 'Max frequency:';
            app.MaxfrequencyEditField = uieditfield(app.CleandataconfigurationPanel, 'numeric');
            app.MaxfrequencyEditField.Limits = [20 90];
            app.MaxfrequencyEditField.Value = 90;
            app.SelecteventsPanel = uipanel(app.PreprocesseddataTab);
            app.SelecteventsPanel.Enable = 'off';
            app.SelecteventsPanel.Title = 'Select events';
            app.GeteventsbyLabel = uilabel(app.SelecteventsPanel);
            app.GeteventsbyLabel.Text = 'Get events by:';
            app.GetEventsDropDown = uidropdown(app.SelecteventsPanel);
            app.GetEventsDropDown.Items = {'--Select--', 'by good segments', 'by all mark'};
            app.GetEventsDropDown.Value = '--Select--';
            app.DefineeventsButton = uibutton(app.SelecteventsPanel, 'push');
            app.DefineeventsButton.ButtonPushedFcn = createCallbackFcn(app, @DefineeventsButtonPushed, true);
            app.DefineeventsButton.Text = 'Define events';
            app.UITable = uitable(app.SelecteventsPanel);
            app.UITable.ColumnName = {'Event name'};
            app.UITable.RowName = {};
            app.CleanDataCheckBox = uicheckbox(app.PreprocesseddataTab);
            app.CleanDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @CleanDataCheckBoxValueChanged, true);
            app.CleanDataCheckBox.Enable = 'off';
            app.CleanDataCheckBox.Text = '';
            app.SelectEventsCheckBox = uicheckbox(app.PreprocesseddataTab);
            app.SelectEventsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectEventsCheckBoxValueChanged, true);
            app.SelectEventsCheckBox.Enable = 'off';
            app.SelectEventsCheckBox.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dataset_configuration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end