classdef FreesurferUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Showrejectedsubjects     matlab.ui.control.Hyperlink
        DatasetinformationPanel  matlab.ui.container.Panel
        FreesurferPathEditField  matlab.ui.control.EditField
        pathLabel_2              matlab.ui.control.Label
        FreesurferPathButton     matlab.ui.control.Button
        LabelInfo                matlab.ui.control.Label
        RuninbackgroundCheckBox  matlab.ui.control.CheckBox
        UITable                  matlab.ui.control.Table
        DatasetFileEditField     matlab.ui.control.EditField
        filenameLabel            matlab.ui.control.Label
        DatasetPathEditField     matlab.ui.control.EditField
        pathLabel                matlab.ui.control.Label
        DatasetFileButton        matlab.ui.control.Button
        DatasetPathButton        matlab.ui.control.Button
        ButtonCancel             matlab.ui.control.Button
        ButtonRun                matlab.ui.control.Button
    end

    
    properties (Access = private)
        rejected_subs % Description
    end
    
    methods (Access = private)
        
        function SelectAll(app,cbx)
            if(cbx.Value)
                app.UITable.Data{:,1} = true(size(app.UITable.Data(:,1)));
            else
                app.UITable.Data{:,1} = false(size(app.UITable.Data(:,1)));
            end
            total = size(app.UITable.Data(:,1),1);
            selected = length(find(table2array(app.UITable.Data(:,1))));
            app.LabelInfo.Text = strcat(num2str(selected)," selected from ",num2str(total), " subjects." );
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DatasetPathButton
        function DatasetPathButtonPushed(app, event)
            try
                folder = uigetdir("Select the Dataset path");
                folder = strrep(folder,'\','/');
                app.DatasetPathEditField.Value = folder;

                % Filling table
                subjects = dir(folder);
                subjects(ismember({subjects.name},{'.','..','derivatives'})) = [];
                subjects(~[subjects.isdir]) = [];
                subs = {subjects.name}';
                cbxs = true(size(subjects));
                T = table(cbxs,subs);
                app.UITable.Data = T;
                if(isequal(app.DatasetinformationPanel.Children(1).Tag,'CheckAll'))
                    delete(app.DatasetinformationPanel.Children(1))
                end
                uicheckbox("Value",true,Tag='CheckAll',Text='',Position=[55,250,30,30],Parent=app.DatasetinformationPanel,...
                    ValueChangedFcn=@(cbt,event) app.SelectAll(cbt));
                total = size(subjects,1);
                selected = length(find(table2array(app.UITable.Data(:,1))));
                app.LabelInfo.Text = strcat(num2str(selected)," selected from ",num2str(total), " subjects." );
            catch
                app.DatasetPathEditField.Value = '';
            end
            app.DatasetFileEditField.Value = '';
            app.Showrejectedsubjects.Enable = false;
            figure(app.UIFigure);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            total = size(app.UITable.Data(:,1),1);
            selected = length(find(table2array(app.UITable.Data(:,1))));
            app.LabelInfo.Text = strcat(num2str(selected)," selected from ",num2str(total), " subjects." );
        end

        % Button pushed function: DatasetFileButton
        function DatasetFileButtonPushed(app, event)
            try
                if(isempty(app.DatasetPathEditField.Value))
                    msgbox('Select first the Dataset base path.','Info');
                    return;
                end
                root_path = app.DatasetPathEditField.Value;
                [file_name,file_path] = uigetfile({'*.nii.gz';'*.nii'},'Select a reference file to import',root_path);
                file_path = strrep(file_path,'\','/');
                base_path = app.DatasetPathEditField.Value;
                file_parts = split(file_path,base_path);
                if(isequal(length(file_parts),1))
                    msgbox({'The base path and file path do not match in the first part.', ...
                        'Please check the configuration.'},'Info');                    
                    return;
                end
                SubID_parts = split(file_parts{2},'/');
                SubID = SubID_parts{2};
                SubID_parts(1:2) = [];
                ref_path = fullfile(SubID_parts{:},file_name);                
                ref_path = strrep(ref_path,SubID,'SubID');
                app.DatasetFileEditField.Value = ref_path;
                
                % Filling table
                subjects = dir(app.DatasetPathEditField.Value);
                subjects(ismember({subjects.name},{'.','..','derivatives'})) = [];
                subjects(~[subjects.isdir]) = [];
                subs = {subjects.name}';
                reject = [];
                for i=1:length(subs)
                    sub = subs{i};
                    file = fullfile(app.DatasetPathEditField.Value,sub,strrep(ref_path,'SubID',sub));
                    if(~isfile(file))
                        reject = [reject i];
                    end
                end
                app.rejected_subs = subjects(reject);
                app.Showrejectedsubjects.Enable = false;
                if(~isempty(reject))
                    app.Showrejectedsubjects.Enable = true;
                    subs(reject) = [];
                    cbxs = true(size(subs));
                    T = table(cbxs,subs);
                    app.UITable.Data = T;
                    if(isequal(app.DatasetinformationPanel.Children(1).Tag,'CheckAll'))
                        delete(app.DatasetinformationPanel.Children(1))
                    end
                    uicheckbox("Value",true,Tag='CheckAll',Text='',Position=[55,250,30,30],Parent=app.DatasetinformationPanel,...
                        ValueChangedFcn=@(cbt,event) app.SelectAll(cbt));
                    total = size(subs,1);
                    selected = length(find(table2array(app.UITable.Data(:,1))));
                    app.LabelInfo.Text = strcat(num2str(selected)," selected from ",num2str(total), " subjects." );
                    msgbox({'Some subjects where rejected from the analysis.', ...
                        'The T1w file were not found.',...
                        'Please check the configuration.'},'Info');
                end                
            catch
                app.DatasetFileEditField.Value = '';
            end
            figure(app.UIFigure);
        end

        % Value changed function: RuninbackgroundCheckBox
        function RuninbackgroundCheckBoxValueChanged(app, event)
            value = app.RuninbackgroundCheckBox.Value;
            if(value)
                app.RuninbackgroundCheckBox.FontWeight = 'bold';
            else
                app.RuninbackgroundCheckBox.FontWeight = 'normal';
            end
        end

        % Button pushed function: ButtonRun
        function ButtonRunPushed(app, event)
            if(isempty(app.FreesurferPathEditField.Value))
                msgbox({'The Freesurfer workspace path can not be empty.', ...
                    'Please check the configuration.'},'Info');
                figure(app.UIFigure);
                return;
            end
            if(isempty(app.DatasetPathEditField.Value))
                msgbox({'The Dataset base path can not be empty.', ...
                    'Please check the configuration.'},'Info');
                figure(app.UIFigure);
                return;
            end
            if(isempty(app.DatasetFileEditField.Value))
                msgbox({'The T1w Reference file can not be empty.', ...
                    'Please check the configuration.'},'Info');
                figure(app.UIFigure);
                return;
            end
            if(isequal(length(find(table2array(app.UITable.Data(:,1)))),0))
                msgbox({'You should select at least one subject to process.', ...
                    'Please check the configuration.'},'Info');
                figure(app.UIFigure);
                return;
            end
            freesurfer_ws = app.FreesurferPathEditField.Value;
            dataset_path = app.DatasetPathEditField.Value;
            ref_file = app.DatasetFileEditField.Value;
            indxs = find(table2array(app.UITable.Data(:,1)));
            subjects = join(string(table2array(app.UITable.Data(indxs,2))));

            structual = jsondecode(fileread(fullfile('cfs_properties/process_structural.json')));
            structual.freesurfer.workspace = freesurfer_ws;
            structual.freesurfer.dataset_path = dataset_path;
            structual.freesurfer.ref_file     = ref_file;
            structual.freesurfer.participants = subjects;

            saveJSON(structual,fullfile(pwd,'cfs_properties/process_structural.json'));
            h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/process_structural.json'));
            h.smartIndentContents
            h.save
            h.close
            if(app.RuninbackgroundCheckBox.Value)
                command = strcat("./guide/freesurfer_processing.sh ",freesurfer_ws," '",subjects, "' ", dataset_path, " ", ref_file , " &");
            else
                command = strcat("./guide/freesurfer_processing.sh ",freesurfer_ws," '",subjects, "' ", dataset_path, " ", ref_file);
            end
            [status,cmdout] = system(command,'-echo');
        end

        % Button pushed function: ButtonCancel
        function ButtonCancelPushed(app, event)
            delete(app);
        end

        % Button pushed function: FreesurferPathButton
        function FreesurferPathButtonPushed(app, event)
            try
                folder = uigetdir("Select the Freesurfer workspace path");
                folder = strrep(folder,'\','/');
                app.FreesurferPathEditField.Value = folder;                
            catch
                app.FreesurferPathEditField.Value = '';
            end
            app.DatasetPathEditField.Value = '';
            app.DatasetFileEditField.Value = '';
            figure(app.UIFigure);
        end

        % Callback function: Showrejectedsubjects
        function ShowrejectedsubjectsHyperlinkClicked(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Freesurfer configuration';
            app.ButtonRun = uibutton(app.UIFigure, 'push');
            app.ButtonRun.ButtonPushedFcn = createCallbackFcn(app, @ButtonRunPushed, true);
            app.ButtonRun.Icon = fullfile(pathToMLAPP, 'images', 'run.jpg');
            app.ButtonRun.Text = 'Run';
            app.ButtonCancel = uibutton(app.UIFigure, 'push');
            app.ButtonCancel.ButtonPushedFcn = createCallbackFcn(app, @ButtonCancelPushed, true);
            app.ButtonCancel.Icon = fullfile(pathToMLAPP, 'images', 'cancel.jpg');
            app.ButtonCancel.Text = 'Cancel';
            app.DatasetinformationPanel = uipanel(app.UIFigure);
            app.DatasetinformationPanel.Title = 'Dataset information';
            app.DatasetPathButton = uibutton(app.DatasetinformationPanel, 'push');
            app.DatasetPathButton.ButtonPushedFcn = createCallbackFcn(app, @DatasetPathButtonPushed, true);
            app.DatasetPathButton.Text = 'Select';
            app.DatasetFileButton = uibutton(app.DatasetinformationPanel, 'push');
            app.DatasetFileButton.ButtonPushedFcn = createCallbackFcn(app, @DatasetFileButtonPushed, true);
            app.DatasetFileButton.Text = 'Select';
            app.pathLabel = uilabel(app.DatasetinformationPanel);
            app.pathLabel.Text = 'Base path:';
            app.DatasetPathEditField = uieditfield(app.DatasetinformationPanel, 'text');
            app.DatasetPathEditField.Editable = 'off';
            app.filenameLabel = uilabel(app.DatasetinformationPanel);
            app.filenameLabel.Text = 'Reference file:';
            app.DatasetFileEditField = uieditfield(app.DatasetinformationPanel, 'text');
            app.DatasetFileEditField.Editable = 'off';
            app.UITable = uitable(app.DatasetinformationPanel);
            app.UITable.ColumnName = {'Select'; 'Subjects'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.ColumnEditable = [true false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.Multiselect = 'off';
            app.RuninbackgroundCheckBox = uicheckbox(app.DatasetinformationPanel);
            app.RuninbackgroundCheckBox.ValueChangedFcn = createCallbackFcn(app, @RuninbackgroundCheckBoxValueChanged, true);
            app.RuninbackgroundCheckBox.Tooltip = {'If "checked" will run this process in background and you can continue using the CiftiStorm application'};
            app.RuninbackgroundCheckBox.Text = 'Run in background';
            app.LabelInfo = uilabel(app.DatasetinformationPanel);
            app.LabelInfo.Text = '';
            app.FreesurferPathButton = uibutton(app.DatasetinformationPanel, 'push');
            app.FreesurferPathButton.ButtonPushedFcn = createCallbackFcn(app, @FreesurferPathButtonPushed, true);
            app.FreesurferPathButton.Tooltip = {'Freesurfer Workspace'};
            app.FreesurferPathButton.Text = 'Select';
            app.pathLabel_2 = uilabel(app.DatasetinformationPanel);
            app.pathLabel_2.Text = 'Fresurfer WS:';
            app.FreesurferPathEditField = uieditfield(app.DatasetinformationPanel, 'text');
            app.FreesurferPathEditField.Editable = 'off';
            app.FreesurferPathEditField.Tooltip = {'Freesurfer Workspace'};
            app.Showrejectedsubjects = uihyperlink(app.UIFigure);
            app.Showrejectedsubjects.HyperlinkClickedFcn = createCallbackFcn(app, @ShowrejectedsubjectsHyperlinkClicked, true);
            app.Showrejectedsubjects.Enable = 'off';
            app.Showrejectedsubjects.Text = {'Show rejected subjects'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FreesurferUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end