classdef Params_configuration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SetparametersPanel              matlab.ui.container.Panel
        ButtonNext                      matlab.ui.control.Button
        ButtonBack                      matlab.ui.control.Button
        ApplyButton                     matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        GeneralsTab                     matlab.ui.container.Tab
        GeneralsparamsPanel             matlab.ui.container.Panel
        LabelReqOutput                  matlab.ui.control.Label
        LabelReqBSTPath                 matlab.ui.control.Label
        LabelReqProtocolName            matlab.ui.control.Label
        LabelReqDatasetName             matlab.ui.control.Label
        LabelReqModality                matlab.ui.control.Label
        DatasetDescriptionTextArea      matlab.ui.control.TextArea
        DatasetdescriptionLabel         matlab.ui.control.Label
        DatasetnameEditField            matlab.ui.control.EditField
        DatasetnameEditFieldLabel       matlab.ui.control.Label
        ResetprotocolSwitch             matlab.ui.control.Switch
        ResetprotocolSwitchLabel        matlab.ui.control.Label
        BSTdbpathEditField              matlab.ui.control.EditField
        BrainstormdbpathEditFieldLabel  matlab.ui.control.Label
        BSTpathEditField                matlab.ui.control.EditField
        BraninstormpathEditFieldLabel   matlab.ui.control.Label
        SelectBST_dbButton              matlab.ui.control.Button
        SelectBSTButton                 matlab.ui.control.Button
        OutputpathEditField             matlab.ui.control.EditField
        OutputpathEditFieldLabel        matlab.ui.control.Label
        SelectOutputPathButton          matlab.ui.control.Button
        ProtocolnameEditField           matlab.ui.control.EditField
        ProtocolnameEditFieldLabel      matlab.ui.control.Label
        ModalityDropDown                matlab.ui.control.DropDown
        ModalityDropDownLabel           matlab.ui.control.Label
        ImportAnatomyTab                matlab.ui.container.Tab
        MRITransformationPanel          matlab.ui.container.Panel
        ApplyLabel                      matlab.ui.control.Label
        UseMRITransCheckBox             matlab.ui.control.CheckBox
        MRITransFileEditField           matlab.ui.control.EditField
        filenameLabel                   matlab.ui.control.Label
        MRITransPathEditField           matlab.ui.control.EditField
        pathLabel                       matlab.ui.control.Label
        SelectMRITransFileButton        matlab.ui.control.Button
        SelectMRITransPathButton        matlab.ui.control.Button
        CommonparamsIAPanel             matlab.ui.container.Panel
        LabelReqSurfResol               matlab.ui.control.Label
        LabelReqNonBrain                matlab.ui.control.Label
        LabelReqLayerDesc               matlab.ui.control.Label
        LayerDescripDropDown            matlab.ui.control.DropDown
        LayerdescriptorLabel            matlab.ui.control.Label
        SurfacesresolutionEditField     matlab.ui.control.NumericEditField
        SurfacesresolutionEditFieldLabel  matlab.ui.control.Label
        NonBrainPathEditField           matlab.ui.control.EditField
        NonbrainsurfacesLabel           matlab.ui.control.Label
        SelectNonBrainPathButton        matlab.ui.control.Button
        SelectAnatomyoptionButtonGroup  matlab.ui.container.ButtonGroup
        HCPIndividualAnatomyPanel       matlab.ui.container.Panel
        LabelReqHCPAtlasAnat            matlab.ui.control.Label
        LabelReqHCPfileAnat             matlab.ui.control.Label
        LabelReqHCPpathAnat             matlab.ui.control.Label
        AnatomyIndivT1wDropDown         matlab.ui.control.DropDown
        AtlasLabel_3                    matlab.ui.control.Label
        AnatomyIndivAtlasDropDown       matlab.ui.control.DropDown
        AtlasLabel_2                    matlab.ui.control.Label
        HCPIndividPathEditField         matlab.ui.control.EditField
        AnatomypathLabel_2              matlab.ui.control.Label
        SelectHCPIndividPathButton      matlab.ui.control.Button
        BSTDefaultAnatomyPanel          matlab.ui.container.Panel
        LabelReq_TemplateAtlasAnat      matlab.ui.control.Label
        LabelReqTemplatenameAnat        matlab.ui.control.Label
        AnatomyTemplateAtlasDropDown    matlab.ui.control.DropDown
        AtlasLabel                      matlab.ui.control.Label
        AnatomyTemplateDropDown         matlab.ui.control.DropDown
        TemplatenameDropDownLabel       matlab.ui.control.Label
        Button_HCP_Individual           matlab.ui.control.RadioButton
        Button_BST_Default              matlab.ui.control.RadioButton
        ImportChannelTab                matlab.ui.container.Tab
        SelectChanneloptionButtonGroup  matlab.ui.container.ButtonGroup
        RawdataPanel                    matlab.ui.container.Panel
        LabelReqFormat                  matlab.ui.control.Label
        LabelReqDataFile                matlab.ui.control.Label
        LabelReqDataBasePath            matlab.ui.control.Label
        SelectRawDataFPathButton        matlab.ui.control.Button
        RawDataFormatDropDown           matlab.ui.control.DropDown
        FormatDropDownLabel             matlab.ui.control.Label
        RawDataFileEditField            matlab.ui.control.EditField
        FilepathLabel                   matlab.ui.control.Label
        RawDataBPathEditField           matlab.ui.control.EditField
        BasepathLabel                   matlab.ui.control.Label
        SelectRawDataBPathButton        matlab.ui.control.Button
        UseChanneltemplatePanel         matlab.ui.container.Panel
        LabelReqChannelName             matlab.ui.control.Label
        LabelReqChannelGroup            matlab.ui.control.Label
        Channel_UITable                 matlab.ui.control.Table
        ChannTemplateNameDropDown       matlab.ui.control.DropDown
        TemplatenameDropDownLabel_2     matlab.ui.control.Label
        ChannTemplateGroupDropDown      matlab.ui.control.DropDown
        GroupDropDownLabel              matlab.ui.control.Label
        Button_Channel_raw_data         matlab.ui.control.RadioButton
        Button_Channel_template         matlab.ui.control.RadioButton
        ComputeHeadmodelTab             matlab.ui.container.Tab
        MethodsButtonGroup              matlab.ui.container.ButtonGroup
        DUNEuroFEMoptionsPanel          matlab.ui.container.Panel
        PanelSubtraction                matlab.ui.container.Panel
        Intorderadd_lbSpinner           matlab.ui.control.Spinner
        Intorderadd_lbSpinnerLabel      matlab.ui.control.Label
        NumMomentsSpinner_2             matlab.ui.control.Spinner
        NoofmomentsLabel_2              matlab.ui.control.Label
        PanelVenant                     matlab.ui.container.Panel
        RelFactExponentSpinner          matlab.ui.control.Spinner
        RelFactExponentSpinnerLabel     matlab.ui.control.Label
        WeightingexponentSpinner        matlab.ui.control.Spinner
        WeightingexponentSpinnerLabel   matlab.ui.control.Label
        ReflengthSpinner                matlab.ui.control.Spinner
        ReflengthSpinnerLabel           matlab.ui.control.Label
        NumMomentsSpinner               matlab.ui.control.Spinner
        NoofmomentsLabel                matlab.ui.control.Label
        DropDownFEMSourcemodel          matlab.ui.control.DropDown
        SourcemodelDropDownLabel        matlab.ui.control.Label
        FEMoptsPanel                    matlab.ui.container.Panel
        Panelfieldtrip                  matlab.ui.container.Panel
        NodeShiftSpinner                matlab.ui.control.Spinner
        NodeShiftSpinnerLabel           matlab.ui.control.Label
        DownsampleSpinner               matlab.ui.control.Spinner
        DownsampleSpinnerLabel          matlab.ui.control.Label
        Panelsimnibs4                   matlab.ui.container.Panel
        NbVerticesSpinner_4             matlab.ui.control.Spinner
        NbVerticesSpinner_4Label        matlab.ui.control.Label
        Panelsimnibs3                   matlab.ui.container.Panel
        VertexDensitySpinner            matlab.ui.control.Spinner
        VertexDensitySpinnerLabel       matlab.ui.control.Label
        NbVerticesSpinner_3             matlab.ui.control.Spinner
        NbVerticesSpinner_3Label        matlab.ui.control.Label
        Paneliso2mesh                   matlab.ui.container.Panel
        KeepRatioSpinner                matlab.ui.control.Spinner
        KeepRatioSpinnerLabel           matlab.ui.control.Label
        MaxVolSpinner                   matlab.ui.control.Spinner
        MaxVolSpinnerLabel              matlab.ui.control.Label
        MergeMethodDropDown             matlab.ui.control.DropDown
        MergeMethodDropDownLabel        matlab.ui.control.Label
        EditFieldBrainFEM               matlab.ui.control.NumericEditField
        EditFieldSkullFEM               matlab.ui.control.NumericEditField
        EditFieldScalpFEM               matlab.ui.control.NumericEditField
        CheckBoxBrainFEM                matlab.ui.control.CheckBox
        CheckBoxSkullFEM                matlab.ui.control.CheckBox
        CheckBoxScalpFEM                matlab.ui.control.CheckBox
        FEMFilesConductivitiesLabel     matlab.ui.control.Label
        DropDownFEMMethod               matlab.ui.control.DropDown
        MethodLabel                     matlab.ui.control.Label
        OpenMEEGBEMoptionsPanel         matlab.ui.container.Panel
        EditFieldBrainCond              matlab.ui.control.NumericEditField
        EditFieldSkullCond              matlab.ui.control.NumericEditField
        EditFieldScalpCond              matlab.ui.control.NumericEditField
        CheckBoxBrainBEM                matlab.ui.control.CheckBox
        CheckBoxSkullBEM                matlab.ui.control.CheckBox
        CheckBoxScalpBEM                matlab.ui.control.CheckBox
        BEMFilesConductivitiesLabel     matlab.ui.control.Label
        SplitLengthSpinner              matlab.ui.control.Spinner
        SplitLengthSpinnerLabel         matlab.ui.control.Label
        isSplitSwitch                   matlab.ui.control.Switch
        isSplitSwitchLabel              matlab.ui.control.Label
        isAdaptativeSwitch              matlab.ui.control.Switch
        isAdaptativeSwitchLabel         matlab.ui.control.Label
        isAdjointSwitch                 matlab.ui.control.Switch
        isAdjointSwitchLabel            matlab.ui.control.Label
        SpheresoptionsPanel             matlab.ui.container.Panel
        ButtonGroup                     matlab.ui.container.ButtonGroup
        Buttons3Shellsphere             matlab.ui.control.RadioButton
        ButtonOverlappingspheres        matlab.ui.control.RadioButton
        ButtonSinglesphere              matlab.ui.control.RadioButton
        Button_DUNEuroFEM               matlab.ui.control.RadioButton
        Button_OpenMEEGBEM              matlab.ui.control.RadioButton
        Button_Spheres                  matlab.ui.control.RadioButton
        FunctionalIntegrationTab        matlab.ui.container.Tab
        FuctionalparamsPanel            matlab.ui.container.Panel
        LabelReqDataFormat              matlab.ui.control.Label
        DataFormatDropDown              matlab.ui.control.DropDown
        DataeventLabel_2                matlab.ui.control.Label
        LabelReqDataEvent               matlab.ui.control.Label
        DataEventDropDown               matlab.ui.control.DropDown
        DataeventLabel                  matlab.ui.control.Label
        LabelReqEEGLABOutputPath        matlab.ui.control.Label
        PrepdatapathEditField           matlab.ui.control.EditField
        EEGLABOutputpathLabel           matlab.ui.control.Label
        SelectPrepdatapathButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        cfs_properties % Description
        cfs_dir % Description
    end
    
    methods (Access = private)
        
        function checked = check_visual_params(app)
            checked = true;
            %% General tab
            if(isequal(app.ModalityDropDown.Value,'--Select--'))
                 msgbox({'The Modality can not be empty',...
                    ' Please select a correct Modality.'},'Info');
                checked = false;
                return;
            end
            if(isempty(app.DatasetnameEditField.Value))
                 msgbox({'The Dataset name can not be empty',...
                    ' Please type a Dataset name.'},'Info');
                checked = false;
                return;
            end
            if(isempty(app.ProtocolnameEditField.Value))
                 msgbox({'The Protocol name can not be empty',...
                    ' Please type a Protocol name.'},'Info');
                checked = false;
                return;
            end
            
            iProtocol = bst_get('Protocol', app.ProtocolnameEditField.Value);
            if(~isempty(iProtocol))
                msgbox({'The Protocol name already exist',...
                    ' Please type a new Protocol name.'},'Info');
                checked = false;
                return;
            end

            if(isempty(app.BSTpathEditField.Value))
                msgbox({'The BrainStorm toolbox path can not be empty.',...
                    ' Please select a correct Brainstorm path.'},'Info');
                checked = false;
                return;
            end
            if(isempty(app.BSTdbpathEditField.Value))
                msgbox({'The BrainStorm db path can not be empty.',...
                    ' Please select a correct DB path.'},'Info');
                app.BSTdbpathEditField.Value = 'local';
                checked = false;
                return;
            end           
            if(isempty(app.OutputpathEditField.Value))
                msgbox({'The Output path can not be empty.',...
                    ' Please select a correct Output path.'},'Info');
                checked = false;
                return;
            end
            
            %% Import anatomy tab
            if(app.Button_BST_Default.Value)
                if(isequal(app.AnatomyTemplateDropDown.Value,'--Select a template--'))
                    msgbox({'The Template can not be empty',...
                        ' Please select a correct Template.'},'Info');
                    checked = false;
                    return;
                end
                if(isequal(app.AnatomyTemplateAtlasDropDown.Value,'--Select an Atlas--'))
                    msgbox({'The Atlas can not be empty',...
                        ' Please select a correct Atlas.'},'Info');
                    checked = false;
                    return;
                end
            end
            if(app.Button_HCP_Individual.Value)
               if(isempty(app.HCPIndividPathEditField.Value))
                   msgbox({'The HCP anatomy path can not be empty',...
                       ' Please select a correct HCP anatomy path.'},'Info');
                   checked = false;
                   return;
               end
               if(isequal(app.AnatomyIndivT1wDropDown.Value, '--Select the T1w file--'))
                   msgbox({'The T1w file can not be empty',...
                       ' Please select a correct T1w file.'},'Info');
                   checked = false;
                   return;
               end
               if(isequal(app.AnatomyIndivAtlasDropDown.Value, '--Select the Atlas file--'))
                   msgbox({'The Atlas file can not be empty',...
                       ' Please select a correct Atlas file.'},'Info');
                   checked = false;
                   return;
               end
            end
            if(isequal(app.LayerDescripDropDown.Value,'--Select--'))
                msgbox({'The Layer descriptor can not be empty',...
                    ' Please select a correct Layer descriptor.'},'Info');
                checked = false;
                return;
            end
            if(isempty(app.NonBrainPathEditField.Value))
                msgbox({'The Non-Brain path can not be empty.',...
                    ' Please select a correct Non-Brain path.'},'Info');
                checked = false;
                return;
            end

            %% Import channel tab
             if(app.Button_Channel_template.Value)
                 if(isequal(app.ChannTemplateGroupDropDown.Value,'--Select--'))
                     msgbox({'The Template group can not be empty',...
                         ' Please select a correct Template group.'},'Info');
                     checked = false;
                     return;
                 end
                  if(isequal(app.ChannTemplateNameDropDown.Value,'--Select--'))
                     msgbox({'The Template name can not be empty',...
                         ' Please select a correct Template name.'},'Info');
                     checked = false;
                     return;
                 end
             end
             if(app.Button_Channel_raw_data.Value)
                 if(isempty(app.RawDataBPathEditField.Value))
                     msgbox({'The Raw data path can not be empty.',...
                         ' Please select a correct Raw data path.'},'Info');
                     checked = false;
                     return;
                 end
                 if(isempty(app.RawDataFileEditField.Value))
                     msgbox({'The Raw data file can not be empty.',...
                         ' Please select a correct Raw data file.'},'Info');
                     checked = false;
                     return;
                 end
                 if(isequal(app.RawDataFormatDropDown.Value,'--Select--'))
                     msgbox({'The File format can not be empty',...
                         ' Please select a correct File format.'},'Info');
                     checked = false;
                     return;
                 end
             end
            %% Head model tab
            if(app.Button_Spheres.Value)
                if(isequal(app.ModalityDropDown.Value,'EEG') && app.ButtonSinglesphere.Value)
                    msgbox({['The selected Headmodel method (',app.ButtonSinglesphere.Text,') does not apply to EEG'],...
                         ' Please select a correct Headmodel method.'},'Info');
                     checked = false;
                     return;
                end
                if(isequal(app.ModalityDropDown.Value,'EEG') && app.ButtonOverlappingspheres.Value)
                    msgbox({['The selected Headmodel method (',app.ButtonOverlappingspheres.Text,') does not apply to EEG'],...
                         ' Please select a correct Headmodel method.'},'Info');
                     checked = false;
                     return;
                end
                if(isequal(app.ModalityDropDown.Value,'MEG') && app.Buttons3Shellsphere.Value )
                    msgbox({['The selected Headmodel method (',app.Buttons3Shellsphere.Text,') does not apply to MEG'],...
                         ' Please select a correct Headmodel method.'},'Info');
                     checked = false;
                     return;
                end
            end
            if(app.Button_OpenMEEGBEM.Value)
                if(~app.CheckBoxScalpBEM.Value && ~app.CheckBoxSkullBEM.Value && ~app.CheckBoxBrainBEM.Value)
                    msgbox({'The BEM surfaces can not be empty',...
                        ' Please select at least two BEM Surfaces.'},'Info');
                    checked = false;
                    return;
                end
                if(isempty(app.EditFieldScalpCond.Value) || isempty(app.EditFieldSkullCond.Value) || isempty(app.EditFieldBrainCond.Value))
                     msgbox({'The BEM File conductivity can not be empty',...
                        ' Please fill a correct contuctifvity.'},'Info');
                    checked = false;
                    return;
                end
            end
            if(app.Button_DUNEuroFEM.Value)
                if(~app.CheckBoxScalpFEM.Value && ~app.CheckBoxSkullFEM.Value && ~app.CheckBoxBrainFEM.Value)
                    msgbox({'The FEM surfaces can not be empty',...
                        ' Please select at least two FEM Surfaces.'},'Info');
                    checked = false;
                    return;
                end
                if(isempty(app.EditFieldScalpCond.Value) || isempty(app.EditFieldSkullCond.Value) || isempty(app.EditFieldBrainCond.Value))
                     msgbox({'The FEM File conductivity can not be empty',...
                        ' Please fill a correct contuctifvity.'},'Info');
                    checked = false;
                    return;
                end
                if(isequal(app.DropDownFEMMethod.Value,'--Select--'))
                     msgbox({'The FEM Method can not be empty',...
                        ' Please select a correct FEM Method.'},'Info');
                    checked = false;
                    return;
                end
                if(isequal(app.DropDownFEMSourcemodel.Value,'--Select--'))
                     msgbox({'The FEM Source model can not be empty',...
                        ' Please select a correct FEM Source model.'},'Info');
                    checked = false;
                    return;
                end
            end

            %% Integration params
            if(isempty(app.PrepdatapathEditField.Value))
                msgbox({'The preprocessed data path can not be empty',...
                    ' Please select a correct preprocessed data path.'},'Info');
                checked = false;
                return;
            end
            if(isequal(app.DataEventDropDown.Value, '--Select event--'))
                msgbox({'The data file can not be empty',...
                    ' Please select a correct data file.'},'Info');
                checked = false;
                return;
            end
            if(isequal(app.DataFormatDropDown.Value, '--Select--'))
                msgbox({'The data format can not be empty',...
                    ' Please select a correct data format.'},'Info');
                checked = false;
                return;
            end

        end
        
        function load_predef_params(app)
            app.BSTDefaultAnatomyPanel.Enable       = 'on';
            app.HCPIndividualAnatomyPanel.Enable    = 'off';
            
            app.UseChanneltemplatePanel.Enable      = 'on';
            app.RawdataPanel.Enable                 = 'off';              

            %% Filling components from predefined params
            % General params
            general_params                          = app.cfs_properties.general_params;
            app.ModalityDropDown.Value              = general_params.modality;
            app.DatasetnameEditField.Value          = general_params.dataset.Name;
            app.DatasetDescriptionTextArea.Value    = general_params.dataset.Description;
            app.ProtocolnameEditField.Value         = general_params.bst_config.protocol_name;
            iProtocol = bst_get('Protocol', app.ProtocolnameEditField.Value);
            if(isempty(iProtocol))
                app.ProtocolnameEditField.FontColor = 'k';
                app.ProtocolnameEditFieldLabel.FontColor = 'k';
                app.ProtocolnameEditField.Tooltip = '';
            else
                app.ProtocolnameEditField.FontColor = 'r';
                app.ProtocolnameEditFieldLabel.FontColor = 'r';
                app.ProtocolnameEditField.Tooltip = 'The protocol name already exist';
            end

            if (general_params.bst_config.reset_protocol)
                app.ResetprotocolSwitch.Value       = 'Yes';
                app.ResetprotocolSwitch.FontColor   = 'b';
                app.ResetprotocolSwitch.FontWeight  = 'bold';
            else
                app.ResetprotocolSwitch.Value       = 'No';
                app.ResetprotocolSwitch.FontColor   = 'k';
                app.ResetprotocolSwitch.FontWeight  = 'normal';
            end
            if(cfs_check_properties('BST_path',general_params.bst_config.bst_path))
                app.BSTpathEditField.Value          = general_params.bst_config.bst_path;
            end
            if(cfs_check_properties('BST_db',general_params.bst_config.db_path))
                app.BSTdbpathEditField.Value        = general_params.bst_config.db_path;
            end
            if(cfs_check_properties('output_path',general_params.output_path))
                app.OutputpathEditField.Value       = general_params.output_path;
            end

            % Anatomy params
            anatomy_params = app.cfs_properties.anatomy_params;
            if(isequal(anatomy_params.anatomy_type.type,'default'))
                app.BSTDefaultAnatomyPanel.Enable       = 'on';                
                app.HCPIndividualAnatomyPanel.Enable    = 'off';
                app.Button_BST_Default.Value            = true;
                app.AnatomyTemplateDropDown.Items       = app.get_bst_default_anatomy();          
                app.AnatomyTemplateDropDown.Value       = anatomy_params.anatomy_type.type_list{1}.template_name;
                app.AnatomyTemplateAtlasDropDown.Items  = app.getAtlasByTemplateName(app.AnatomyTemplateDropDown.Value);
                app.AnatomyTemplateAtlasDropDown.Value  = anatomy_params.anatomy_type.type_list{1}.default_atlas;
            elseif(isequal(anatomy_params.anatomy_type.type,'template'))
            else
                app.BSTDefaultAnatomyPanel.Enable       = 'off';
                app.HCPIndividualAnatomyPanel.Enable    = 'on';
                app.Button_HCP_Individual.Value         = true;
                if(cfs_check_properties('indiv_anat_path',anatomy_params.anatomy_type.type_list{2}.base_path))
                    app.HCPIndividPathEditField.Value       = anatomy_params.anatomy_type.type_list{2}.base_path;
                    app.AnatomyIndivT1wDropDown.Items       = {anatomy_params.anatomy_type.type_list{2}.T1w_file_name};
                    app.AnatomyIndivT1wDropDown.Value       = anatomy_params.anatomy_type.type_list{2}.T1w_file_name;
                    app.AnatomyIndivAtlasDropDown.Items     = {anatomy_params.anatomy_type.type_list{2}.Atlas_file_name};
                    app.AnatomyIndivAtlasDropDown.Value     = anatomy_params.anatomy_type.type_list{2}.Atlas_file_name;
                end
            end
            if(cfs_check_properties('mri_transf_path',anatomy_params.common_params.mri_transformation.base_path,...
                    anatomy_params.common_params.mri_transformation.file_name))
                app.MRITransPathEditField.Value             = anatomy_params.common_params.mri_transformation.base_path;
                app.MRITransFileEditField.Value             = anatomy_params.common_params.mri_transformation.file_name;
            end
            if(cfs_check_properties('non_brain_path',anatomy_params.common_params.non_brain_surfaces.base_path))
                app.NonBrainPathEditField.Value             = anatomy_params.common_params.non_brain_surfaces.base_path;
            end
            for i=1:length(app.LayerDescripDropDown.Items)
                if(isequal(lower(anatomy_params.common_params.layer_desc.desc), lower(app.LayerDescripDropDown.Items{i})))
                    app.LayerDescripDropDown.Value      = app.LayerDescripDropDown.Items{i};
                end
            end
            if(anatomy_params.common_params.surfaces_resolution.nvertices > 2000 && ...
                    anatomy_params.common_params.surfaces_resolution.nvertices < 15000)
                app.SurfacesresolutionEditField.Value       = 8000;
            end

            % Channel params
            channel_params = app.cfs_properties.channel_params;
            if(isequal(channel_params.channel_type.type,1))
                app.UseChanneltemplatePanel.Enable      = 'off';                
                app.RawdataPanel.Enable                 = 'on';
                app.Button_Channel_raw_data.Value       = true;
                if(cfs_check_properties('raw_data_path',channel_params.channel_type.type_list{1}.base_path,...
                        channel_params.channel_type.type_list{1}.file_location))
                    app.RawDataBPathEditField.Value     = channel_params.channel_type.type_list{1}.base_path;
                    app.RawDataFileEditField.Value      = channel_params.channel_type.type_list{1}.file_location;
                    app.RawDataFormatDropDown.Items     = {channel_params.channel_type.type_list{1}.file_location};
                    app.RawDataFormatDropDown.Value     = channel_params.channel_type.type_list{1}.file_location;
                end
            else                
                app.UseChanneltemplatePanel.Enable      = 'on';                
                app.RawdataPanel.Enable                 = 'off';
                app.Button_Channel_template.Value       = true;
                app.ChannTemplateGroupDropDown.Items    = app.get_bst_default_LayoutG(); 
                app.ChannTemplateGroupDropDown.Value    = channel_params.channel_type.type_list{2}.group_layout_name;
                app.ChannTemplateNameDropDown.Items     = app.get_bst_default_LayoutN(app.ChannTemplateGroupDropDown.Value);
                app.ChannTemplateNameDropDown.Value     = channel_params.channel_type.type_list{2}.channel_layout_name;
                order_layout                            = app.get_bst_default_LayoutStruct(app.ChannTemplateGroupDropDown.Value,app.ChannTemplateNameDropDown.Value);
                T                                       = struct2table(order_layout);
                set(app.Channel_UITable, 'Data', T);
            end
            % Headmodel params
            headmodel_params = app.cfs_properties.headmodel_params;           
            
            switch lower(headmodel_params.Method.value)
                case 'os_meg'
                    app.Button_Spheres.Value = true;
                    app.SpheresoptionsPanel.Enable = 'on';
                    app.OpenMEEGBEMoptionsPanel.Enable = 'off';
                    app.DUNEuroFEMoptionsPanel.Enable = 'off';
                case 'openmeeg'
                    app.Button_OpenMEEGBEM.Value = true;
                    app.SpheresoptionsPanel.Enable = 'off';
                    app.OpenMEEGBEMoptionsPanel.Enable = 'on';
                    app.DUNEuroFEMoptionsPanel.Enable = 'off';
                    method = headmodel_params.Method.methods{4};
                    if(method.isAdjoint)
                        app.isAdjointSwitch.Value = 'Yes';
                        app.isAdjointSwitch.FontColor = 'b';
                    else
                        app.isAdjointSwitch.Value = 'No';
                        app.isAdjointSwitch.FontColor = 'k';
                    end
                    if(method.isAdaptative)
                        app.isAdaptativeSwitch.Value = 'Yes';
                        app.isAdaptativeSwitch.FontColor = 'b';
                    else
                        app.isAdaptativeSwitch.Value = 'No';
                        app.isAdaptativeSwitch.FontColor = 'k';
                    end
                    if(method.isSplit)
                        app.isSplitSwitch.Value = 'Yes';
                        app.isSplitSwitch.FontColor = 'b';
                    else
                        app.isSplitSwitch.Value = 'No';
                        app.isSplitSwitch.FontColor = 'k';
                    end
                    app.SplitLengthSpinner.Value = method.SplitLength;
                case 'duneuro'
                    app.Button_DUNEuroFEM.Value = true;
                    app.SpheresoptionsPanel.Enable = 'off';
                    app.OpenMEEGBEMoptionsPanel.Enable = 'off';
                    app.DUNEuroFEMoptionsPanel.Enable = 'on';
                    method = headmodel_params.Method.methods{5};
                    app.FemSelectEditField.Value = method.FemSelect;
                    app.FemCondEditField.Value = method.FemCond;
                    if(method.Isotropic)
                        app.IsotropicSwitch.Value = 'Yes';
                        app.IsotropicSwitch.FontColor = 'b';
                    else
                        app.IsotropicSwitch.Value = 'No';
                        app.IsotropicSwitch.FontColor = 'k';
                    end
                    app.DropDownFEMMethod.Value = method.FemMesh.Method.value;
                    app.MeshTypeDropDown_1.Value = method.FemMesh.MeshType.value;
                    app.MergeMethodDropDown.Value = method.FemMesh.MergeMethod.value;
                    app.VertexDensitySpinner.Value = method.FemMesh.VertexDensity.value;
                    app.DownsampleSpinner.Value = method.FemMesh.Downsample.value;
                    % if(method.FemMesh.  .value)
                    % app.UseTensorSwitch.Value = method.FemMesh.Downsample.value;
                    % else
                    % end
                    app.MaxVolSpinner_1.Value = method.FemMesh.MaxVol.value;
                    app.KeepRatioSpinner_1.Value = method.FemMesh.KeepRatio.value;
                    app.NbVerticesSpinner.Value = method.FemMesh.NbVertices.value;
                    app.NodeShiftSpinner.Value = method.FemMesh.NodeShift.value;
            end            
        end
        
        function defaultANames = get_bst_default_anatomy(app)
            % loading anatomical templates
            defaults = jsondecode(fileread(fullfile('bst_defaults','bst_default_anatomy.json')));
            defaultANames = {defaults.Name};
            defaultANames{end+1} = '--Select a template--';
        end
        
        function defaultNames = get_bst_default_LayoutG(app)
            defaults = jsondecode(fileread(fullfile('bst_defaults','bst_eeg_layouts.json')));
            defaultNames = {defaults.name};
            app.ChannTemplateGroupDropDown.Items{end+1} = '--Select--';
            app.ChannTemplateGroupDropDown.Value = '--Select--';
            app.ChannTemplateNameDropDown.Items = {'--Select a template--'};
            app.ChannTemplateNameDropDown.Value = '--Select a template--';
        end
        
        function AtlasName = getAtlasByTemplateName(app,TemplateName)
            AtlasName = [];
            default_anat = cfs_get('defaults_dir');
            template_file = fullfile(default_anat,[TemplateName,'.zip']); 

            % On CFS dir
            if(isfile(template_file))
                d = uiprogressdlg(app.CiftiStormUIFigure,'Title','Extracting Atlas from template.');
                extractFile(template_file, default_anat, 'tess_cortex_pial_low.mat');
                cortex = load(fullfile(default_anat,'tess_cortex_pial_low.mat'));
                delete(fullfile(default_anat,'tess_cortex_pial_low.mat'));
                AtlasName = {cortex.Atlas.Name};
                delete(d);
            end

            % On BST db dir
            if(isempty(AtlasName))
                d = uiprogressdlg(app.CiftiStormUIFigure,'Title','Extracting Atlas from BST db.');
                userDir = getUserDir();
                template_anat = fullfile(userDir,".brainstorm","defaults","anatomy",[TemplateName,'.zip']);
                if(isfile(template_anat))
                    copyfile(template_anat,default_anat)
                    extractFile(template_file, default_anat, 'tess_cortex_pial_low.mat');
                    cortex = load(fullfile(default_anat,'tess_cortex_pial_low.mat'));
                    delete(fullfile(default_anat,'tess_cortex_pial_low.mat'));
                    AtlasName = {cortex.Atlas.Name};
                end
                delete(d);
            end

            % On BST online address
            if(isempty(AtlasName))
                d = uiprogressdlg(app.CiftiStormUIFigure,'Title','Downloading template from BST repository.');
                defaults = jsondecode(fileread(fullfile('bst_defaults','bst_default_anatomy.json')));
                ind = find(ismember({defaults.Name},TemplateName),1);
                url = defaults(ind).FilePath;
                matlab.net.http.HTTPOptions.VerifyServerName = false;
                options = weboptions('Timeout',Inf,'RequestMethod','auto');
                websave(template_file,url,options);
                delete(d);
                d = uiprogressdlg(app.CiftiStormUIFigure,'Title','Extracting template from file.');
                extractFile(template_file, default_anat, 'tess_cortex_pial_low.mat');                
                cortex = load(fullfile(default_anat,'tess_cortex_pial_low.mat'));
                delete(fullfile(default_anat,'tess_cortex_pial_low.mat'));
                AtlasName = {cortex.Atlas.Name};
                delete(d);
            end  
            AtlasName{end+1} = '--Select--';
        end
        
        function defaultNames = get_bst_default_LayoutN(app,groupName)
            defaults = jsondecode(fileread(fullfile('bst_defaults','bst_eeg_layouts.json')));
            defaultNames = defaults(find(ismember({defaults.name},groupName),1)).contents;
            defaultNames = {defaultNames.name};
        end
        
        function order_layout = get_bst_default_LayoutStruct(app,group,name)
            name = strrep(name,' ','_');
            layout = [];
            [~, default_eeg] = cfs_get('defaults_dir');
            file_name = ['channel_',name,'.mat'];
            default_group = fullfile(default_eeg,group);
            layout_file = fullfile(default_group,file_name);
            if(isfile(layout_file))
                layout = load(layout_file);
            end
            if(~isempty(app.BSTpathEditField.Value) && isempty(layout))
                BST_path = app.BSTpathEditField.Value;
                copyfile(fullfile(BST_path,'defaults','eeg',group,file_name),default_group)
                layout = load(layout_file);
            end
            if(isempty(layout))
                bst_online = cfs_get('bst_default_eeg');
                online_file = strcat(bst_online,'/',group,'/',file_name,'?download=1');
                matlab.net.http.HTTPOptions.VerifyServerName = false;
                options = weboptions('Timeout',Inf,'RequestMethod','auto');
                websave(layout_file,online_file,options);
                layout = load(layout_file);
            end
            for i=1:length(layout.Channel)
                loc = layout.Channel(i).Loc;
                Weight = layout.Channel(i).Weight;
                fields(i).Locs = strcat(num2str(loc(1)),',',num2str(loc(2)),',',num2str(loc(3)));
            end
            order_layout.No         = cellstr(string(1:length(layout.Channel)))';
            order_layout.Name       = {layout.Channel.Name}';
            order_layout.Type       = {layout.Channel.Type}';
            order_layout.Position   = {fields.Locs}';
            order_layout.Comment    = {layout.Channel.Comment}';
        end
        
        function update_property_files(app)
            %% General tab
            general_params                      = app.cfs_properties.general_params;
            general_params.modality             = app.ModalityDropDown.Value;
            general_params.dataset.Name         = app.DatasetnameEditField.Value;
            general_params.dataset.Description  = app.ProtocolnameEditField.Value;
            general_params.bst_config.bst_path  = app.BSTpathEditField.Value;
            general_params.bst_config.db_path   = app.BSTdbpathEditField.Value;
            general_params.output_path          = app.OutputpathEditField.Value;
            app.cfs_properties.general_params   = general_params;            

            %% Import anatomy tab
            anatomy_params = app.cfs_properties.anatomy_params;
            if(app.Button_BST_Default.Value)
                anatomy_params.anatomy_type.type = "default";
                anatomy_params.anatomy_type.type_list{1}.template_name = app.AnatomyTemplateDropDown.Value;
                anatomy_params.anatomy_type.type_list{1}.default_atlas = app.AnatomyTemplateAtlasDropDown.Value;
            end
            if(app.Button_HCP_Individual.Value)
                anatomy_params.anatomy_type.type = "individual";
                anatomy_params.anatomy_type.type_list{2}.base_path = app.HCPIndividPathEditField.Value;
                anatomy_params.anatomy_type.type_list{2}.T1w_file_name = app.AnatomyIndivT1wDropDown.Value;
                anatomy_params.anatomy_type.type_list{2}.Atlas_file_name = app.AnatomyIndivAtlasDropDown.Value;
            end
            anatomy_params.common_params.layer_desc.desc = app.LayerDescripDropDown.Value;
            anatomy_params.common_params.non_brain_surfaces.base_path = app.NonBrainPathEditField.Value;
            anatomy_params.common_params.surfaces_resolution.nvertices = app.SurfacesresolutionEditField.Value;
            anatomy_params.common_params.mri_transformation.use_transformation = app.UseMRITransCheckBox.Value;
            if(app.UseMRITransCheckBox.Value)
                anatomy_params.common_params.mri_transformation.base_path = app.MRITransPathEditField.Value;
                anatomy_params.common_params.mri_transformation.file_name = app.MRITransFileEditField.Value;
            end
            app.cfs_properties.anatomy_params = anatomy_params;

            %% Import channel tab
            channel_params = app.cfs_properties.channel_params;
            if(app.Button_Channel_template.Value)
                channel_params.channel_type.type = "default";
                channel_params.channel_type.type_list{2}.group_layout_name = app.ChannTemplateGroupDropDown.Value;
                channel_params.channel_type.type_list{2}.channel_layout_name = app.ChannTemplateNameDropDown.Value;
            end
            if(app.Button_Channel_raw_data.Value)
                channel_params.channel_type.type = "raw";
                channel_params.channel_type.type_list{1}.base_path = app.RawDataBPathEditField.Value;
                channel_params.channel_type.type_list{1}.file_location = app.RawDataFileEditField.Value;
                channel_params.channel_type.type_list{1}.data_format = app.RawDataFormatDropDown.Value;
            end
            app.cfs_properties.channel_params = channel_params;

            %% Head model tab
            headmodel_params                                    = app.cfs_properties.headmodel_params;
            if(app.Button_Spheres.Value)
                if(app.ButtonSinglesphere.Value)
                    headmodel_params.Method.value               = 'meg_sphere';
                end
                if(app.ButtonOverlappingspheres.Value)
                    headmodel_params.Method.value               = 'os_meg';
                end
                if(app.Buttons3Shellsphere.Value)
                    headmodel_params.Method.value               = 'eeg_3sphereberg';
                end                
            end
            if(app.Button_OpenMEEGBEM.Value)
                headmodel_params.Method.value = 'openmeeg';
                headmodel_params.method_type{4}.BemSelect       = [app.CheckBoxScalpBEM.Value,app.CheckBoxSkullBEM.Value,app.CheckBoxBrainBEM.Value];
                headmodel_params.method_type{4}.BemNames        = {"Scalp","Skull","Brain"};
                headmodel_params.method_type{4}.BemCond         = [app.EditFieldScalpCond.Value,app.EditFieldSkullCond.Value,app.EditFieldBrainCond.Value];
                headmodel_params.method_type{4}.isAdjoint       = isequal(app.isAdjointSwitch.Value,'Yes');
                headmodel_params.method_type{4}.isAdaptative    = isequal(app.isAdaptativeSwitch.Value,'Yes');
                headmodel_params.method_type{4}.isSplit         = isequal(app.isSplitSwitch.Value,'Yes');
                headmodel_params.method_type{4}.SplitLength     = app.SplitLengthSpinner.Value;
            end
            if(app.Button_DUNEuroFEM.Value)
                headmodel_params.Method.value = 'duneuro';
                headmodel_params.method_type{5}.FemSelect = [app.CheckBoxScalpFEM.Value,app.CheckBoxSkullFEM.Value,app.CheckBoxBrainFEM.Value];

                headmodel_params.method_type{5}.BemCond   = [app.EditFieldScalpFEM.Value,app.EditFieldSkullFEM.Value,app.EditFieldBrainFEM.Value];

            end
            app.cfs_properties.headmodel_params = headmodel_params;

            %% Process integration
            integration_params                              = app.cfs_properties.integration_params;
            integration_params.preproc_data.base_path       = app.PrepdatapathEditField.Value;
            integration_params.preproc_data.event_file      = app.DataEventDropDown.Value;
            integration_params.preproc_data.format          = app.DataFormatDropDown.Value;

            app.cfs_properties.integration_params = integration_params;

            % saving property files
            saveJSON(app.cfs_properties.general_params,'cfs_properties/general_params.json');
            h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/general_params.json'));
            h.smartIndentContents
            h.save
            h.close
            saveJSON(app.cfs_properties.anatomy_params,'cfs_properties/process_import_anat.json');
            h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/process_import_anat.json'));
            h.smartIndentContents
            h.save
            h.close
            saveJSON(app.cfs_properties.channel_params,'cfs_properties/process_import_channel.json');
            h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/process_import_channel.json'));
            h.smartIndentContents
            h.save
            h.close
            saveJSON(app.cfs_properties.headmodel_params,'cfs_properties/process_comp_headmodel.json');
            h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/process_comp_headmodel.json'));
            h.smartIndentContents
            h.save
            h.close
            saveJSON(app.cfs_properties.process_integration,'cfs_properties/process_integration.json');
            h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/process_integration.json'));
            h.smartIndentContents
            h.save
            h.close
        end        
        
        
        function response = ShowParameters(app)
            response = true;
            general_params = app.cfs_properties.general_params;
            
            anatomy_params = app.cfs_properties.anatomy_params;

            channel_params = app.cfs_properties.channel_params;

            headmodel_params = app.cfs_properties.headmodel_params;

            integration_params = app.cfs_properties.integration_params;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Dataset Configuration';
            movegui(app.UIFigure,"center");
            addpath('app');
            addpath('bst_defaults');
            addpath('external');
            addpath(genpath('functions'));
            addpath('external');
            app.cfs_dir = cfs_get('cfs_dir');
            app.cfs_properties = get_properties();
            % load prodefinition parameters
            load_predef_params(app);
        end

        % Button pushed function: SelectBSTButton
        function SelectBSTButtonPushed(app, event)
            try
                folder = uigetdir("Select the Brinstorm path");
                if(isfile(fullfile(folder,'brainstorm.m')))
                    app.BSTpathEditField.Value = folder;
                else
                    msgbox({'The selected folder do not contain the BrainStorm toolbox.',...
                        folder,...
                        ' Please select a correct Brainstorm path.'},'Info');
                    app.BSTpathEditField.Value = '';
                end
            catch
            end
            figure(app.UIFigure);
        end

        % Button pushed function: SelectBST_dbButton
        function SelectBST_dbButtonPushed(app, event)
            try
                folder = uigetdir("Select the BST db folder");
                [~,values] = fileattrib(folder);
                if(values.UserWrite)
                    app.BSTdbpathEditField.Value = folder;
                else
                    app.BSTdbpathEditField.Value = 'local';
                    msgbox({'The current user do not have write permissions on the selected forder.',...
                        ' Please check the folder permission or select another output folder.'},'Info');
                end
            catch
                app.BSTdbpathEditField.Value = 'local';
            end
            figure(app.UIFigure);
        end

        % Button pushed function: SelectOutputPathButton
        function SelectOutputPathButtonPushed(app, event)
            try
                folder = uigetdir("Select the Output folder");
                [~,values] = fileattrib(folder);
                if(values.UserWrite)
                    app.OutputpathEditField.Value = folder;
                else
                    msgbox({'The current user do not have write permissions on the selected forder.',...
                        ' Please check the folder permission or select another output folder.'},'Info');
                end
            catch
            end
            figure(app.UIFigure);
        end

        % Selection changed function: SelectAnatomyoptionButtonGroup
        function SelectAnatomyoptionButtonGroupSelectionChanged(app, event)
            if(app.Button_BST_Default.Value)
                app.BSTDefaultAnatomyPanel.Enable = 'on';
                app.HCPIndividualAnatomyPanel.Enable = 'off';
                app.AnatomyTemplateDropDown.Items       = app.get_bst_default_anatomy();          
                app.AnatomyTemplateDropDown.Value       = '--Select a template--';
                app.AnatomyTemplateAtlasDropDown.Value  = '--Select--';
            end            
            if(app.Button_HCP_Individual.Value)
                app.BSTDefaultAnatomyPanel.Enable = 'off';
                app.HCPIndividualAnatomyPanel.Enable = 'on';
            end
        end

        % Value changed function: ChannTemplateGroupDropDown
        function ChannTemplateGroupDropDownValueChanged(app, event)
            group_name = app.ChannTemplateGroupDropDown.Value;
            if(isequal(app.ModalityDropDown.Value,'--Select--'))
                msgbox('Please select the modality first in Generals Tab.','Info');
                app.ChannTemplateGroupDropDown.Value = '--Select--';
                return;
            end
            if(isempty(app.BSTpathEditField.Value))
                msgbox('Please select the Brainstorm path in Generals Tab.','Info');
                app.ChannTemplateGroupDropDown.Value = '--Select--';
                return;
            end
            if(~isequal(group_name,'--Select--'))                
                app.ChannTemplateNameDropDown.Items = app.get_bst_default_LayoutN(group_name);
                app.ChannTemplateNameDropDown.Items{end+1} = '--Select a template--';
                app.ChannTemplateNameDropDown.Value = '--Select a template--';
            else
                app.ChannTemplateNameDropDown.Items = {'--Select a template--'};
                app.ChannTemplateNameDropDown.Value = '--Select a template--';
                set(app.Channel_UITable, 'Data', []);
            end
        end

        % Selection changed function: SelectChanneloptionButtonGroup
        function SelectChanneloptionButtonGroupSelectionChanged(app, event)
            if(app.Button_Channel_template.Value)
                app.UseChanneltemplatePanel.Enable = 'on';
                app.RawdataPanel.Enable = 'off';
            end
            if(app.Button_Channel_raw_data.Value)
                app.UseChanneltemplatePanel.Enable = 'off';
                app.RawdataPanel.Enable = 'on';
            end
        end

        % Value changed function: ChannTemplateNameDropDown
        function ChannTemplateNameDropDownValueChanged(app, event)
            template_name = event.Value;            
            if(~isequal(template_name,'--Select a template--'))                
                group           = app.ChannTemplateGroupDropDown.Value;
                order_layout    = app.get_bst_default_LayoutStruct(group,template_name);
                T               = struct2table(order_layout);
                set(app.Channel_UITable, 'Data', T);
            else
                set(app.Channel_UITable, 'Data', []);
            end
            
        end

        % Value changed function: ModalityDropDown
        function ModalityDropDownValueChanged(app, event)
            modality = app.ModalityDropDown.Value;
            if(isequal(modality,'EEG'))
                app.Button_Channel_template.Value = true;
                app.UseChanneltemplatePanel.Enable = 'on';
                app.RawdataPanel.Enable = 'off';
            else
                app.Button_Channel_raw_data.Value = true;
                app.UseChanneltemplatePanel.Enable = 'off';
                app.RawdataPanel.Enable = 'on';
            end
        end

        % Button pushed function: SelectRawDataBPathButton
        function SelectRawDataBPathButtonPushed(app, event)
            try
                folder = uigetdir("Select the Raw data base path");
                folder = strrep(folder,'\','/');
                app.RawDataBPathEditField.Value = folder;
            catch
            end
            figure(app.UIFigure);
        end

        % Button pushed function: SelectHCPIndividPathButton
        function SelectHCPIndividPathButtonPushed(app, event)
            try
                folder = uigetdir("Select the Anatomy basepath");
                structures = dir(folder);
                structures(ismember( {structures.name}, {'.', '..'})) = [];  %remove . and ..
                count = 0;
                for i=1:length(structures)
                    structure = structures(i);
                    if(~isfile(fullfile(structure.folder,structure.name,'T1w','T1w.nii.gz'))...
                            && ~isfile(fullfile(structure.folder,structure.name,'T1w','T1w_acpc_dc_restore.nii.gz'))...
                            && ~isfile(fullfile(structure.folder,structure.name,'T1w','T1w_acpc_dc.nii.gz'))...
                            && ~isfile(fullfile(structure.folder,structure.name,'T1w','T2w.nii.gz'))...
                            && ~isfile(fullfile(structure.folder,structure.name,'T1w','T2w_acpc_dc_restore.nii.gz'))...
                            && ~isfile(fullfile(structure.folder,structure.name,'T1w','T2w_acpc_dc.nii.gz')))
                        count = count + 1;
                    else
                        cStructure = structure;
                    end
                end
                if(isequal(count,length(structures)))
                    msgbox({'The selected folder do not contain any Ciftify structure',...
                        'Please select a correct anatomy folder.'},'Info');
                    return;
                end
                if(count > 0)
                    msgbox({'One or more subject forders are not a HCP structure.',...
                        'Please check the Anatomy folder.'},'Info');                    
                end
                app.HCPIndividPathEditField.Value = folder;

                filesList = dir(fullfile(cStructure.folder,cStructure.name,"T1w"));
                opt_T1w_files = regexpi({filesList.name}, '(T1w|T2w)[a-z-_]*\.nii\.gz', 'match');
                opt_T1w_files(cellfun(@isempty,opt_T1w_files))=[];
                app.AnatomyIndivT1wDropDown.Items = [opt_T1w_files{:}];
                app.AnatomyIndivT1wDropDown.Items{end+1} = '--Select the T1w file--';
                app.AnatomyIndivT1wDropDown.Value = '--Select the T1w file--';

                opt_Atlas_files = regexpi({filesList.name}, '(wmparc.nii.gz)|((aparc)[\w\+\.]*\+aseg\.nii\.gz)', 'match');
                opt_Atlas_files(cellfun(@isempty,opt_Atlas_files))=[];
                app.AnatomyIndivAtlasDropDown.Items = [opt_Atlas_files{:}];
                app.AnatomyIndivAtlasDropDown.Items{end+1} = '--Select the Atlas file--';
                app.AnatomyIndivAtlasDropDown.Value = '--Select the Atlas file--';
            catch
            end
            figure(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Selection changed function: MethodsButtonGroup
        function MethodsButtonGroupSelectionChanged(app, event)
            if(app.Button_Spheres.Value)
                app.SpheresoptionsPanel.Enable = 'on';
                app.OpenMEEGBEMoptionsPanel.Enable = 'off';
                app.DUNEuroFEMoptionsPanel.Enable = 'off';
            end
            if(app.Button_OpenMEEGBEM.Value)
                app.SpheresoptionsPanel.Enable = 'off';
                app.OpenMEEGBEMoptionsPanel.Enable = 'on';
                app.DUNEuroFEMoptionsPanel.Enable = 'off';
            end
            if(app.Button_DUNEuroFEM.Value)
                app.SpheresoptionsPanel.Enable = 'off';
                app.OpenMEEGBEMoptionsPanel.Enable = 'off';
                app.DUNEuroFEMoptionsPanel.Enable = 'on';
            end
        end

        % Button pushed function: SelectRawDataFPathButton
        function SelectRawDataFPathButtonPushed(app, event)
            try
                if(isempty(app.RawDataBPathEditField.Value))
                    msgbox('Select first the base path of the raw data.','Info');
                    return;
                end
                root_path = app.RawDataBPathEditField.Value;
                [file_name,file_path] = uigetfile({'*.mat';'*.mff';'*.edf';'*.*'},'Select a reference file to import',root_path);
                file_path = strrep(file_path,'\','/');
                base_path = app.RawDataBPathEditField.Value;
                file_parts = split(file_path,base_path);
                if(isequal(length(file_parts),1))
                    msgbox({'The base path and file path do not match in the first part.', ...
                        'Please check the configuration.'},'Info');                    
                    return;
                end
                SubID_parts = split(file_parts{2},'/');
                SubID = SubID_parts{2};
                SubID_parts(1:2) = [];
                ref_path = fullfile(SubID_parts{:},file_name);                
                ref_path = strrep(ref_path,SubID,'SubID');
                app.RawDataFileEditField.Value = ref_path;
                 [~,~,ext] = fileparts(app.RawDataFileEditField.Value);
                 if(isempty(ext) && isequal(file_name,'c,rfDC'))
                    ext = '4D';
                 end
                 app.RawDataFormatDropDown.Value = strcat('.',ext);
            catch
                
            end
            figure(app.UIFigure);
        end

        % Button pushed function: SelectNonBrainPathButton
        function SelectNonBrainPathButtonPushed(app, event)
            try
                folder = uigetdir("Select the Template basepath");
                structures = dir(folder);
                structures(ismember( {structures.name}, {'.', '..'})) = [];  %remove . and ..
                count = 0;
                for i=1:length(structures)
                    structure = structures(i);
                    if(~isfile(fullfile(structure.folder,structure.name,[structure.name,'_outskin_mesh.nii.gz']))...
                            || ~isfile(fullfile(structure.folder,structure.name,[structure.name,'_outskull_mesh.nii.gz']))...
                            || ~isfile(fullfile(structure.folder,structure.name,[structure.name,'_inskull_mesh.nii.gz'])))
                        count = count + 1;
                    end
                end
                 if(isequal(count,length(structures)))
                    msgbox({'The selected folder do not contain any FSL Bet structure',...
                        'Please select a correct anatomy folder.'},'Info');
                    return;
                end
                if(count > 0)
                    msgbox({'One or more subject forders are not a FSL Bet structure.',...
                        'Please check the FSL non-brain surfaces folder.'},'Info');                    
                end
                app.NonBrainPathEditField.Value = folder;
            catch
            end
            figure(app.UIFigure);
        end

        % Button pushed function: ButtonBack
        function ButtonBackPushed(app, event)
            if(isequal(app.TabGroup.SelectedTab.Title,'Import Anatomy'))
                app.TabGroup.SelectedTab = app.GeneralsTab;
                return;
            end
            if(isequal(app.TabGroup.SelectedTab.Title,'Import Channel'))
                app.TabGroup.SelectedTab = app.ImportAnatomyTab;
                return;
            end
            if(isequal(app.TabGroup.SelectedTab.Title,'Compute Headmodel'))
                app.TabGroup.SelectedTab = app.ImportChannelTab;
                return;
            end
            if(isequal(app.TabGroup.SelectedTab.Title,'Fuctional Integration'))
                app.TabGroup.SelectedTab = app.ComputeHeadmodelTab;
                return;
            end
        end

        % Button pushed function: ButtonNext
        function ButtonNextPushed(app, event)
            if(isequal(app.TabGroup.SelectedTab.Title,'Generals'))
                app.TabGroup.SelectedTab = app.ImportAnatomyTab;
                return;
            end
            if(isequal(app.TabGroup.SelectedTab.Title,'Import Anatomy'))
                app.TabGroup.SelectedTab = app.ImportChannelTab;
                return;
            end
            if(isequal(app.TabGroup.SelectedTab.Title,'Import Channel'))
                app.TabGroup.SelectedTab = app.ComputeHeadmodelTab;
                return;
            end
            if(isequal(app.TabGroup.SelectedTab.Title,'Compute Headmodel'))
                app.TabGroup.SelectedTab = app.FunctionalIntegrationTab;
                return;
            end
        end

        % Value changed function: AnatomyTemplateDropDown
        function AnatomyTemplateDropDownValueChanged(app, event)
            value = app.AnatomyTemplateDropDown.Value;     
            app.AnatomyTemplateAtlasDropDown.Items = {'Please wait...','--Select--'};
            app.AnatomyTemplateAtlasDropDown.Value = 'Please wait...';
            app.AnatomyTemplateAtlasDropDown.Enable = false;
            if(isequal(value,"--Select a template--"))
                msgbox('Select a correct Anatomy template.','Info');
                return;
            end            
            AtlasName = app.getAtlasByTemplateName(value);
            app.AnatomyTemplateAtlasDropDown.Items = AtlasName;
            app.AnatomyTemplateAtlasDropDown.Value = '--Select--';
            app.AnatomyTemplateAtlasDropDown.Enable = true;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            checked = app.check_visual_params();
            if(checked)
                response = app.ShowParameters();
                if(response)
                    app.update_property_files();
                    delete(app);
                end
            end              
        end

        % Button pushed function: SelectMRITransFileButton
        function SelectMRITransFileButtonPushed(app, event)
            try
                if(isempty(app.MRITransPathEditField.Value))
                    msgbox('Select first the MRI transformation base path.','Info');
                    return;
                end
                root_path = app.MRITransPathEditField.Value;
                [file_name,file_path] = uigetfile({'*.txt';'*.*'},'Select a reference file to import',root_path);
                file_path = strrep(file_path,'\','/');
                base_path = app.MRITransPathEditField.Value;
                file_parts = split(file_path,base_path);
                if(isequal(length(file_parts),1))
                    msgbox({'The base path and file path do not match in the first part.', ...
                        'Please check the configuration.'},'Info');                    
                    return;
                end
                SubID_parts = split(file_parts{2},'/');
                SubID = SubID_parts{2};
                SubID_parts(1:2) = [];
                ref_path = fullfile(SubID_parts{:},file_name);                
                ref_path = strrep(ref_path,SubID,'SubID');
                app.MRITransFileEditField.Value = ref_path;
            catch
                
            end
            figure(app.UIFigure);
        end

        % Button pushed function: SelectMRITransPathButton
        function SelectMRITransPathButtonPushed(app, event)
            try
                folder = uigetdir("Select the Raw data base path");
                folder = strrep(folder,'\','/');
                app.MRITransPathEditField.Value = folder;
            catch
            end
            figure(app.UIFigure);
        end

        % Value changed function: ResetprotocolSwitch
        function ResetprotocolSwitchValueChanged(app, event)
            value = app.ResetprotocolSwitch.Value;
            if(isequal(value,'Yes'))
                app.ResetprotocolSwitch.FontColor = 'b';
                app.ResetprotocolSwitch.FontWeight = 'bold';
            else
                app.ResetprotocolSwitch.FontColor = 'k';
                app.ResetprotocolSwitch.FontWeight = 'normal';
            end
        end

        % Value changed function: isAdjointSwitch
        function isAdjointSwitchValueChanged(app, event)
            value = app.isAdjointSwitch.Value;
            if(isequal(value,'Yes'))
                app.isAdjointSwitch.FontColor = 'b';
                app.isAdjointSwitch.FontWeight = 'bold';
            else
                app.isAdjointSwitch.FontColor = 'k';
                app.isAdjointSwitch.FontWeight = 'normal';
            end
        end

        % Value changed function: isAdaptativeSwitch
        function isAdaptativeSwitchValueChanged(app, event)
            value = app.isAdaptativeSwitch.Value;
            if(isequal(value,'Yes'))
                app.isAdaptativeSwitch.FontColor = 'b';
                app.isAdaptativeSwitch.FontWeight = 'bold';
            else
                app.isAdaptativeSwitch.FontColor = 'k';
                app.isAdaptativeSwitch.FontWeight = 'normal';
            end
        end

        % Value changed function: isSplitSwitch
        function isSplitSwitchValueChanged(app, event)
            value = app.isSplitSwitch.Value;
            if(isequal(value,'Yes'))
                app.isSplitSwitch.FontColor = 'b';
                app.isSplitSwitch.FontWeight = 'bold';
            else
                app.isSplitSwitch.FontColor = 'k';
                app.isSplitSwitch.FontWeight = 'normal';
            end
        end

        % Callback function
        function IsotropicSwitchValueChanged(app, event)
            value = app.IsotropicSwitch.Value;
            if(isequal(value,'Yes'))
                app.IsotropicSwitch.FontColor = 'b';
                app.IsotropicSwitch.FontWeight = 'bold';
            else
                app.IsotropicSwitch.FontColor = 'k';
                app.IsotropicSwitch.FontWeight = 'normal';
            end
        end

        % Value changed function: UseMRITransCheckBox
        function UseMRITransCheckBoxValueChanged(app, event)
            value = app.UseMRITransCheckBox.Value;
            if(value)
                app.MRITransPathEditField.Enable = true;
                app.SelectMRITransPathButton.Enable = true;
                app.MRITransFileEditField.Enable = true;
                app.SelectMRITransFileButton.Enable = true;
            else
                app.MRITransPathEditField.Enable = false;
                app.SelectMRITransPathButton.Enable = false;
                app.MRITransFileEditField.Enable = false;
                app.SelectMRITransFileButton.Enable = false;
            end
        end

        % Value changed function: DropDownFEMMethod
        function DropDownFEMMethodValueChanged(app, event)
            value = app.DropDownFEMMethod.Value;
            if(isequal(value,'iso2mesh-2021') || isequal(value,'iso2mesh'))
                app.FEMoptsPanel.Visible = "on";
                % iso2mesh-2021 & iso2mesh
                app.Paneliso2mesh.Visible = 'on';
                % simnibs3
                app.Panelsimnibs3.Visible = 'off';
                % simnibs4
                app.Panelsimnibs4.Visible = 'off';
                % fieldtrip
                app.Panelfieldtrip.Visible = 'off';
            end
            if(isequal(value,'brain2mesh'))
                app.FEMoptsPanel.Visible = "off";   
            end
            if(isequal(value,'simnibs3'))
                app.FEMoptsPanel.Visible = "on";
                % iso2mesh-2021 & iso2mesh
                app.Paneliso2mesh.Visible = 'off';
                % simnibs3
                app.Panelsimnibs3.Visible = 'on';
                % simnibs4
                app.Panelsimnibs4.Visible = 'off';
                % fieldtrip
                app.Panelfieldtrip.Visible = 'off';
            end
            if(isequal(value,'simnibs4'))
                app.FEMoptsPanel.Visible = "on";
                % iso2mesh-2021 & iso2mesh
                app.Paneliso2mesh.Visible = 'off';
                % simnibs3
                app.Panelsimnibs3.Visible = 'off';
                % simnibs4
                app.Panelsimnibs4.Visible = 'on';
                % fieldtrip
                app.Panelfieldtrip.Visible = 'off';
            end
            if(isequal(value,'fieldtrip'))
                app.FEMoptsPanel.Visible = "on";
                % iso2mesh-2021 & iso2mesh
                app.Paneliso2mesh.Visible = 'off';
                % simnibs3
                app.Panelsimnibs3.Visible = 'off';
                % simnibs4
                app.Panelsimnibs4.Visible = 'off';
                % fieldtrip
                app.Panelfieldtrip.Visible = 'on';             
            end
            
        end

        % Value changed function: DropDownFEMSourcemodel
        function DropDownFEMSourcemodelValueChanged(app, event)
            value = app.DropDownFEMSourcemodel.Value;
            if(isequal(value,'Venant'))
                app.PanelVenant.Visible = 'on';
                app.PanelSubtraction.Visible = 'off';
            end
            if(isequal(value,'Subtraction'))
                app.PanelVenant.Visible = 'off';
                app.PanelSubtraction.Visible = 'on';
            end
            if(isequal(value,'Partial integration') || isequal(value,'--Select--'))
                app.PanelVenant.Visible = 'off';
                app.PanelSubtraction.Visible = 'off';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);            
        end

        % Value changing function: ProtocolnameEditField
        function ProtocolnameEditFieldValueChanging(app, event)
            changingValue = event.Value;
            iProtocol = bst_get('Protocol', changingValue);
            if(isempty(iProtocol))
                app.ProtocolnameEditField.FontColor = 'k';
                app.ProtocolnameEditFieldLabel.FontColor = 'k';
                app.ProtocolnameEditField.Tooltip = '';
            else
                app.ProtocolnameEditField.FontColor = 'r';
                app.ProtocolnameEditFieldLabel.FontColor = 'r';
                app.ProtocolnameEditField.Tooltip = 'The protocol name already exist';
            end
        end

        % Button pushed function: SelectPrepdatapathButton
        function SelectPrepdatapathButtonPushed(app, event)
            % try
                folder = uigetdir("Select the Preprocessed data path");
                structures = dir(folder);
                structures(ismember( {structures.name}, {'.', '..'})) = [];  %remove . and ..
                count = 0;
                for i=1:length(structures)
                    structure = structures(i);
                    files = dir(fullfile(structure.folder,structure.name,"*preproc_eeg.set"));
                    if(isempty(files))
                        count = count + 1;
                    else
                        cStructure = structure;
                    end
                end
                if(isequal(count,length(structures)))
                    msgbox({'The selected folder do not contain any Preprocessed data',...
                        'Please select a correct preprocessed data folder.'},'Info');
                    return;
                end
                if(count > 0)
                    msgbox({'One or more subject forders do not contain a processed data file.',...
                        'Please check the Preprocessed data folder.'},'Info');
                end
                app.PrepdatapathEditField.Value = folder;

                filesList = dir(fullfile(folder,"*","*preproc_eeg.set"));
                opt_files = regexpi({filesList.name}, '[\w\W\-_]*(preproc\_eeg\.set)', 'match');
                opt_files(cellfun(@isempty,opt_files))=[];
                
                for j=1:length(opt_files)
                   file = opt_files{j};
                   descriptors = split(file{1},'_');
                   task = split(descriptors{find(contains(descriptors,'task'),1)},'-');
                   events(j) = task(2); 
                end
                events = unique(events);
                app.DataEventDropDown.Items = [events];
                app.DataEventDropDown.Items{end+1} = 'All';
                app.DataEventDropDown.Items{end+1} = '--Select event--';
                app.DataEventDropDown.Value = '--Select event--';
            % catch
            % end
            figure(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SetparametersPanel = uipanel(app.UIFigure);
            app.SetparametersPanel.Title = 'Set parameters';
            app.TabGroup = uitabgroup(app.SetparametersPanel);
            app.GeneralsTab = uitab(app.TabGroup);
            app.GeneralsTab.Title = 'Generals';
            app.GeneralsparamsPanel = uipanel(app.GeneralsTab);
            app.GeneralsparamsPanel.Title = 'Generals params';
            app.ModalityDropDownLabel = uilabel(app.GeneralsparamsPanel);
            app.ModalityDropDownLabel.Text = 'Modality:';
            app.ModalityDropDown = uidropdown(app.GeneralsparamsPanel);
            app.ModalityDropDown.Items = {'--Select--', 'EEG', 'MEG'};
            app.ModalityDropDown.ValueChangedFcn = createCallbackFcn(app, @ModalityDropDownValueChanged, true);
            app.ModalityDropDown.Value = '--Select--';
            app.ProtocolnameEditFieldLabel = uilabel(app.GeneralsparamsPanel);
            app.ProtocolnameEditFieldLabel.Text = 'Protocol name:';
            app.ProtocolnameEditField = uieditfield(app.GeneralsparamsPanel, 'text');
            app.ProtocolnameEditField.ValueChangingFcn = createCallbackFcn(app, @ProtocolnameEditFieldValueChanging, true);
            app.SelectOutputPathButton = uibutton(app.GeneralsparamsPanel, 'push');
            app.SelectOutputPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputPathButtonPushed, true);
            app.SelectOutputPathButton.Text = 'Select';
            app.OutputpathEditFieldLabel = uilabel(app.GeneralsparamsPanel);
            app.OutputpathEditFieldLabel.Text = 'Output path:';
            app.OutputpathEditField = uieditfield(app.GeneralsparamsPanel, 'text');
            app.OutputpathEditField.Editable = 'off';
            app.SelectBSTButton = uibutton(app.GeneralsparamsPanel, 'push');
            app.SelectBSTButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBSTButtonPushed, true);
            app.SelectBSTButton.Text = 'Select';
            app.SelectBST_dbButton = uibutton(app.GeneralsparamsPanel, 'push');
            app.SelectBST_dbButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBST_dbButtonPushed, true);
            app.SelectBST_dbButton.Text = 'Select';
            app.BraninstormpathEditFieldLabel = uilabel(app.GeneralsparamsPanel);
            app.BraninstormpathEditFieldLabel.Text = 'Braninstorm path:';
            app.BSTpathEditField = uieditfield(app.GeneralsparamsPanel, 'text');
            app.BSTpathEditField.Editable = 'off';
            app.BrainstormdbpathEditFieldLabel = uilabel(app.GeneralsparamsPanel);
            app.BrainstormdbpathEditFieldLabel.Text = 'Brainstorm db path:';
            app.BSTdbpathEditField = uieditfield(app.GeneralsparamsPanel, 'text');
            app.BSTdbpathEditField.Editable = 'off';
            app.BSTdbpathEditField.Value = 'local';
            app.ResetprotocolSwitchLabel = uilabel(app.GeneralsparamsPanel);
            app.ResetprotocolSwitchLabel.Text = 'Reset protocol:';
            app.ResetprotocolSwitch = uiswitch(app.GeneralsparamsPanel, 'slider');
            app.ResetprotocolSwitch.Items = {'No', 'Yes'};
            app.ResetprotocolSwitch.ValueChangedFcn = createCallbackFcn(app, @ResetprotocolSwitchValueChanged, true);
            app.ResetprotocolSwitch.Value = 'No';
            app.DatasetnameEditFieldLabel = uilabel(app.GeneralsparamsPanel);
            app.DatasetnameEditFieldLabel.Text = 'Dataset name:';
            app.DatasetnameEditField = uieditfield(app.GeneralsparamsPanel, 'text');
            app.DatasetdescriptionLabel = uilabel(app.GeneralsparamsPanel);
            app.DatasetdescriptionLabel.Text = 'DS. description:';
            app.DatasetDescriptionTextArea = uitextarea(app.GeneralsparamsPanel);
            app.LabelReqModality = uilabel(app.GeneralsparamsPanel);
            app.LabelReqModality.Text = '*';
            app.LabelReqDatasetName = uilabel(app.GeneralsparamsPanel);
            app.LabelReqDatasetName.Text = '*';
            app.LabelReqProtocolName = uilabel(app.GeneralsparamsPanel);
            app.LabelReqProtocolName.Text = '*';
            app.LabelReqBSTPath = uilabel(app.GeneralsparamsPanel);
            app.LabelReqBSTPath.Text = '*';
            app.LabelReqOutput = uilabel(app.GeneralsparamsPanel);
            app.LabelReqOutput.Text = '*';
            app.ImportAnatomyTab = uitab(app.TabGroup);
            app.ImportAnatomyTab.Title = 'Import Anatomy';
            app.SelectAnatomyoptionButtonGroup = uibuttongroup(app.ImportAnatomyTab);
            app.SelectAnatomyoptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectAnatomyoptionButtonGroupSelectionChanged, true);
            app.SelectAnatomyoptionButtonGroup.Title = 'Select Anatomy option';
            app.Button_BST_Default = uiradiobutton(app.SelectAnatomyoptionButtonGroup);
            app.Button_BST_Default.Text = '';
            app.Button_BST_Default.Value = true;
            app.Button_HCP_Individual = uiradiobutton(app.SelectAnatomyoptionButtonGroup);
            app.Button_HCP_Individual.Text = '';
            app.BSTDefaultAnatomyPanel = uipanel(app.SelectAnatomyoptionButtonGroup);
            app.BSTDefaultAnatomyPanel.Title = 'BST Default Anatomy';
            app.TemplatenameDropDownLabel = uilabel(app.BSTDefaultAnatomyPanel);
            app.TemplatenameDropDownLabel.Text = 'Template name:';
            app.AnatomyTemplateDropDown = uidropdown(app.BSTDefaultAnatomyPanel);
            app.AnatomyTemplateDropDown.Items = {'--Select a template--'};
            app.AnatomyTemplateDropDown.ValueChangedFcn = createCallbackFcn(app, @AnatomyTemplateDropDownValueChanged, true);
            app.AnatomyTemplateDropDown.Value = '--Select a template--';
            app.AtlasLabel = uilabel(app.BSTDefaultAnatomyPanel);
            app.AtlasLabel.Text = 'Atlas:';
            app.AnatomyTemplateAtlasDropDown = uidropdown(app.BSTDefaultAnatomyPanel);
            app.AnatomyTemplateAtlasDropDown.Items = {'--Select--'};
            app.AnatomyTemplateAtlasDropDown.Value = '--Select--';
            app.LabelReqTemplatenameAnat = uilabel(app.BSTDefaultAnatomyPanel);
            app.LabelReqTemplatenameAnat.Text = '*';
            app.LabelReq_TemplateAtlasAnat = uilabel(app.BSTDefaultAnatomyPanel);
            app.LabelReq_TemplateAtlasAnat.Text = '*';
            app.HCPIndividualAnatomyPanel = uipanel(app.SelectAnatomyoptionButtonGroup);
            app.HCPIndividualAnatomyPanel.Enable = 'off';
            app.HCPIndividualAnatomyPanel.Title = 'HCP Individual Anatomy';
            app.SelectHCPIndividPathButton = uibutton(app.HCPIndividualAnatomyPanel, 'push');
            app.SelectHCPIndividPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectHCPIndividPathButtonPushed, true);
            app.SelectHCPIndividPathButton.Text = 'Select';
            app.AnatomypathLabel_2 = uilabel(app.HCPIndividualAnatomyPanel);
            app.AnatomypathLabel_2.Text = 'Anatomy path:';
            app.HCPIndividPathEditField = uieditfield(app.HCPIndividualAnatomyPanel, 'text');
            app.HCPIndividPathEditField.Editable = 'off';
            app.AtlasLabel_2 = uilabel(app.HCPIndividualAnatomyPanel);
            app.AtlasLabel_2.Text = 'Atlas file name:';
            app.AnatomyIndivAtlasDropDown = uidropdown(app.HCPIndividualAnatomyPanel);
            app.AnatomyIndivAtlasDropDown.Items = {'--Select the Atlas file--'};
            app.AnatomyIndivAtlasDropDown.Value = '--Select the Atlas file--';
            app.AtlasLabel_3 = uilabel(app.HCPIndividualAnatomyPanel);
            app.AtlasLabel_3.Text = 'T1w file name:';
            app.AnatomyIndivT1wDropDown = uidropdown(app.HCPIndividualAnatomyPanel);
            app.AnatomyIndivT1wDropDown.Items = {'--Select the T1w file--'};
            app.AnatomyIndivT1wDropDown.Value = '--Select the T1w file--';
            app.LabelReqHCPpathAnat = uilabel(app.HCPIndividualAnatomyPanel);
            app.LabelReqHCPpathAnat.Text = '*';
            app.LabelReqHCPfileAnat = uilabel(app.HCPIndividualAnatomyPanel);
            app.LabelReqHCPfileAnat.Text = '*';
            app.LabelReqHCPAtlasAnat = uilabel(app.HCPIndividualAnatomyPanel);
            app.LabelReqHCPAtlasAnat.Text = '*';
            app.CommonparamsIAPanel = uipanel(app.ImportAnatomyTab);
            app.CommonparamsIAPanel.Title = 'Common params';
            app.SelectNonBrainPathButton = uibutton(app.CommonparamsIAPanel, 'push');
            app.SelectNonBrainPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectNonBrainPathButtonPushed, true);
            app.SelectNonBrainPathButton.Text = 'Select';
            app.NonbrainsurfacesLabel = uilabel(app.CommonparamsIAPanel);
            app.NonbrainsurfacesLabel.Text = 'Non-brain surfaces:';
            app.NonBrainPathEditField = uieditfield(app.CommonparamsIAPanel, 'text');
            app.NonBrainPathEditField.Editable = 'off';
            app.SurfacesresolutionEditFieldLabel = uilabel(app.CommonparamsIAPanel);
            app.SurfacesresolutionEditFieldLabel.Text = 'Surfaces resolution:';
            app.SurfacesresolutionEditField = uieditfield(app.CommonparamsIAPanel, 'numeric');
            app.SurfacesresolutionEditField.Limits = [5000 15000];
            app.SurfacesresolutionEditField.Value = 8000;
            app.LayerdescriptorLabel = uilabel(app.CommonparamsIAPanel);
            app.LayerdescriptorLabel.Text = 'Layer descriptor:';
            app.LayerDescripDropDown = uidropdown(app.CommonparamsIAPanel);
            app.LayerDescripDropDown.Items = {'--Select--', 'Pial', 'Midthickness', 'White', 'FS_LR', 'Bigbrain'};
            app.LayerDescripDropDown.Value = '--Select--';
            app.LabelReqLayerDesc = uilabel(app.CommonparamsIAPanel);
            app.LabelReqLayerDesc.Text = '*';
            app.LabelReqNonBrain = uilabel(app.CommonparamsIAPanel);
            app.LabelReqNonBrain.Text = '*';
            app.LabelReqSurfResol = uilabel(app.CommonparamsIAPanel);
            app.LabelReqSurfResol.Text = '*';
            app.MRITransformationPanel = uipanel(app.ImportAnatomyTab);
            app.MRITransformationPanel.Title = 'MRI Transformation';
            app.SelectMRITransPathButton = uibutton(app.MRITransformationPanel, 'push');
            app.SelectMRITransPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectMRITransPathButtonPushed, true);
            app.SelectMRITransPathButton.Enable = 'off';
            app.SelectMRITransPathButton.Text = 'Select';
            app.SelectMRITransFileButton = uibutton(app.MRITransformationPanel, 'push');
            app.SelectMRITransFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectMRITransFileButtonPushed, true);
            app.SelectMRITransFileButton.Enable = 'off';
            app.SelectMRITransFileButton.Text = 'Select';
            app.pathLabel = uilabel(app.MRITransformationPanel);
            app.pathLabel.Enable = 'off';
            app.pathLabel.Text = 'Base path:';
            app.MRITransPathEditField = uieditfield(app.MRITransformationPanel, 'text');
            app.MRITransPathEditField.Editable = 'off';
            app.MRITransPathEditField.Enable = 'off';
            app.filenameLabel = uilabel(app.MRITransformationPanel);
            app.filenameLabel.Enable = 'off';
            app.filenameLabel.Text = 'Reference file:';
            app.MRITransFileEditField = uieditfield(app.MRITransformationPanel, 'text');
            app.MRITransFileEditField.Editable = 'off';
            app.MRITransFileEditField.Enable = 'off';
            app.UseMRITransCheckBox = uicheckbox(app.MRITransformationPanel);
            app.UseMRITransCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseMRITransCheckBoxValueChanged, true);
            app.UseMRITransCheckBox.Text = '';
            app.ApplyLabel = uilabel(app.MRITransformationPanel);
            app.ApplyLabel.Text = 'Apply:';
            app.ImportChannelTab = uitab(app.TabGroup);
            app.ImportChannelTab.Title = 'Import Channel';
            app.SelectChanneloptionButtonGroup = uibuttongroup(app.ImportChannelTab);
            app.SelectChanneloptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectChanneloptionButtonGroupSelectionChanged, true);
            app.SelectChanneloptionButtonGroup.Title = 'Select Channel option';
            app.Button_Channel_template = uiradiobutton(app.SelectChanneloptionButtonGroup);
            app.Button_Channel_template.Text = '';
            app.Button_Channel_template.Value = true;
            app.Button_Channel_raw_data = uiradiobutton(app.SelectChanneloptionButtonGroup);
            app.Button_Channel_raw_data.Text = '';
            app.UseChanneltemplatePanel = uipanel(app.SelectChanneloptionButtonGroup);
            app.UseChanneltemplatePanel.Title = 'Use Channel template';
            app.GroupDropDownLabel = uilabel(app.UseChanneltemplatePanel);
            app.GroupDropDownLabel.Text = 'Group:';
            app.ChannTemplateGroupDropDown = uidropdown(app.UseChanneltemplatePanel);
            app.ChannTemplateGroupDropDown.Items = {'--Select--'};
            app.ChannTemplateGroupDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannTemplateGroupDropDownValueChanged, true);
            app.ChannTemplateGroupDropDown.Value = '--Select--';
            app.TemplatenameDropDownLabel_2 = uilabel(app.UseChanneltemplatePanel);
            app.TemplatenameDropDownLabel_2.Text = 'Template name:';
            app.ChannTemplateNameDropDown = uidropdown(app.UseChanneltemplatePanel);
            app.ChannTemplateNameDropDown.Items = {'--Select--'};
            app.ChannTemplateNameDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannTemplateNameDropDownValueChanged, true);
            app.ChannTemplateNameDropDown.Value = '--Select--';
            app.Channel_UITable = uitable(app.UseChanneltemplatePanel);
            app.Channel_UITable.ColumnName = {'No.'; 'Name'; 'Type'; 'Location'; 'Comment'};
            app.Channel_UITable.RowName = {};
            app.LabelReqChannelGroup = uilabel(app.UseChanneltemplatePanel);
            app.LabelReqChannelGroup.Text = '*';
            app.LabelReqChannelName = uilabel(app.UseChanneltemplatePanel);
            app.LabelReqChannelName.Text = '*';
            app.RawdataPanel = uipanel(app.SelectChanneloptionButtonGroup);
            app.RawdataPanel.Enable = 'off';
            app.RawdataPanel.Title = 'Use Raw data';
            app.SelectRawDataBPathButton = uibutton(app.RawdataPanel, 'push');
            app.SelectRawDataBPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRawDataBPathButtonPushed, true);
            app.SelectRawDataBPathButton.Text = 'Select';
            app.BasepathLabel = uilabel(app.RawdataPanel);
            app.BasepathLabel.Text = 'Base path:';
            app.RawDataBPathEditField = uieditfield(app.RawdataPanel, 'text');
            app.RawDataBPathEditField.Editable = 'off';
            app.FilepathLabel = uilabel(app.RawdataPanel);
            app.FilepathLabel.Text = 'File path:';
            app.RawDataFileEditField = uieditfield(app.RawdataPanel, 'text');
            app.RawDataFileEditField.Editable = 'off';
            app.FormatDropDownLabel = uilabel(app.RawdataPanel);
            app.FormatDropDownLabel.Text = 'Format:';
            app.RawDataFormatDropDown = uidropdown(app.RawdataPanel);
            app.RawDataFormatDropDown.Items = {'-Select-', 'mat', 'mff', 'edf', '4D'};
            app.RawDataFormatDropDown.Value = '-Select-';
            app.SelectRawDataFPathButton = uibutton(app.RawdataPanel, 'push');
            app.SelectRawDataFPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRawDataFPathButtonPushed, true);
            app.SelectRawDataFPathButton.Text = 'Select';
            app.LabelReqDataBasePath = uilabel(app.RawdataPanel);
            app.LabelReqDataBasePath.Text = '*';
            app.LabelReqDataFile = uilabel(app.RawdataPanel);
            app.LabelReqDataFile.Text = '*';
            app.LabelReqFormat = uilabel(app.RawdataPanel);
            app.LabelReqFormat.Text = '*';
            app.ComputeHeadmodelTab = uitab(app.TabGroup);
            app.ComputeHeadmodelTab.Title = 'Compute Headmodel';
            app.MethodsButtonGroup = uibuttongroup(app.ComputeHeadmodelTab);
            app.MethodsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MethodsButtonGroupSelectionChanged, true);
            app.MethodsButtonGroup.Title = 'Methods';
            app.Button_Spheres = uiradiobutton(app.MethodsButtonGroup);
            app.Button_Spheres.Text = '';
            app.Button_OpenMEEGBEM = uiradiobutton(app.MethodsButtonGroup);
            app.Button_OpenMEEGBEM.Text = '';
            app.Button_OpenMEEGBEM.Value = true;
            app.Button_DUNEuroFEM = uiradiobutton(app.MethodsButtonGroup);
            app.Button_DUNEuroFEM.Text = '';
            app.SpheresoptionsPanel = uipanel(app.MethodsButtonGroup);
            app.SpheresoptionsPanel.Enable = 'off';
            app.SpheresoptionsPanel.Title = 'Spheres options';
            app.ButtonGroup = uibuttongroup(app.SpheresoptionsPanel);
            app.ButtonSinglesphere = uiradiobutton(app.ButtonGroup);
            app.ButtonSinglesphere.Text = 'Single sphere';
            app.ButtonOverlappingspheres = uiradiobutton(app.ButtonGroup);
            app.ButtonOverlappingspheres.Text = 'Overlapping spheres';
            app.ButtonOverlappingspheres.Value = true;
            app.Buttons3Shellsphere = uiradiobutton(app.ButtonGroup);
            app.Buttons3Shellsphere.Text = '3-shell sphere';
            app.OpenMEEGBEMoptionsPanel = uipanel(app.MethodsButtonGroup);
            app.OpenMEEGBEMoptionsPanel.Title = 'OpenMEEG BEM options';
            app.isAdjointSwitchLabel = uilabel(app.OpenMEEGBEMoptionsPanel);
            app.isAdjointSwitchLabel.Text = 'isAdjoint:';
            app.isAdjointSwitch = uiswitch(app.OpenMEEGBEMoptionsPanel, 'slider');
            app.isAdjointSwitch.Items = {'No', 'Yes'};
            app.isAdjointSwitch.ValueChangedFcn = createCallbackFcn(app, @isAdjointSwitchValueChanged, true);
            app.isAdjointSwitch.Tooltip = {'If 1, use adjoint formulation (less memory, longer)'};
            app.isAdjointSwitch.Value = 'No';
            app.isAdaptativeSwitchLabel = uilabel(app.OpenMEEGBEMoptionsPanel);
            app.isAdaptativeSwitchLabel.Text = 'isAdaptative:';
            app.isAdaptativeSwitch = uiswitch(app.OpenMEEGBEMoptionsPanel, 'slider');
            app.isAdaptativeSwitch.Items = {'No', 'Yes'};
            app.isAdaptativeSwitch.ValueChangedFcn = createCallbackFcn(app, @isAdaptativeSwitchValueChanged, true);
            app.isAdaptativeSwitch.Tooltip = {'If 1, use adaptive integration (more accurate, 3x longer)'};
            app.isAdaptativeSwitch.Value = 'Yes';
            app.isSplitSwitchLabel = uilabel(app.OpenMEEGBEMoptionsPanel);
            app.isSplitSwitchLabel.Text = 'isSplit:';
            app.isSplitSwitch = uiswitch(app.OpenMEEGBEMoptionsPanel, 'slider');
            app.isSplitSwitch.Items = {'No', 'Yes'};
            app.isSplitSwitch.ValueChangedFcn = createCallbackFcn(app, @isSplitSwitchValueChanged, true);
            app.isSplitSwitch.Value = 'No';
            app.SplitLengthSpinnerLabel = uilabel(app.OpenMEEGBEMoptionsPanel);
            app.SplitLengthSpinnerLabel.Text = 'SplitLength:';
            app.SplitLengthSpinner = uispinner(app.OpenMEEGBEMoptionsPanel);
            app.SplitLengthSpinner.Limits = [3000 6000];
            app.SplitLengthSpinner.Value = 4000;
            app.BEMFilesConductivitiesLabel = uilabel(app.OpenMEEGBEMoptionsPanel);
            app.BEMFilesConductivitiesLabel.Text = 'BEM Files & Conductivities';
            app.CheckBoxScalpBEM = uicheckbox(app.OpenMEEGBEMoptionsPanel);
            app.CheckBoxScalpBEM.Text = '   Scalp';
            app.CheckBoxScalpBEM.Value = true;
            app.CheckBoxSkullBEM = uicheckbox(app.OpenMEEGBEMoptionsPanel);
            app.CheckBoxSkullBEM.Text = '   Skull';
            app.CheckBoxSkullBEM.Value = true;
            app.CheckBoxBrainBEM = uicheckbox(app.OpenMEEGBEMoptionsPanel);
            app.CheckBoxBrainBEM.Text = '   Brain';
            app.CheckBoxBrainBEM.Value = true;
            app.EditFieldScalpCond = uieditfield(app.OpenMEEGBEMoptionsPanel, 'numeric');
            app.EditFieldScalpCond.Value = 1;
            app.EditFieldSkullCond = uieditfield(app.OpenMEEGBEMoptionsPanel, 'numeric');
            app.EditFieldSkullCond.Value = 0.0125;
            app.EditFieldBrainCond = uieditfield(app.OpenMEEGBEMoptionsPanel, 'numeric');
            app.EditFieldBrainCond.Value = 1;
            app.DUNEuroFEMoptionsPanel = uipanel(app.MethodsButtonGroup);
            app.DUNEuroFEMoptionsPanel.Enable = 'off';
            app.DUNEuroFEMoptionsPanel.Title = 'DUNEuro FEM options';
            app.MethodLabel = uilabel(app.DUNEuroFEMoptionsPanel);
            app.MethodLabel.Text = 'FEM Method:';
            app.DropDownFEMMethod = uidropdown(app.DUNEuroFEMoptionsPanel);
            app.DropDownFEMMethod.Items = {'--Select--', 'iso2mesh-2021', 'iso2mesh', 'brain2mesh', 'simnibs3', 'simnibs4', 'fieldtrip'};
            app.DropDownFEMMethod.ValueChangedFcn = createCallbackFcn(app, @DropDownFEMMethodValueChanged, true);
            app.DropDownFEMMethod.Tooltip = {'Iso2mesh-2021: Call iso2mesh to create a tetrahedral mesh from the BEM surfaces.'; 'Iso2mesh: Previous version of this method (useful in case the new one fails).'; 'Brain2mesh: Segment the T1 (and T2) MRI with SPM12, mesh with Brain2mesh.'; 'SimNIBS 3.x: Call SimNIBS/headreco to segment and mesh the T1 (and T2) MRI.'; 'SimNIBS 4.x: Call SimNIBS/charm to segment and mesh the T1 (and T2) MRI.'; 'FieldTrip: Call FieldTrip to create hexahedral mesh of the T1 MRI.'};
            app.DropDownFEMMethod.Value = '--Select--';
            app.FEMFilesConductivitiesLabel = uilabel(app.DUNEuroFEMoptionsPanel);
            app.FEMFilesConductivitiesLabel.Text = 'FEM Files & Conductivities';
            app.CheckBoxScalpFEM = uicheckbox(app.DUNEuroFEMoptionsPanel);
            app.CheckBoxScalpFEM.Text = '   Scalp';
            app.CheckBoxScalpFEM.Value = true;
            app.CheckBoxSkullFEM = uicheckbox(app.DUNEuroFEMoptionsPanel);
            app.CheckBoxSkullFEM.Text = '   Skull';
            app.CheckBoxSkullFEM.Value = true;
            app.CheckBoxBrainFEM = uicheckbox(app.DUNEuroFEMoptionsPanel);
            app.CheckBoxBrainFEM.Text = '   Brain';
            app.CheckBoxBrainFEM.Value = true;
            app.EditFieldScalpFEM = uieditfield(app.DUNEuroFEMoptionsPanel, 'numeric');
            app.EditFieldScalpFEM.Value = 1.79;
            app.EditFieldSkullFEM = uieditfield(app.DUNEuroFEMoptionsPanel, 'numeric');
            app.EditFieldSkullFEM.Value = 0.008;
            app.EditFieldBrainFEM = uieditfield(app.DUNEuroFEMoptionsPanel, 'numeric');
            app.EditFieldBrainFEM.Value = 0.43;
            app.FEMoptsPanel = uipanel(app.DUNEuroFEMoptionsPanel);
            app.FEMoptsPanel.Visible = 'off';
            app.Paneliso2mesh = uipanel(app.FEMoptsPanel);
            app.MergeMethodDropDownLabel = uilabel(app.Paneliso2mesh);
            app.MergeMethodDropDownLabel.Text = 'Merge Method:';
            app.MergeMethodDropDown = uidropdown(app.Paneliso2mesh);
            app.MergeMethodDropDown.Items = {'mergemesh', 'mergesurf'};
            app.MergeMethodDropDown.Tooltip = {'Iso2mesh function used to merge the input surfaces:'; '- MergeMesh: Default option (faster). Simply concatenates the meshes without any intersection checks.'; '- MergeSurf: Advanced option (slower). Concatenates and checks for intersections, split intersecting elements.'};
            app.MergeMethodDropDown.Value = 'mergemesh';
            app.MaxVolSpinnerLabel = uilabel(app.Paneliso2mesh);
            app.MaxVolSpinnerLabel.Text = 'MaxVol:';
            app.MaxVolSpinner = uispinner(app.Paneliso2mesh);
            app.MaxVolSpinner.Step = 0.01;
            app.MaxVolSpinner.Limits = [0 1];
            app.MaxVolSpinner.Tooltip = {'Max tetrahedral volume (in cm^3) (10=coarse, 0.0001=fine)'};
            app.MaxVolSpinner.Value = 0.1;
            app.KeepRatioSpinnerLabel = uilabel(app.Paneliso2mesh);
            app.KeepRatioSpinnerLabel.Text = 'KeepRatio:';
            app.KeepRatioSpinner = uispinner(app.Paneliso2mesh);
            app.KeepRatioSpinner.Limits = [1 100];
            app.KeepRatioSpinner.Tooltip = {'Percentage of elements kept (1-100%)'};
            app.KeepRatioSpinner.Value = 100;
            app.Panelsimnibs3 = uipanel(app.FEMoptsPanel);
            app.Panelsimnibs3.Visible = 'off';
            app.NbVerticesSpinner_3Label = uilabel(app.Panelsimnibs3);
            app.NbVerticesSpinner_3Label.Text = 'NbVertices:';
            app.NbVerticesSpinner_3 = uispinner(app.Panelsimnibs3);
            app.NbVerticesSpinner_3.Limits = [8000 20000];
            app.NbVerticesSpinner_3.ValueDisplayFormat = '%.0f';
            app.NbVerticesSpinner_3.Value = 15000;
            app.VertexDensitySpinnerLabel = uilabel(app.Panelsimnibs3);
            app.VertexDensitySpinnerLabel.Text = 'VertexDensity:';
            app.VertexDensitySpinner = uispinner(app.Panelsimnibs3);
            app.VertexDensitySpinner.Step = 0.01;
            app.VertexDensitySpinner.Limits = [0 1];
            app.VertexDensitySpinner.Value = 0.5;
            app.Panelsimnibs4 = uipanel(app.FEMoptsPanel);
            app.Panelsimnibs4.Visible = 'off';
            app.NbVerticesSpinner_4Label = uilabel(app.Panelsimnibs4);
            app.NbVerticesSpinner_4Label.Text = 'NbVertices:';
            app.NbVerticesSpinner_4 = uispinner(app.Panelsimnibs4);
            app.NbVerticesSpinner_4.Limits = [8000 20000];
            app.NbVerticesSpinner_4.ValueDisplayFormat = '%.0f';
            app.NbVerticesSpinner_4.Value = 15000;
            app.Panelfieldtrip = uipanel(app.FEMoptsPanel);
            app.Panelfieldtrip.Visible = 'off';
            app.DownsampleSpinnerLabel = uilabel(app.Panelfieldtrip);
            app.DownsampleSpinnerLabel.Text = 'Downsample:';
            app.DownsampleSpinner = uispinner(app.Panelfieldtrip);
            app.DownsampleSpinner.Limits = [1 5];
            app.DownsampleSpinner.Value = 3;
            app.NodeShiftSpinnerLabel = uilabel(app.Panelfieldtrip);
            app.NodeShiftSpinnerLabel.Text = 'NodeShift:';
            app.NodeShiftSpinner = uispinner(app.Panelfieldtrip);
            app.NodeShiftSpinner.Step = 0.01;
            app.NodeShiftSpinner.Limits = [0 0.49];
            app.NodeShiftSpinner.Value = 0.3;
            app.SourcemodelDropDownLabel = uilabel(app.DUNEuroFEMoptionsPanel);
            app.SourcemodelDropDownLabel.Text = 'Source model:';
            app.DropDownFEMSourcemodel = uidropdown(app.DUNEuroFEMoptionsPanel);
            app.DropDownFEMSourcemodel.Items = {'--Select--', 'Venant', 'Subtraction', 'Partial integration'};
            app.DropDownFEMSourcemodel.ValueChangedFcn = createCallbackFcn(app, @DropDownFEMSourcemodelValueChanged, true);
            app.DropDownFEMSourcemodel.Value = '--Select--';
            app.PanelVenant = uipanel(app.DUNEuroFEMoptionsPanel);
            app.PanelVenant.Visible = 'off';
            app.NoofmomentsLabel = uilabel(app.PanelVenant);
            app.NoofmomentsLabel.Text = 'No. of moments:';
            app.NumMomentsSpinner = uispinner(app.PanelVenant);
            app.NumMomentsSpinner.Limits = [1 5];
            app.NumMomentsSpinner.Tooltip = {'Number of moments (1-5)'};
            app.NumMomentsSpinner.Value = 3;
            app.ReflengthSpinnerLabel = uilabel(app.PanelVenant);
            app.ReflengthSpinnerLabel.Text = 'Ref. length:';
            app.ReflengthSpinner = uispinner(app.PanelVenant);
            app.ReflengthSpinner.Limits = [1 100];
            app.ReflengthSpinner.Tooltip = {'Reference length (1-100)'};
            app.ReflengthSpinner.Value = 20;
            app.WeightingexponentSpinnerLabel = uilabel(app.PanelVenant);
            app.WeightingexponentSpinnerLabel.Text = 'Weighting exponent:';
            app.WeightingexponentSpinner = uispinner(app.PanelVenant);
            app.WeightingexponentSpinner.Limits = [1 3];
            app.WeightingexponentSpinner.Tooltip = {'Weighting exponent (1-3)'};
            app.WeightingexponentSpinner.Value = 1;
            app.RelFactExponentSpinnerLabel = uilabel(app.PanelVenant);
            app.RelFactExponentSpinnerLabel.Text = 'Rel. Fact. Exponent:';
            app.RelFactExponentSpinner = uispinner(app.PanelVenant);
            app.RelFactExponentSpinner.Limits = [3 9];
            app.RelFactExponentSpinner.Tooltip = {'Relaxation factor exponent (3-9)'};
            app.RelFactExponentSpinner.Value = 6;
            app.PanelSubtraction = uipanel(app.DUNEuroFEMoptionsPanel);
            app.PanelSubtraction.Visible = 'off';
            app.NoofmomentsLabel_2 = uilabel(app.PanelSubtraction);
            app.NoofmomentsLabel_2.Text = 'Intorderadd:';
            app.NumMomentsSpinner_2 = uispinner(app.PanelSubtraction);
            app.NumMomentsSpinner_2.Limits = [1 5];
            app.NumMomentsSpinner_2.Tooltip = {'intorder add (1-5)'};
            app.NumMomentsSpinner_2.Value = 1;
            app.Intorderadd_lbSpinnerLabel = uilabel(app.PanelSubtraction);
            app.Intorderadd_lbSpinnerLabel.Text = 'Intorderadd_lb:';
            app.Intorderadd_lbSpinner = uispinner(app.PanelSubtraction);
            app.Intorderadd_lbSpinner.Limits = [1 5];
            app.Intorderadd_lbSpinner.Tooltip = {'intorderadd_lb (1-5)'};
            app.Intorderadd_lbSpinner.Value = 2;
            app.FunctionalIntegrationTab = uitab(app.TabGroup);
            app.FunctionalIntegrationTab.Title = 'Functional Integration';
            app.FuctionalparamsPanel = uipanel(app.FunctionalIntegrationTab);
            app.FuctionalparamsPanel.Title = 'Fuctional params';
            app.SelectPrepdatapathButton = uibutton(app.FuctionalparamsPanel, 'push');
            app.SelectPrepdatapathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPrepdatapathButtonPushed, true);
            app.SelectPrepdatapathButton.Text = 'Select';
            app.EEGLABOutputpathLabel = uilabel(app.FuctionalparamsPanel);
            app.EEGLABOutputpathLabel.Text = 'Prep. data path:';
            app.PrepdatapathEditField = uieditfield(app.FuctionalparamsPanel, 'text');
            app.PrepdatapathEditField.Editable = 'off';
            app.LabelReqEEGLABOutputPath = uilabel(app.FuctionalparamsPanel);
            app.LabelReqEEGLABOutputPath.Text = '*';
            app.DataeventLabel = uilabel(app.FuctionalparamsPanel);
            app.DataeventLabel.Text = 'Data event :';
            app.DataEventDropDown = uidropdown(app.FuctionalparamsPanel);
            app.DataEventDropDown.Items = {'--Select event--'};
            app.DataEventDropDown.Value = '--Select event--';
            app.LabelReqDataEvent = uilabel(app.FuctionalparamsPanel);
            app.LabelReqDataEvent.Text = '*';
            app.DataeventLabel_2 = uilabel(app.FuctionalparamsPanel);
            app.DataeventLabel_2.Text = 'Data format :';
            app.DataFormatDropDown = uidropdown(app.FuctionalparamsPanel);
            app.DataFormatDropDown.Items = {'--Select--', 'set', 'edf', 'mat', 'plg'};
            app.DataFormatDropDown.Value = '--Select--';
            app.LabelReqDataFormat = uilabel(app.FuctionalparamsPanel);
            app.LabelReqDataFormat.Text = '*';
            app.CancelButton = uibutton(app.SetparametersPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Icon = fullfile(pathToMLAPP, 'images', 'cancel.jpg');
            app.CancelButton.Text = 'Cancel';
            app.ApplyButton = uibutton(app.SetparametersPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Icon = fullfile(pathToMLAPP, 'images', 'apply.png');
            app.ApplyButton.Text = 'Apply';
            app.ButtonBack = uibutton(app.SetparametersPanel, 'push');
            app.ButtonBack.ButtonPushedFcn = createCallbackFcn(app, @ButtonBackPushed, true);
            app.ButtonBack.Icon = fullfile(pathToMLAPP, 'images', 'back.png');
            app.ButtonBack.Tooltip = {'Back'};
            app.ButtonBack.Text = '';
            app.ButtonNext = uibutton(app.SetparametersPanel, 'push');
            app.ButtonNext.ButtonPushedFcn = createCallbackFcn(app, @ButtonNextPushed, true);
            app.ButtonNext.Icon = fullfile(pathToMLAPP, 'images', 'next.png');
            app.ButtonNext.Tooltip = {'Next'};
            app.ButtonNext.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Params_configuration

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end