classdef Redefine_channel_labels < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        CancelButton         matlab.ui.control.Button
        SetlabelsButton      matlab.ui.control.Button
        SelectedlayoutPanel  matlab.ui.container.Panel
        Layout_UITable       matlab.ui.control.Table
        SelectallCheckBox    matlab.ui.control.CheckBox
        NoteUncheckthelabelsthatyoudontwanttouseintheanalysisLabel  matlab.ui.control.Label
    end

    
    methods (Access = public)
        
        function load_channel_labels(app,BSTpath,labels,group_name,layout_name)
            layout_name = strrep(layout_name,' ','_');
            layout = load(fullfile(BSTpath,'defaults','eeg',group_name,['channel_',layout_name,'.mat']));
            for i=1:length(layout.Channel)
                loc = layout.Channel(i).Loc;               
                fields(i).Locs = strcat(num2str(loc(1)),',',num2str(loc(2)),',',num2str(loc(3)));                
                fields(i).CheckBox = true;
            end
            order_layout.No         = cellstr(string(1:length(layout.Channel)))';
            order_layout.Check      = {fields.CheckBox}';
            order_layout.Name       = {layout.Channel.Name}';
            order_layout.Type       = {layout.Channel.Type}';
            order_layout.Position   = {fields.Locs}';
            order_layout.Comment    = {layout.Channel.Comment}';
            T                       = struct2table(order_layout);
            ColumnWidth = app.Layout_UITable.ColumnWidth;            
            set(app.Layout_UITable, 'Data', T);             
            set(app.Layout_UITable, 'ColumnWidth', ColumnWidth);
            set(app.Layout_UITable,'ColumnFormat',{'char','logical','char','char','char','char'});
            set(app.Layout_UITable,'ColumnEditable',[false,true,false,false,false,false]);
        end
        
    end
    
    methods (Access = private)
             
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.UIFigure.Name = 'Redefine channel labels';
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
             delete(app);
        end

        % Value changed function: SelectallCheckBox
        function SelectallCheckBoxValueChanged(app, event)
            value = app.SelectallCheckBox.Value;
            if(value)
                Data = app.Layout_UITable.Data;
                for i=1:size(Data,1)
                  Data.Check{i} = true;
                end
                set(app.Layout_UITable,'Data',Data);
            else
                Data = app.Layout_UITable.Data;
                for i=1:size(Data,1)
                  Data.Check{i} = false;
                end
                set(app.Layout_UITable,'Data',Data);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SetlabelsButton = uibutton(app.UIFigure, 'push');
            app.SetlabelsButton.Text = 'Set labels';
            app.SelectedlayoutPanel = uipanel(app.UIFigure);
            app.SelectedlayoutPanel.Title = 'Selected layout';
            app.Layout_UITable = uitable(app.SelectedlayoutPanel);
            app.Layout_UITable.ColumnName = {'No.'; 'Select'; 'Name'; 'Type'; 'Location'; 'Comment'};
            app.Layout_UITable.RowName = {};
            app.SelectallCheckBox = uicheckbox(app.SelectedlayoutPanel);
            app.SelectallCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectallCheckBoxValueChanged, true);
            app.SelectallCheckBox.Text = 'Select all';
            app.SelectallCheckBox.Value = true;
            app.NoteUncheckthelabelsthatyoudontwanttouseintheanalysisLabel = uilabel(app.SelectedlayoutPanel);
            app.NoteUncheckthelabelsthatyoudontwanttouseintheanalysisLabel.Text = 'Note: Uncheck the labels that you don''t want to use in the analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Redefine_channel_labels

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end